classdef ExampleDirectoryApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UITable                 matlab.ui.control.Table
        DirectoryLocationLabel  matlab.ui.control.Label
        BrowseButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            
            path=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';      
            app.DirectoryLocationLabel.Text=path;  % Sets the label text to be the selected path

            a=dir(path);                         % Obtains the contents of the selected path.
            b={a(:).name}';                      % Gets the name of the files/folders of the contents and stores them appropriately in a cell array
            b(ismember(b,{'.','..'})) = [];      % Removes unnecessary '.' and '..' results from the display.

            
            app.UITable.Data=b;                  % Displays the directory information to the UITable.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.DirectoryLocationLabel = uilabel(app.UIFigure);
            app.DirectoryLocationLabel.Text = 'Directory Location';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Directory Contents'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExampleDirectoryApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end