classdef nCORTEx_host < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        cameraPanelSwitchLabel_camera   matlab.ui.control.Label
        StatusBar                       slrealtime.ui.control.StatusBar
        cameraPanelSwitch_camera        matlab.ui.control.Image
        photomPanelSwitch_photom        matlab.ui.control.Image
        sensePanelSwitch_sense          matlab.ui.control.Image
        npxlsPanelSwitch_npxls          matlab.ui.control.Image
        photonPanelSwitch_photon        matlab.ui.control.Image
        rxPanelSwitch_rx                matlab.ui.control.Image
        gxPanelSwitch_gx                matlab.ui.control.Image
        optoPanelSwitch_opto            matlab.ui.control.Image
        SessionLabelLabel               matlab.ui.control.Label
        DataCacheButton_expmnt          matlab.ui.control.Button
        NotesTextArea_expmnt            matlab.ui.control.TextArea
        slxParamTable                   matlab.ui.control.Table
        SessionQCLabel                  matlab.ui.control.Label
        BrowseProjects                  matlab.ui.control.Button
        ProjectPathTextEditField_proj   matlab.ui.control.EditField
        ProjectLabel                    matlab.ui.control.Label
        BrowseModules                   matlab.ui.control.Button
        ModulesFilePath                 matlab.ui.control.EditField
        ModulesEditFieldLabel           matlab.ui.control.Label
        rxPanelSwitchLabel_rx           matlab.ui.control.Label
        npxlsPanelSwitchLabel_npxls     matlab.ui.control.Label
        SubjectStatusLabel              matlab.ui.control.Label
        SessionQCButtonLabel            matlab.ui.control.Label
        QCButton                        matlab.ui.control.Image
        photonPanelSwitchLabel_photon   matlab.ui.control.Label
        sensePanelSwitchLabel_sense     matlab.ui.control.Label
        photomPanelSwitchLabel_photom   matlab.ui.control.Label
        optoPanelSwitchLabel_opto       matlab.ui.control.Label
        gxPanelSwitchLabel_gx           matlab.ui.control.Label
        ExperimentSetupLabel            matlab.ui.control.Label
        ParameterTableButton            matlab.ui.control.StateButton
        SessionTimerminEditFieldLabel   matlab.ui.control.Label
        PhaseDropDown_slrt              matlab.ui.control.DropDown
        SaveSubjectLabelButton          matlab.ui.control.Button
        ExpmntDropDownLabel             matlab.ui.control.Label
        DataDiscardButton_expmnt        matlab.ui.control.Button
        LabelButton                     matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        ExpmntDropDownV2_slrt           matlab.ui.control.DropDown
        SubjectDropDown_expmnt          matlab.ui.control.DropDown
        SubjectDropDownLabel            matlab.ui.control.Label
        SessionTimerminEditField        matlab.ui.control.NumericEditField
        connectTargetButton             matlab.ui.control.StateButton
        SelectProjectDirectoryDropDown_proj  matlab.ui.control.DropDown
        DataStreamButton_expmnt         matlab.ui.control.Button
        SelectProjectDirectoryDropDownLabel  matlab.ui.control.Label
        SubjectStatusPanel              matlab.ui.container.Panel
        SexDropDownV2_subj              matlab.ui.control.DropDown
        SexDropDownLabel_subj           matlab.ui.control.Label
        NoteButton_subj                 matlab.ui.control.StateButton
        WatermLEditField_subj           matlab.ui.control.NumericEditField
        WatermLEditFieldLabel_subj      matlab.ui.control.Label
        NotesLabel_expmnt               matlab.ui.control.Label
        DoBDatePicker_subj              matlab.ui.control.DatePicker
        genoPanel                       matlab.ui.container.Panel
        RemoveTab_geno                  matlab.ui.control.Button
        GenotypeDropDownV2_genoT1       matlab.ui.control.DropDown
        GenotypeDropDownLabel_genoT1    matlab.ui.control.Label
        TabNumLabel_geno                matlab.ui.control.Label
        AlleleDropDown_genoT1           matlab.ui.control.DropDown
        AlleleDropDownLabel_genoT1      matlab.ui.control.Label
        Tab_genoT1                      matlab.ui.control.Button
        AddTab_geno                     matlab.ui.control.Button
        surgPanel                       matlab.ui.container.Panel
        RemoveTab_surg                  matlab.ui.control.Button
        Tab_surgT1                      matlab.ui.control.Button
        SurgeryDateDatePicker_surgT1    matlab.ui.control.DatePicker
        SurgeryDateDatePickerLabel_surgT1  matlab.ui.control.Label
        TabNumLabel_surg                matlab.ui.control.Label
        AddTab_surg                     matlab.ui.control.Button
        ProcedureDropDownV2_surgT1      matlab.ui.control.DropDown
        ProcedureDropDownLabel_surgT1   matlab.ui.control.Label
        WeightEditField_subj            matlab.ui.control.NumericEditField
        WeightEditFieldLabel_subj       matlab.ui.control.Label
        DateofBirthLabel                matlab.ui.control.Label
        ModalityPanel                   matlab.ui.container.Panel
        gxPanel                         matlab.ui.container.Panel
        gxParameterTableButton_gxT1     matlab.ui.control.StateButton
        RemoveTab_gx                    matlab.ui.control.Button
        AddTab_gx                       matlab.ui.control.Button
        Tab_gxT1                        matlab.ui.control.Button
        NeuralTargetSideDropDown_gxT1   matlab.ui.control.DropDown
        NeuralTargetSideDropDownLabel_gxT1  matlab.ui.control.Label
        TabNumLabel_gx                  matlab.ui.control.Label
        ManipulationDropDownV2_gxT1     matlab.ui.control.DropDown
        ManipulationDropDownLabel_gxT1  matlab.ui.control.Label
        RNASensoryDropDownV2_gxT1       matlab.ui.control.DropDown
        RNASensoryDropDownLabel_gxT1    matlab.ui.control.Label
        GeneEditingDropDownV2_gxT1      matlab.ui.control.DropDown
        GeneEditingDropDownLabel_gxT1   matlab.ui.control.Label
        NeuralTargetDropDownV2_gxT1     matlab.ui.control.DropDown
        NeuralTargetDropDownLabel_gxT1  matlab.ui.control.Label
        rxPanel                         matlab.ui.container.Panel
        rxParameterTableButton_rxT1     matlab.ui.control.StateButton
        RemoveTab_rx                    matlab.ui.control.Button
        AddTab_rx                       matlab.ui.control.Button
        Tab_rxT1                        matlab.ui.control.Button
        TabNumLabel_rx                  matlab.ui.control.Label
        RxDoseDropDownV2_rxT1           matlab.ui.control.DropDown
        RxDoseDropDownLabel_rxT1        matlab.ui.control.Label
        RxTimer_rxT1                    matlab.ui.control.StateButton
        RxDeliveryMethodDropDownV2_rxT1  matlab.ui.control.DropDown
        RxDeliveryMethodDropDownLabel_rxT1  matlab.ui.control.Label
        RxDrugDropDownV2_rxT1           matlab.ui.control.DropDown
        RxDrugDropDownLabel_rxT1        matlab.ui.control.Label
        optoPanel                       matlab.ui.container.Panel
        PowerLabel_optoT1               matlab.ui.control.Label
        PowerNumericEntryLabel_optoT1   matlab.ui.control.Label
        PowerNumericEntry_optoT1        matlab.ui.control.NumericEditField
        SetStimulationParams_optoT1     matlab.ui.control.Button
        optoParameterTableButton_optoT1  matlab.ui.control.StateButton
        RemoveTab_opto                  matlab.ui.control.Button
        AddTab_opto                     matlab.ui.control.Button
        Tab_optoT1                      matlab.ui.control.Button
        NeuralTargetSideDropDown_optoT1  matlab.ui.control.DropDown
        NeuralTargetSideDropDownLabel_optoT1  matlab.ui.control.Label
        TabNumLabel_opto                matlab.ui.control.Label
        LaserWavelengthDropDownV2_optoT1  matlab.ui.control.DropDown
        LaserWavelengthDropDownLabel_optoT1  matlab.ui.control.Label
        OpsinDropDownV2_optoT1          matlab.ui.control.DropDown
        OpsinDropDownLabel_optoT1       matlab.ui.control.Label
        NeuralTargetDropDownV2_optoT1   matlab.ui.control.DropDown
        NeuralTargetDropDownLabel_optoT1  matlab.ui.control.Label
        photonPanel                     matlab.ui.container.Panel
        photonParameterTableButton_photomT1  matlab.ui.control.StateButton
        NeuralHardwareDropDownV2_photonT1  matlab.ui.control.DropDown
        NeuralHardwareDropDownLabel_photonT1  matlab.ui.control.Label
        NeuralTargetDropDownV2_photonT1  matlab.ui.control.DropDown
        NeuralTargetDropDownLabel_photonT1  matlab.ui.control.Label
        NeuralTargetSideDropDown_photonT1  matlab.ui.control.DropDown
        NeuralTargetSideDropDownLabel_photonT1  matlab.ui.control.Label
        channelPanel_photonT1           matlab.ui.container.Panel
        Subtab_photonT1_chan1           matlab.ui.control.Button
        Subtab_photonT1_chan2           matlab.ui.control.Button
        Subtab_photonT1_chan3           matlab.ui.control.Button
        Subtab_photonT1_chan4           matlab.ui.control.Button
        chanNumLabel_photonT1_chan      matlab.ui.control.Label
        photonChannelsLabel_photonT1_chan  matlab.ui.control.Label
        VectorDropDownV2_photonT1_chan1  matlab.ui.control.DropDown
        VectorDropDownLabel_photonT1_chan1  matlab.ui.control.Label
        PowerLabel_photonT1_chan1       matlab.ui.control.Label
        PowerNumericEntryLabel_photonT1_chan1  matlab.ui.control.Label
        PowerNumericEntry_photonT1_chan1  matlab.ui.control.NumericEditField
        ExcitWavelengthDropDownV2_photonT1_chan1  matlab.ui.control.DropDown
        ExcitWavelengthDropDownLabel_photonT1_chan1  matlab.ui.control.Label
        SensorDropDownV2_photonT1_chan1  matlab.ui.control.DropDown
        SensorDropDownLabel_photonT1_chan1  matlab.ui.control.Label
        RemoveTab_photon                matlab.ui.control.Button
        AddTab_photon                   matlab.ui.control.Button
        Tab_photonT1                    matlab.ui.control.Button
        TabNumLabel_photon              matlab.ui.control.Label
        npxlsPanel                      matlab.ui.container.Panel
        npxlsParameterTableButton_npxlsT1  matlab.ui.control.StateButton
        RemoveTab_npxls                 matlab.ui.control.Button
        AddTab_npxls                    matlab.ui.control.Button
        Tab_npxlsT1                     matlab.ui.control.Button
        ProbeAreasDisplayButton_npxlsT1  matlab.ui.control.Image
        ProbeAreasSearchButton_npxlsT1  matlab.ui.control.Button
        TabNumLabel_npxls               matlab.ui.control.Label
        ProbeShankRegionsLabel_npxlsT1  matlab.ui.control.Label
        HeadstageSNDropDownV2_npxlsT1   matlab.ui.control.DropDown
        HeadstageSNDropDownLabel_npxlsT1  matlab.ui.control.Label
        ProbeSNDropDownV2_npxlsT1       matlab.ui.control.DropDown
        ProbeSNDropDownLabel_npxlsT1    matlab.ui.control.Label
        ProbeVersionDropDownV2_npxlsT1  matlab.ui.control.DropDown
        ProbeVersionDropDownLabel_npxlsT1  matlab.ui.control.Label
        ProbeSideDropDownV2_npxlsT1     matlab.ui.control.DropDown
        ProbeSideDropDownLabel_npxlsT1  matlab.ui.control.Label
        cameraPanel                     matlab.ui.container.Panel
        FSNumericEntryLabel_cameraT1    matlab.ui.control.Label
        FSNumericEntry_cameraT1         matlab.ui.control.NumericEditField
        frameRate_cameraT1              matlab.ui.control.Label
        NeuralNetworkDropDownV2_cameraT1  matlab.ui.control.DropDown
        NeuralNetDropDownLabel_cameraT1  matlab.ui.control.Label
        Tab_cameraT1                    matlab.ui.control.Button
        RemoveTab_camera                matlab.ui.control.Button
        AddTab_camera                   matlab.ui.control.Button
        SerialNumberDropDownV2_cameraT1  matlab.ui.control.DropDown
        CameraTargetDropDownV2_cameraT1  matlab.ui.control.DropDown
        CameraTargetDropDownLabel_cameraT1  matlab.ui.control.Label
        SerialNumberDropDownLabel_cameraT1  matlab.ui.control.Label
        SpinParamsParameterTableButton_cameraT1  matlab.ui.control.StateButton
        TabNumLabel_camera              matlab.ui.control.Label
        SpinviewButton_camera           matlab.ui.control.Button
        CameraProgressBar_cameraT1      matlab.ui.control.UIAxes
        photomPanel                     matlab.ui.container.Panel
        photomParameterTableButton_photomT1  matlab.ui.control.StateButton
        channelPanel_photomT1           matlab.ui.container.Panel
        Subtab_photomT1_chan1           matlab.ui.control.Button
        Subtab_photomT1_chan2           matlab.ui.control.Button
        Subtab_photomT1_chan3           matlab.ui.control.Button
        Subtab_photomT1_chan4           matlab.ui.control.Button
        chanNumLabel_photomT1_chan      matlab.ui.control.Label
        photomChannelsLabel_photomT1_chan  matlab.ui.control.Label
        VectorDropDownV2_photomT1_chan1  matlab.ui.control.DropDown
        VectorDropDownLabel_photomT1_chan1  matlab.ui.control.Label
        PowerLabel_photomT1_chan1       matlab.ui.control.Label
        PowerNumericEntryLabel_photomT1_chan1  matlab.ui.control.Label
        PowerNumericEntry_photomT1_chan1  matlab.ui.control.NumericEditField
        ExcitWavelengthDropDownV2_photomT1_chan1  matlab.ui.control.DropDown
        ExcitWavelengthDropDownLabel_photomT1_chan1  matlab.ui.control.Label
        SensorDropDownV2_photomT1_chan1  matlab.ui.control.DropDown
        SensorDropDownLabel_photomT1_chan1  matlab.ui.control.Label
        RemoveTab_photom                matlab.ui.control.Button
        AddTab_photom                   matlab.ui.control.Button
        Tab_photomT1                    matlab.ui.control.Button
        NeuralTargetSideDropDown_photomT1  matlab.ui.control.DropDown
        NeuralTargetSideDropDownLabel_photomT1  matlab.ui.control.Label
        TabNumLabel_photom              matlab.ui.control.Label
        NeuralHardwareDropDownV2_photomT1  matlab.ui.control.DropDown
        NeuralHardwareDropDownLabel_photomT1  matlab.ui.control.Label
        NeuralTargetDropDownV2_photomT1  matlab.ui.control.DropDown
        NeuralTargetDropDownLabel_photomT1  matlab.ui.control.Label
        sensePanel                      matlab.ui.container.Panel
        senseParameterTableButton_senseT1  matlab.ui.control.StateButton
        SensoryTargetSideDropDown_senseT1  matlab.ui.control.DropDown
        SensoryTargetSideDropDownLabel_senseT1  matlab.ui.control.Label
        AddTab_sense                    matlab.ui.control.Button
        RemoveTab_sense                 matlab.ui.control.Button
        Tab_senseT1                     matlab.ui.control.Button
        CalibrationButton_senseT1       matlab.ui.control.StateButton
        TabNumLabel_sense               matlab.ui.control.Label
        ApparatusDropDownV2_senseT1     matlab.ui.control.DropDown
        ApparatusDropDownLabel_senseT1  matlab.ui.control.Label
        SensoryTargetDropDownV2_senseT1  matlab.ui.control.DropDown
        SensoryTargetDropDownLabel_senseT1  matlab.ui.control.Label
        ModalityDropDownV2_senseT1      matlab.ui.control.DropDown
        ModalityDropDownLabel_senseT1   matlab.ui.control.Label
        SessionMetricsPanel             matlab.ui.container.Panel
        StdHzLabel                      matlab.ui.control.Label
        MeanHzLabel                     matlab.ui.control.Label
        NpxlsStatLabel                  matlab.ui.control.Label
        WhiskStatLabel                  matlab.ui.control.Label
        PupilStatLabel                  matlab.ui.control.Label
        NpxlsStdHz                      matlab.ui.control.Label
        WhiskStdHz                      matlab.ui.control.Label
        PupilStdHz                      matlab.ui.control.Label
        NpxlsMeanHz                     matlab.ui.control.Label
        WhiskMeanHz                     matlab.ui.control.Label
        PupilMeanHz                     matlab.ui.control.Label
        HitRateLabel                    matlab.ui.control.Label
        HitRate                         matlab.ui.control.Label
        ValidateButton                  matlab.ui.control.Button
        PupilFrmCount                   matlab.ui.control.Label
        WhiskFrmCount                   matlab.ui.control.Label
        WhiskLabel                      matlab.ui.control.Label
        PupilLabel                      matlab.ui.control.Label
        UIAxes_WhiskFrames              matlab.ui.control.UIAxes
        UIAxes_PupilFrames              matlab.ui.control.UIAxes
        JOLTPanel                       matlab.ui.container.Panel
        JOLTValidationPanel             matlab.ui.container.Panel
        NpxlsStatLabel_JOLT             matlab.ui.control.Label
        NpxlsStdHz_JOLT                 matlab.ui.control.Label
        NpxlsMeanHz_JOLT                matlab.ui.control.Label
        JOLT_ValidateButton             matlab.ui.control.Button
        JOLTButtonsPanel                matlab.ui.container.Panel
        JOLTdiscardCount                matlab.ui.control.Label
        JOLTsaveCount                   matlab.ui.control.Label
        JOLTDiscardButton               matlab.ui.control.Button
        JOLTSaveButton                  matlab.ui.control.Button
        JOLTStartTrialButton            matlab.ui.control.Button
        MonofilAxes                     matlab.ui.control.UIAxes
        JOLTSwipeLeftButton             matlab.ui.control.StateButton
        JOLTSwipeRightButton            matlab.ui.control.StateButton
        SessionQCPanel                  matlab.ui.container.Panel
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        nCORTEx_waitbar                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        slTarget=slrealtime; % Description
        isStopped % Description
        sampleTime=0.001 % Description
        % params=setBaseParams(struct);
        params = struct;
        sessionParams=[];
        cyberGreen=[0.24,0.94,0.46];
        cyberBlack = [0.00,0.00,0.00];
        disableGreen=[0.05,0.14,0.08];
        cyberOrange=[1.00,0.41,0.16];
        cyberGrey=[0.18,0.18,0.22];
        cyberRed=[1.00,0.33,0.42];
        activeGrey=[0.96,0.96,0.96];
        disableGrey=[0.50,0.50,0.50];
        enableWhite=[1.00,1.00,1.00];
        gX=0; % initialize gate count
        % tX=0; % initialize trial count
        tcp=[]; % store tcp handle when initiated
        dq=[];
        % Runtime Panel Position Presets
        % JOLTPanelPos = [17,98,462,402];
        JOLTPanelPos = [321,60,462,402];
        % JOLTButtonsPanelPos = [20,42,422,163] + [17,257,462,254];
        % JOLTValidationPanelPos = [20,42,422,163] + [17,257,462,254];
        JOLTSwipeNum=0;
        bufferTimer % Description
        trialEndTimer
        waitbarTimer_camera
        animLine
        monofilBuffer=[];
        joltTrialSaveCount=0;
        presetMask={"piezo","opto","vision","pgx","photom","npxls"};
        subjIDNum="";
        discardCount = 0;
        saveCount = 0;
        trialCount=-1;
        trialMask=[];
        experiment="";
        tabs = struct;
        mdlWks = [];
        cortexClient = [];
        allenRegs = [];
        npxls = struct; % struct containing information for each npxls probe
        sense = struct; % struct containing sensory calibration information
        camera = struct;
        slrt = struct;
        photon = struct;
        photom = struct;
        opto = struct;
        rx = struct;
        gx = struct;
        modalityList = ["npxls","sense","camera","slrt","photon","photom","opto","gx","rx"];
        modalityList_RAW = ["slrt","npxls","camera","photon","photom"];
        extractionLayers = ["RAW","EXT","DTS","FTR"];
        elapsedTime = 0;
        originalMatlabPath % keep track of the path prior to launching app (matlab default)
        nCORTEx_repo;
    end

    properties (Access = public)
        editingModalityLabel_expmnt;
    end

    methods (Access = private)

        function params = storeSlxParams(app, params)
            load_system(app.params.experiment)
            app.mdlWks = get_param(app.params.experiment,'ModelWorkspace');
            slxParams = app.mdlWks.whos;
            % params.slxWks = mdlWks;
            params.slxParams = struct;
            for i = 1:length(slxParams)
                % Get parameter name
                paramName = slxParams(i).name;
                % Get parameter value
                paramValue = app.mdlWks.getVariable(paramName);
                params.slxParams.(paramName) = paramValue;
                % tablePos = getTablePosition()                
            end
        end

        function transmitCortexMsg(app,msg)
            % MIGRATE TARGET DATA
            if isfield(app.params, 'connectedClients')
                connectedTargets = app.params.connectedClients;
                for i = 1:length(connectedTargets)
                    target = connectedTargets(i);
                    targetSrv = app.(target).cortexClient;                                        
                    writeline(targetSrv,msg);
                end
            end
        end

        function animateSwitchIcon(app, switchIconRef, switchStat)
            switchFramesDir = struct2table(dir(fullfile(app.nCORTEx_repo,"Icons","cortexSwitch_anim")));
            frames = switchFramesDir(contains(switchFramesDir.name,"png"),:);
            if strcmp(switchStat,'Off')
                for i = 1:height(frames)
                    app.(switchIconRef).ImageSource=fullfile(app.nCORTEx_repo,"Icons","cortexSwitch_anim",frames(i,:).name);
                    pause(0.001);
                end
                app.(switchIconRef).ImageSource=fullfile(app.nCORTEx_repo,"Icons","cortexSwitch_on.png");
            else
                for i = height(frames):-1:1
                    app.(switchIconRef).ImageSource=fullfile(app.nCORTEx_repo,"Icons","cortexSwitch_anim",frames(i,:).name);
                    pause(0.001);
                end
                app.(switchIconRef).ImageSource=fullfile(app.nCORTEx_repo,"Icons","cortexSwitch_off.png");
            end
        end

        function cacheAlert(app,stat)
            cacheLoc = fullfile(app.params.paths.nCORTEx_repo,"Setup","nCORTExCache.mat");
            load(cacheLoc,'cache');
            hostName = getHostName();
            % check if empty
            if isfield(cache.(hostName),"sessionCache")
                if ~isempty(cache.(hostName).sessionCache)
                    app.DataStreamButton_expmnt.BackgroundColor = [0.988235294117647,0.415686274509804,0.129411764705882];                
                    app.DataStreamButton_expmnt.Enable = 1;                                         
                    app.DataCacheButton_expmnt.BackgroundColor = [0.988235294117647,0.415686274509804,0.129411764705882];                           
                else
                    app.DataStreamButton_expmnt.BackgroundColor = app.cyberGreen;
                    app.DataCacheButton_expmnt.BackgroundColor = app.cyberGrey;                                               
                end
            end
            
        end

        function setSimulinkParams(app, model)
            switch model
                case 'nCORTEx'
                    T_npxls = (1/app.Npxls_FSEditField_npxlsT1.Value)/(app.sampleTime);
                    T_pupil = (1/app.PupilFSEditField_cameraT1.Value)/(app.sampleTime);
                    T_whisk = (1/app.WhiskFSEditField_cameraT1.Value)/(app.sampleTime);
                    load_system(model);
                    mdlWks = get_param(model,'ModelWorkspace');
                    assignin(mdlWks, 'tStop', (app.SessionTimerminEditField.Value*60)); % minute to second conversion
                    assignin(mdlWks, 'T_npxls', T_npxls);
                    assignin(mdlWks, 'T_pupil', T_pupil);
                    assignin(mdlWks, 'T_whisk', T_whisk);
                    assignin(mdlWks, 'toneDuration', 1.5);
                    assignin(mdlWks, 'maxFrames', (app.SessionTimerminEditField.Value*60)/app.sampleTime);
                case 'JOLT'
                    T_npxls = (1/250)/(app.sampleTime);
                    % T_pupil = (1/app.PupilFSEditField_cameraT1.Value)/(app.sampleTime);
                    % T_whisk = (1/app.WhiskFSEditField_cameraT1.Value)/(app.sampleTime);
                    load_system(model);
                    mdlWks = get_param(model,'ModelWorkspace');
                    assignin(mdlWks, 'tStop', (app.SessionTimerminEditField.Value*60)); % minute to second conversion
                    assignin(mdlWks, 'T_npxls', T_npxls);
                    set_param(model, 'StopTime', string(app.SessionTimerminEditField.Value*60));
                    % assignin(mdlWks, 'T_pupil', T_pupil);
                    % assignin(mdlWks, 'T_whisk', T_whisk);
                    % assignin(mdlWks, 'toneDuration', 1.5);
                    % assignin(mdlWks, 'maxFrames', (app.SessionTimerminEditField.Value*60)/app.sampleTime);
                    % case 'ATTN'
            end
        end

        function pyDict = struct2dict(app, matStruct)
            % Returns a matlab struct as a text formatted python dictionary
            % containing struct fields as dict keys and struct values as dict values
            % Converts a MATLAB struct to a text-formatted Python dictionary

            % Start with an empty string
            pyDict = '{';

            % Loop through the fields of the struct
            fields = fieldnames(matStruct);
            for i = 1:length(fields)
                field = fields{i};
                value = matStruct.(field);

                % Convert the field name and value to strings
                fieldStr = sprintf('"%s"', field);
                valueStr = '';

                % Check the data type of the value and format it accordingly
                if ischar(value)
                    valueStr = sprintf('"%s"', value);
                elseif isnumeric(value) || islogical(value)
                    valueStr = num2str(value);
                elseif isstruct(value)
                    % Recursively convert nested structs
                    valueStr = struct2dict(app, value);
                else
                    % Handle other data types as needed
                    error('Unsupported data type in struct.');
                end

                % Combine the field and value strings
                pairStr = sprintf('%s: %s', fieldStr, valueStr);

                % Add the pair to the dictionary string
                if i < length(fields)
                    pyDict = [pyDict, pairStr, ', '];
                else
                    pyDict = [pyDict, pairStr];
                end
            end

            % Close the dictionary
            pyDict = [pyDict, '}'];

        end

        function colorCodeByValid(app, labelParam, isValid)
            switch isValid
                case 1
                    app.(labelParam).FontColor = app.cyberGreen;
                case 0
                    app.(labelParam).FontColor = app.cyberOrange;
                case -1
                    app.(labelParam).FontColor = app.cyberGrey;
            end
        end

        function label = npxlsLabel(app)
            % label = app.ProbeSideDropDownV2_npxlsT1.Value;

            % label = '';
            sideT1 = app.ProbeSideDropDownV2_npxlsT1.Value;
            versionT1 = app.ProbeVersionDropDownV2_npxlsT1.Value;
            label = sprintf("%s-npx%s", sideT1, strrep(versionT1,'.',''));
            tabFields = fieldnames(app.tabs);
            sideFields = tabFields(contains(tabFields,"ProbeSideDropDown_"));
            versionFields = tabFields(contains(tabFields,"ProbeVersionDropDown_"));
            for i = 1:length(sideFields)
                sideField = sideFields{i};
                versionField = versionFields{i};
                side = app.tabs.(sideField).Value;
                version = app.tabs.(versionField).Value;
                label = sprintf("%s--%s-npx%s", label, side, strrep(version,'.',''));
            end
        end

        function label = pharmLabel(app)
            lbl = struct;
            lbl.injLoc = app.PGxTgtDropDown_pgxT1.Value;
            lbl.receptor = app.RxDrugDropDownV2_rxT1.Value;
            lbl.drug = app.RxDeliveryMethodDropDownV2_rxT1.Value;
            lbl.dosage = app.PGxDosageDropDown_pgxT1.Value;
            lblFields = fieldnames(lbl);
            label = '';
            m = 1;
            for i = 1:length(lblFields)
                lblfld = lblFields{i};
                if ~strcmp(lbl.(lblfld),'None')
                    if m > 1
                        label = strcat(label,'-',lbl.(lblfld));
                    else
                        label = strcat(label,lbl.(lblfld));
                    end
                    m = m+1;
                end
            end
        end

        function label = photonLabel(app)
            modRef="photon";
            % try
            % nTarget1 = string(app.NeuralTargetDropDownV2_photomT1.Value);
            side = string(app.NeuralTargetSideDropDown_photonT1.Value);
            sensor1T1 = string(app.(modRef).channels.SensorDropDownV2_photonT1_chan1.Value);
            sensor2T1 = string(app.(modRef).channels.SensorDropDownV2_photonT1_chan2.Value);
            sensor3T1 = string(app.(modRef).channels.SensorDropDownV2_photonT1_chan3.Value);
            sensor4T1 = string(app.(modRef).channels.SensorDropDownV2_photonT1_chan4.Value);
            sensors = [sensor1T1, sensor2T1, sensor3T1, sensor4T1];
            label = buildPhotonLabel(app, side, sensors);
            tabFields = fieldnames(app.tabs);
            % nTargFields = tabFields(contains(tabFields,"NeuralTargetDropDown_photom"));
            sideFields = tabFields(contains(tabFields,"NeuralTargetSideDropDown_photon"));
            chanFields = fieldnames(app.(modRef).channels);
            sensor1Fields = chanFields(contains(chanFields,"SensorDropDownV2_photon") & contains(chanFields,"chan1"));
            sensor2Fields = chanFields(contains(chanFields,"SensorDropDownV2_photon") & contains(chanFields,"chan2"));
            sensor3Fields = chanFields(contains(chanFields,"SensorDropDownV2_photon") & contains(chanFields,"chan3"));
            sensor4Fields = chanFields(contains(chanFields,"SensorDropDownV2_photon") & contains(chanFields,"chan4"));
            for i = 1:length(sideFields)
                side = sideFields{i};
                tabID = split(side,'_');
                tabID = string(tabID{2});
                side = string(app.tabs.(side).Value);
                % snsr1 = sensor1Fields{i};
                snsr1 = string(cell2mat(sensor1Fields(contains(sensor1Fields,tabID))));
                snsr1 = string(app.(modRef).channels.(snsr1).Value);
                snsr2 = string(cell2mat(sensor2Fields(contains(sensor2Fields,tabID))));
                snsr2 = string(app.(modRef).channels.(snsr2).Value);
                snsr3 = string(cell2mat(sensor3Fields(contains(sensor3Fields,tabID))));
                snsr3 = string(app.(modRef).channels.(snsr3).Value);
                snsr4 = string(cell2mat(sensor4Fields(contains(sensor4Fields,tabID))));
                snsr4 = string(app.(modRef).channels.(snsr4).Value);
                subLabel = buildPhotonLabel(app, side, [snsr1, snsr2, snsr3, snsr4]);
                if ~strcmp(subLabel,"")
                    label = sprintf("%s--%s",label, subLabel);
                end
            end
            % catch           
            % end
        end

        function label = buildPhotonLabel(app, side, sensors)
            % label = strrep(nTarget,'None','');
            label = sprintf("%s",side);
            for i = 1:length(sensors)
                sensor = sensors(i);
                if i == 1
                    if ~strcmp(sensor,'None')
                        label = strcat(label,sprintf('-%s',sensor));
                    end
                else
                    if ~strcmp(sensor,'None')
                        label = strcat(label,sprintf('-%s',sensor));
                    end
                end

            end
        end

        function label = photomLabel(app)
            modRef="photom";
            try
                % nTarget1 = string(app.NeuralTargetDropDownV2_photomT1.Value);
                side = string(app.NeuralTargetSideDropDown_photomT1.Value);
                sensor1T1 = string(app.(modRef).channels.SensorDropDownV2_photomT1_chan1.Value);
                sensor2T1 = string(app.(modRef).channels.SensorDropDownV2_photomT1_chan2.Value);
                sensor3T1 = string(app.(modRef).channels.SensorDropDownV2_photomT1_chan3.Value);
                sensor4T1 = string(app.(modRef).channels.SensorDropDownV2_photomT1_chan4.Value);
                sensors = [sensor1T1, sensor2T1, sensor3T1, sensor4T1];
                label = buildPhotomLabel(app, side, sensors);
                tabFields = fieldnames(app.tabs);
                % nTargFields = tabFields(contains(tabFields,"NeuralTargetDropDown_photom"));
                sideFields = tabFields(contains(tabFields,"NeuralTargetSideDropDown_photom"));
                chanFields = fieldnames(app.(modRef).channels);
                sensor1Fields = chanFields(contains(chanFields,"SensorDropDownV2_photom") & contains(chanFields,"chan1"));
                sensor2Fields = chanFields(contains(chanFields,"SensorDropDownV2_photom") & contains(chanFields,"chan2"));
                sensor3Fields = chanFields(contains(chanFields,"SensorDropDownV2_photom") & contains(chanFields,"chan3"));
                sensor4Fields = chanFields(contains(chanFields,"SensorDropDownV2_photom") & contains(chanFields,"chan4"));
                for i = 1:length(sideFields)                    
                    side = sideFields{i};
                    tabID = split(side,'_');
                    tabID = string(tabID{2});
                    side = string(app.tabs.(side).Value);                    
                    % snsr1 = sensor1Fields{i};
                    snsr1 = string(cell2mat(sensor1Fields(contains(sensor1Fields,tabID))));
                    snsr1 = string(app.(modRef).channels.(snsr1).Value);
                    snsr2 = string(cell2mat(sensor2Fields(contains(sensor2Fields,tabID))));
                    snsr2 = string(app.(modRef).channels.(snsr2).Value);
                    snsr3 = string(cell2mat(sensor3Fields(contains(sensor3Fields,tabID))));
                    snsr3 = string(app.(modRef).channels.(snsr3).Value);
                    snsr4 = string(cell2mat(sensor4Fields(contains(sensor4Fields,tabID))));
                    snsr4 = string(app.(modRef).channels.(snsr4).Value);
                    subLabel = buildPhotomLabel(app, side, [snsr1, snsr2, snsr3, snsr4]);
                    if ~strcmp(subLabel,"")
                        label = sprintf("%s--%s",label, subLabel);
                    end
                end
            catch
                label = "";
            end
        end

        function label = optoLabel(app)
            
            side1 = string(app.NeuralTargetSideDropDown_optoT1.Value);
            opsin1 = string(app.OpsinDropDownV2_optoT1.Value);
            label = "";
            if ~strcmp(opsin1,'None'); label = sprintf('%s-%s',side1,opsin1); end
            tabFields = fieldnames(app.tabs);
            
            opsinFields = tabFields(contains(tabFields,"OpsinDropDown_opto"));
            sideFields = tabFields(contains(tabFields,"NeuralTargetSideDropDown_opto"));
            for i = 1:length(opsinFields)
                opsin = opsinFields{i};
                tabID = split(opsin,'_');
                tabID = string(tabID{2});
                opsin = referenceTabField(app,opsin,"Value",[]);
                side = string(cell2mat(sideFields(contains(sideFields,tabID))));
                side = referenceTabField(app,side,"Value",[]);
                if ~strcmp(opsin,'None')
                    subLabel = sprintf("%s-%s",side,opsin);
                    label = sprintf("%s--%s",label,subLabel);
                end
            end
        end

        function label = gxLabel(app)
            % nTarget1 = string(app.NeuralTargetDropDownV2_gxT1.Value);
            manipulation = string(app.ManipulationDropDownV2_gxT1.Value);
            substrate = string(app.GeneEditingDropDownV2_gxT1.Value);
            side = string(app.NeuralTargetSideDropDown_gxT1.Value);
            label = "";
            if ~strcmp(manipulation,'None'); label = sprintf("%s-%s-%s",side,substrate,manipulation); end
            tabFields = fieldnames(app.tabs);
            % nTargFields = tabFields(contains(tabFields,"NeuralTargetDropDown_gx"));
            manipulationFields = tabFields(contains(tabFields,"ManipulationDropDown_gx"));
            sideFields = tabFields(contains(tabFields,"NeuralTargetSideDropDown_gx"));
            substrateFields = tabFields(contains(tabFields,"GeneEditingDropDown_gx"));
            for i = 1:length(manipulationFields)
                manipulation = manipulationFields{i};
                tabID = split(manipulation,'_');
                tabID = string(tabID{2});
                manipulation = string(app.tabs.(manipulation).Value);
                side = string(cell2mat(sideFields(contains(sideFields,tabID))));
                side = string(app.tabs.(side).Value);
                substrate = string(cell2mat(substrateFields(contains(subtrateFields,tabID))));
                substrate = referenceTabField(app,substrate,"Value",[]);
                if ~strcmp(manipulation,'None')
                    subLabel = sprintf("%s-%s-%s",side,substrate,manipulation);
                    label = sprintf("%s--%s",label,subLabel);
                end
            end
        end

        function label = rxLabel(app)
            drug1 = string(app.RxDrugDropDownV2_rxT1.Value);
            dose1 = string(app.RxDoseDropDownV2_rxT1.Value);
            % dose1 = string(app.RxDoseDropDownV2_rxT1.Value);
            label = "";
            if ~strcmp(drug1,'None'); label = sprintf("%s-%s",drug1,dose1); end
            tabFields = fieldnames(app.tabs);
            drugFields = tabFields(contains(tabFields,"RxDrugDropDown_rx"));
            doseFields = tabFields(contains(tabFields,"RxDoseDropDown_rx"));
            for i = 1:length(drugFields)
                drug = drugFields{i};
                tabID = split(drug,'_');
                tabID = string(tabID{2});
                drug = referenceTabField(app,drug,"Value",[]);
                dose = string(cell2mat(doseFields(contains(doseFields,tabID))));
                dose = referenceTabField(app,dose,"Value",[]);
                if ~strcmp(drug,'None')
                    subLabel = sprintf("%s-%s",drug,dose);
                    label = sprintf("%s--%s",label,subLabel);
                end
            end
        end

        function label = genoLabel(app)
            %
            gene1 = string(app.GenotypeDropDownV2_genoT1.Value);            
            tabFields = fieldnames(app.tabs);
            label = sprintf("%s",gene1);
            geneFields = tabFields(contains(tabFields,"GenotypeDropDownV2_geno"));
            for i = 1:length(geneFields)
                gene = geneFields{i};
                gene = referenceTabField(app,gene,"Value",[]);
                subLabel = sprintf("%s",gene);
                if ~strcmp(gene,'None')
                    label = sprintf("%s-x-%s",label,subLabel);
                end
            end
        end

        function label = buildPhotomLabel(app, side, sensors)
            % label = strrep(nTarget,'None','');
            label = sprintf("%s",side);
            for i = 1:length(sensors)
                sensor = sensors(i);
                if i == 1
                    if ~strcmp(sensor,'None')
                        label = strcat(label,sprintf('-%s',sensor));
                    end
                else
                    if ~strcmp(sensor,'None')
                        label = strcat(label,sprintf('-%s',sensor));
                    end
                end
            end
        end

        function subjectLabel = buildSubjectLabel(app)
            subjectLabel = buildSessionLabel(app);
            sessionLabel = buildSessionLabel(app);
            % remove the date and gate tag
            date = string(datetime('now', 'Format', 'yyyy-MM-dd'));
            app.gX = updateGateNum(app, sessionLabel);
            gate=strcat("_g",string(app.gX));
            subjectLabel = strrep(subjectLabel,strcat(date,"_"),"");
            subjectLabel = strrep(subjectLabel,strcat(gate),"");
        end

        function sessionLabel = buildSessionLabel(app)

            ssnLbl.date = strcat(string(datetime('now', 'Format', 'yyyy-MM-dd')));
            ssnLbl.subj = strcat(string(app.SubjectDropDown_expmnt.Value));
            ssnLbl.geno = genoLabel(app); 
            % ssnLbl.pgx = pharmLabel(app);
            % if strcmp(app.npxlsPanelSwitch_npxls.Value,'On'); ssnLbl.npxls = npxlsLabel(app); end
            % if strcmp(app.photonPanelSwitch_photon.Value,'On'); ssnLbl.photon = photonLabel(app); end
            % if strcmp(app.photomPanelSwitch_photom.Value,'On'); ssnLbl.photom = photomLabel(app); end
            % if strcmp(app.optoPanelSwitch_opto.Value,'On'); ssnLbl.opto = optoLabel(app); end
            % if strcmp(app.gxPanelSwitch_gx.Value,'On'); ssnLbl.gx = gxLabel(app); end
            % if strcmp(app.rxPanelSwitch_rx.Value,'On'); ssnLbl.rx = rxLabel(app); end  
            if strcmp(app.npxlsPanelSwitch_npxls.AltText,'On'); ssnLbl.npxls = npxlsLabel(app); end
            if strcmp(app.photonPanelSwitch_photon.AltText,'On'); ssnLbl.photon = photonLabel(app); end
            if strcmp(app.photomPanelSwitch_photom.AltText,'On'); ssnLbl.photom = photomLabel(app); end
            if strcmp(app.optoPanelSwitch_opto.AltText,'On'); ssnLbl.opto = optoLabel(app); end
            if strcmp(app.gxPanelSwitch_gx.AltText,'On'); ssnLbl.gx = gxLabel(app); end
            if strcmp(app.rxPanelSwitch_rx.AltText,'On'); ssnLbl.rx = rxLabel(app); end  
            % ssnLbl.state = (app.StateDropDownV2_subj.Value);
            ssnLbl.phase = strrep(app.PhaseDropDown_slrt.Value,'_','-');


            ssnFnames = fieldnames(ssnLbl);
            sessionLabel = '';
            for i = 1:length(ssnFnames)
                ssnFld = ssnFnames{i};
                if (~strcmp(ssnLbl.(ssnFld),'None') && ~isempty(ssnLbl.(ssnFld)))
                    lblItm = sprintf("%s--%s",ssnFld, ssnLbl.(ssnFld));
                    if i > 1
                        sessionLabel = strcat(sessionLabel,'_',lblItm);
                    else
                        sessionLabel = strcat(sessionLabel,lblItm);
                    end

                end
            end

            % update gX from sessionLog
            app.gX = updateGateNum(app, sessionLabel);
            % append gX
            gate=strcat("_g",string(app.gX));
            sessionLabel = strcat(sessionLabel, gate);
            % mask empty entries

        end

        function switchedLabel = sessionLabelSwitch(app, switchRef, label)
            % switch app.(switchRef).Value
            switch app.(switchRef).AltText
                case 'On'
                    switchedLabel = strcat('_',label);
                case 'Off'
                    switchedLabel = '';
                otherwise
                    switchedLabel = '';
            end
        end


        function refreshDataBufferStatus(app)
            app.DataStreamButton_expmnt.Icon="brkIcon.jpg";
            app.dataBufferButtonMode="gate";
            dirEmpty = ~(length(dir(app.params.paths.tempDir))>2);
            % check if temp is not empty
            if ~dirEmpty
                % enable this button (the data buffer button) if
                % nCORTEx_temp has any trial data
                app.DataStreamButton_expmnt.Enable=true;
                app.DataStreamButton_expmnt.BackgroundColor=app.cyberGreen;
            else
                % if temp is empty, disable the button
                app.DataStreamButton_expmnt.Enable=false;
                app.DataStreamButton_expmnt.BackgroundColor=app.disableGreen;
                DiscardButtonEnable(app,0);
            end
        end

        function DiscardButtonEnable(app, EN)
            switch EN
                case 0
                    app.DataDiscardButton_expmnt.Enable=false;
                    app.DataDiscardButton_expmnt.BackgroundColor=app.disableGreen;
                case 1
                    app.DataDiscardButton_expmnt.Enable=true;
                    app.DataDiscardButton_expmnt.BackgroundColor=app.cyberGreen;
            end
        end

        function resetLoggingActions(app)
            DiscardButtonEnable(app, 0);
            refreshDataBufferStatus(app);
        end

        function updateWaitbar_cortex(app, tStep)
            waitBarRef = app.nCORTEx_waitbar;
            % barHndl = referenceTabField(app,waitBarRef,"hndl",[]);
            % maxTime = str2double(app.SessionTimerminEditField.Text);
            maxTime = app.SessionTimerminEditField.Value;
            framesPrct = app.elapsedTime / (maxTime*60);
            drawrectangle(waitBarRef,'Position', [0, 0,framesPrct, 1],'InteractionsAllowed','none','Color',app.cyberGreen);
            app.elapsedTime = app.elapsedTime + tStep;
        end

        function updateWaitbar_camera(app, cam)
            % disp("updaiting waitbar");
            tabID = cam.tabID;
            waitBarRef = sprintf("CameraProgressBar_%s",tabID);
            framesEditFieldRef = sprintf("FSNumericEntry_%s",tabID);
            barHndl = referenceTabField(app,waitBarRef,"hndl",[]);
            frameRate = (referenceTabField(app,framesEditFieldRef,"Value",[]));
            % frameRate = 
            totalFrames = app.SessionTimerminEditField.Value * 60 * frameRate;
            currentNumFrames = countFrames(cam.savePath);
            if currentNumFrames >= 0
                framesPrct = currentNumFrames / totalFrames;
            else
                framesPrct = 0;
            end
            % cla(barHndl)
            drawrectangle(barHndl,'Position', [0, 0,framesPrct, 1],'InteractionsAllowed','none','Color',app.cyberGreen);
        end

        function clearCameraWaitBars(app,camera)
            cameras = fieldnames(camera);
            for i = 1:length(cameras)
                cam = cameras{i};
                cam = camera.(cam);
                tabID = cam.tabID;
                waitBarRef = sprintf("CameraProgressBar_%s",tabID);
                barHndl = referenceTabField(app,waitBarRef,"hndl",[]);
                cla(barHndl);
            end
        end

        function listenForStop(app)
            if string(app.slTarget.ModelStatus.State)=="DONE"
                % disp("stopping slx")
                % stop(app.slTarget)
                StopButtonPushed(app);
            end
        end

        function play_nCORTEx(app, model)
            % empty Temp folder
            % - code here to empty
            app.PupilLabel.FontColor=[0.18,0.18,0.22];
            app.WhiskLabel.FontColor=[0.18,0.18,0.22];

            %% CONFIGURE CAM ACQ
            app.params.spinParams.execStatus='start';
            app.params.spinParams.sessionLabel = app.SessionLabelLabel.Text;
            % re-update fps values based on GUI
            % app.params.spinParams.saveDir=app.SelectProjectDirectoryDropDownLabel.Text;            %
            % app.params.spinParams.saveDir=app.params.paths.tempDir;
            % START CAMERAS (if exist)
            cameras = fieldnames(app.camera);                
            if ~isempty(cameras)
                % REFRESH CAMS (FINAL CALL)
                for i = 1:length(cameras)
                    camRef = cameras{i};
                    camRef = strrep(camRef,'camera','');
                    camRef = sprintf("cameraT%s",camRef);
                    simEvent.Source.Tag = sprintf("SerialNumberDropDownV2_%s",camRef);
                    SerialNumberDropDownV2_cameraValueChanged(app, simEvent)
                end
                cameraPlay(app.params, app.camera, app.params.paths.Data.RAW.CAMERA.local, app.sessionParams.sessionLabel, "start");
            end
            % cameraPlay(app.camera, app.params.paths.tempDir, app.sessionParams.sessionLabel, "stop");

            %% Build and Load model
            tg = app.slTarget;
            disp(strcat('Accessing', {' '},tg.TargetSettings.name,{'...'}));
            % set Sim Pameters
            % setSimulinkParams(app, model);
            
            % build            
            cd(fullfile(app.params.paths.modulePath));
            load_system(model);
            % update any changes (UNCOMMENT TO RETEST)
            % set_param(fullfile(app.params.paths.modulePath, model), 'SimulationCommand', 'update');
            % addpath(genpath(fullfile(app.params.paths.modulePath)));
            set_param(model, 'SimulationCommand', 'Update');
            Simulink.BlockDiagram.refreshBlocks(model)            
            % rmpath(genpath(fullfile(app.params.paths.modulePath)));
            slbuild(model);            
            load(tg,model);
            app.slTarget=tg;
            cd(fullfile(app.nCORTEx_repo));

            % STORE SLX PARAMETERS
            app.sessionParams = storeSlxParams(app, app.sessionParams);
            
            %% UNCOMMENT TO TEST
            % % load_system(fullfile(app.params.paths.modulePath,app.params.experiment));
            % setupAndRunTimedSimulation(app, model, app.SessionTimerminEditField.Value*60); % Stop after 10 seconds
            % Create and start the timer
            % wait ten seconds after speedgoat target is DONE
            app.params.slxTimerObj = timer('StartDelay', app.SessionTimerminEditField.Value*60+10, ...
                'TimerFcn', @(~,~)listenForStop(app), ...
                'ExecutionMode', 'singleShot');

            % PROGRGES BSAER            
            app.params.waitBarTimer = timer("ExecutionMode","fixedSpacing",'Period',1,'TimerFcn',@(~,~)updateWaitbar_cortex(app,1),'ExecutionMode','fixedRate');
         
            start(app.params.slxTimerObj);
            start(app.params.waitBarTimer);
            % cd(fullfile(app.params.paths.modulePath));
            start(app.slTarget)
            Simulink.sdi.view

            % check if ready for data buffering/streaming
            % refreshDataBufferStatus(app);

            % Draw a progress bar
            sessionTime = app.SessionTimerminEditField.Value*60; % minute to second conversion
            % while true
            % filledWidth=i;
            % log = getlogsout()
            if app.isStopped
                % If model completes without user's stop input, stop camera acquisition
                % app.params.spinParams.execStatus='stop';
                % spinParams = struct2dict(app, app.params.spinParams);
                % spinParamsJSON = jsonencode(spinParams);
                disp("stopping slx")
                stop(app.slTarget)
                disp(strcat('Application',{' '},'Stopped'))
                % Release resources
                % system(strjoin(["./callSpinTEx.sh" '' spinParamsJSON ' &']))
            elseif string(app.slTarget.ModelStatus.State)=="DONE"
                % If model completes without user's stop input, stop camera acquisition
                % app.params.spinParams.execStatus='stop';
                % spinParams = struct2dict(app, app.params.spinParams);
                % spinParamsJSON = jsonencode(spinParams);
                disp("stopping slx")
                stop(app.slTarget)
                disp(strcat('Application',{' '},'Stopped'))
                % Release resources
                % system(strjoin(["./callSpinTEx.sh" '' spinParamsJSON ' &']))
                % system("pkill python")
            end
            % end

        end

        function play_JOLT(app, model)
            %% CONFIGURE BUILD AND LOAD MODEL
            attachPanel(app, "JOLTPanel", "place");
            attachPanel(app, "JOLTPanel", "unhide");
            attachPanel(app,"ModalityPanel","hide");

            tg = app.slTarget;
            disp(strcat('Accessing', {' '},tg.TargetSettings.name,{'...'}));                       
            % build            
            cd(fullfile(app.params.paths.modulePath));
            load_system(model);
            % update any changes (UNCOMMENT TO RETEST)
            % set_param(fullfile(app.params.paths.modulePath, model), 'SimulationCommand', 'update');
            % addpath(genpath(fullfile(app.params.paths.modulePath)));
            set_param(model, 'SimulationCommand', 'Update');
            Simulink.BlockDiagram.refreshBlocks(model)            
            % rmpath(genpath(fullfile(app.params.paths.modulePath)));
            slbuild(model);            
            load(tg,model);
            app.slTarget=tg;
            cd(fullfile(app.nCORTEx_repo));                      
            % store slx params
            app.sessionParams = storeSlxParams(app, app.sessionParams);
            % Create and start the timer
            % wait ten seconds after speedgoat target is DONE
            app.params.slxTimerObj = timer('StartDelay', app.SessionTimerminEditField.Value*60+10, ...
                'TimerFcn', @(~,~)listenForStop(app), ...
                'ExecutionMode', 'singleShot');          
            app.params.waitBarTimer = timer("ExecutionMode","fixedSpacing",'Period',1,'TimerFcn',@(~,~)updateWaitbar_cortex(app,1),'ExecutionMode','fixedRate');                    
            start(app.params.waitBarTimer);
            start(app.params.slxTimerObj);
            % cd(fullfile(app.params.paths.modulePath));
            start(app.slTarget)
            Simulink.sdi.view;
            % Simulink.sdi.saveView("JOLT_view")
            Simulink.sdi.loadView("JOLT_view.mldatx")
            % isBuild=true;
            % loadSimulink(app, 'JOLT', isBuild);
            % Simulink.sdi.view
            % save session parameters
            % % app.sessionParams=app.params;
            % % app.sessionParams.npxlsFS=app.Npxls_FSEditField_npxlsT1.Value;
            % % app.sessionParams.sessionLabel=app.SessionLabelLabel.Text;
            % run application
            % runSimulink(app, 'JOLT');
            % initiate TCP: client connect to JOLT server
            app.tcp=tcpclient("192.168.7.5",8001);
            % parfeval(@slrtReader,0,app.dq,app.tcp);
            %% MAIN LOOP
            % enable JOLT buttons
            enableButton(app, "JOLTStartTrialButton", 1);
            app.trialCount=-1;
            app.trialMask=[];
            app.saveCount=0;
            app.discardCount=0;
            app.JOLTsaveCount.Text="--";
            app.JOLTdiscardCount.Text="--";

            % while true
            %     data = read(app.tcp);
            %     % realtimeUpdate(app,data);
            % end
        end

        function enableButton(app, buttonRef, EN)
            switch EN
                case 0 % disable case
                    darkBGC = modulateBGColor(app, app.(buttonRef).BackgroundColor, 'darken');
                    app.(buttonRef).BackgroundColor = darkBGC;
                    app.(buttonRef).Enable=false;
                case 1
                    brghtBGC = modulateBGColor(app, app.(buttonRef).BackgroundColor, 'brighten');
                    app.(buttonRef).BackgroundColor = brghtBGC;
                    app.(buttonRef).Enable=true;
            end
        end

        function enableField(app, fieldRef, EN, isHide)
            switch EN
                case 0
                    app.(fieldRef).Enable=false;
                case 1
                    app.(fieldRef).Enable=true;
            end
            switch isHide
                case 0
                    app.(fieldRef).Visible=true;
                case 1
                    app.(fieldRef).Visible=false;
            end
        end

        function rgb_out = modulateBGColor(app, rgb_in, action)
            switch action
                case 'darken'
                    hsv_in = rgb2hsv(rgb_in);
                    hsv_out = hsv_in;
                    hsv_out(3) = 0.24;
                    rgb_out = hsv2rgb(hsv_out);
                case 'brighten'
                    hsv_in = rgb2hsv(rgb_in);
                    hsv_out = hsv_in;
                    hsv_out(3) = 0.94;
                    rgb_out = hsv2rgb(hsv_out);
            end
        end

        function attachPanel(app, panelRef, action)
            switch action
                case 'place'
                    app.(panelRef).Position=app.(strcat(panelRef,"Pos"));
                case 'displace'
                    app.(panelRef).Position=app.(strcat(panelRef,"Pos"))+[550,0,0,0];
                case 'hide'
                    app.(panelRef).Visible=false;
                case 'unhide'
                    app.(panelRef).Visible=true;
            end
        end

        function loadSimulink(app, model, isBuild)
            %% Build and Load model
            disp(strcat('Accessing', {' '},app.slTarget.TargetSettings.name,{'...'}));
            % setSimulinkParams(app, model);
            if isBuild
                slbuild(model);
            end
            load(app.slTarget,model);
        end

        function runSimulink(app, model)
            disp(strcat('Running', {' '}, app.slTarget.TargetSettings.name,{'...'}));
            setSimulinkParams(app, model);
            start(app.slTarget);
        end

        function offloadRTLog(app)
            try
                logsout = evalin('base','logsout');
            catch
                logsout = [];
                disp("WARNING: no logsout found in the workspace, returning empty data structure");
            end
            realtimeLog = struct;
            realtimeLog.data = logsout;
            realtimeLog.session = compileSession(app);
            realtimeLog.metadata = app.sessionParams;
            % if ~exist(fullfile(app.params.paths.tempDir,"SLRT"),'dir'); mkdir(fullfile(app.params.paths.tempDir,"SLRT")); end
            save(fullfile(app.params.paths.Data.RAW.SLRT.local,strcat(app.sessionParams.sessionLabel,".mat")),"realtimeLog");
        end

        function session = compileSession(app)
            % store all entry values and items
            appFields = fieldnames(app);
            % tabFields = fieldnames(app.tabs);
            session = struct;
            session = saveSessionStat(app, session, app);
        end

        function session = saveSessionStat(app, session, obj)
            fields = fieldnames(obj);
            % recover ALL console properties
            if strcmp(class(obj),'nCORTEx_host')
                meta = metaclass(obj);
                metaProps = meta.PropertyList;
                fields = {};
                for i = 1:length(metaProps)
                    metaProp = metaProps(i);
                    definingClass = metaProp.DefiningClass.Name;
                    if strcmp(definingClass,'nCORTEx_host');
                        fields = [fields; metaProp.Name];
                    end

                end
            end
            for i = 1:length(fields)
                field = fields{i};
                elem = obj.(field);
                if isstruct(elem) && ~strcmp((field),'tabs') && ~strcmp((field),'photom') && ~strcmp((field),'photon')
                    session.(field) = obj.(field);
                elseif ~strcmp(field,'originalMatlabPath')
                    if isprop(elem,'Value')
                        session.(field).Value = elem.Value;
                    end
                    if isprop(elem,'Text')
                        session.(field).Text = elem.Text;
                    end
                    if isprop(elem,'Items')
                        session.(field).Items = elem.Items;
                    end
                    if isprop(elem,"AltText")
                        session.(field).AltText = elem.AltText;
                    end
                    if strcmp(string(field),'tabs')
                        % session.(field) = elem;
                        session.tabs = struct;
                        session.tabs = saveSessionStat(app, session.tabs, elem);
                    elseif strcmp(string(field),'photom') || strcmp(string(field),'photon')
                        session.(field) = struct;
                        % store channels
                        if isfield(elem,'channels')
                            session.(field).channels = struct;
                            session.(field).channels = saveSessionStat(app, session.(field).channels, elem.channels);
                        end
                    end
                end
            end
        end

        function clearTabs(app, tabs)
            tabFields = fieldnames(tabs);
            for i = 1:length(tabFields)
                tabField = tabFields{i};
                delete(app.tabs.(tabField));
            end
        end

        function playSpecialCallback(app, fieldRef)
            % refHandle = referenceTabField(app,fieldRef,"hndl",[]);
            if contains(fieldRef,"SerialNumberDropDownV2_camera") 
                % val = referenceTabField(app,fieldRef,"Value",[]);
                simEvent.Source.Tag = referenceTabField(app,fieldRef,"Tag",[]);
                % referenceTabField(app,fieldRef,"Value",val);
                SerialNumberDropDownV2_cameraValueChanged(app,simEvent);
            elseif contains(fieldRef,"CameraTargetDropDownV2_camera")               
                tabRef = split(fieldRef,"_");
                tabRef = tabRef{2};
                SNRef = sprintf("SerialNumberDropDownV2_%s",tabRef);
                simEvent.Source.Tag = referenceTabField(app,SNRef,"Tag",[]);
                SerialNumberDropDownV2_cameraValueChanged(app,simEvent);
            end
        end

        function recoverSession(app)
            extrctLogs = dir(fullfile(app.params.paths.projDir_cloud,"Experiments",app.params.experiment,"Extraction-Logs"));
            extractionLog = readtable(fullfile(extrctLogs(3).folder,extrctLogs(3).name),"Delimiter",',');
            subject = app.SubjectDropDown_expmnt.Value;
            if ~isempty(extractionLog)
                subjectSessions = extractionLog(contains(extractionLog.SessionName,subject),:);
                if ~isempty(subjectSessions)
                    latestSession = string(subjectSessions(end,:).SessionName);
                    % rawSLRTPath = fullfile(app.params.paths.projDir_cloud,"Experiments",app.params.experiment,"Data","RAW","SLRT");
                    rawSLRTlocalPath = app.params.paths.Data.RAW.SLRT.local;
                    rawSLRTcloudPath = app.params.paths.Data.RAW.SLRT.cloud;
                    try
                        load(fullfile(rawSLRTcloudPath,sprintf("%s.mat",latestSession)),'realtimeLog');
                    catch 
                        try
                            disp("could not recover previous session from cloud, checking local...")
                            load(fullfile(rawSLRTlocalPath,sprintf("%s.mat",latestSession)),'realtimeLog');
                        catch
                            disp("ERROR: LATEST SESSION IN THE LOG FILE NOT FOUND");
                            return
                        end
                    end
                    if isfield(realtimeLog,'session')
                        session = realtimeLog.session;
                        loadSession(app, session);
                    end
                end
            end
            LabelButtonPushed(app);
        end

        function loadSession(app, session)
            fields = fieldnames(session);
            for i = 1:length(fields)
                field = fields{i};
                if isprop(app, field) && (~contains(field,"_expmnt") && ~contains(field,"_slrt")) && ~contains(field,"_proj") 
                    % RECOVER TABS
                    if strcmp(field,'tabs')
                        % app.(field) = session.(field);
                        tabFields = fieldnames(session.(field));
                        surveyTabs(app, tabFields);
                        loadSessionTabs(app, app.tabs, session.(field));
                    elseif strcmp(field,'photom') || strcmp(field,'photon')
                        % RECOVER CHANNELS
                        try
                            if isfield(session.(field),'channels')
                                % surveyTabs(app, fieldnames(session.(field).channels))
                                loadSessionTabs(app, app.(field).channels, session.(field).channels)                    
                            end
                        catch
                            disp("no channels")
                        end
                    else
                        % RECOVER UI ENTRIES
                        sessFields = fieldnames(session.(field));
                        for j = 1:length(sessFields)
                            sessField = sessFields{j};
                            if any(isprop(app.(field), sessField) | isstruct(app.(field)))
                                if isprop(app.(field),'Items') && strcmp(sessField,"Items") && ~strcmp(class(app.(field)),"matlab.ui.control.ToggleSwitch") && ~contains(field,"PanelSwitch") 
                                    % app.(field).Items = session.(field).Items;
                                    sessItems = string(session.(field).Items);
                                    appItems = string(app.(field).Items);                                    
                                    allItems = sort(unique([sessItems, appItems]));                            
                                    allItems = allItems(~strcmp(allItems,"+"));
                                    allItems = allItems(~strcmp(allItems,"None"));
                                    allItems = ["None", sort(allItems), {"+"}];
                                    try
                                        % app.(field).Items = categorical(allItems);
                                        app.(field).Items = (allItems);
                                    catch
                                        disp(sprintf("BROKE_%s",field));
                                    end
                                else
                                    try
                                        if ~strcmp(field,"params")
                                            app.(field).(sessField) = session.(field).(sessField);
                                        end
                                    catch
                                        disp(sprintf("BROKE ALL_%s",field));
                                    end
                                end                               
                            end
                        end
                    end
                end
                % INITIATE UNIQUE CALLBACKS ON STARTUP
                playSpecialCallback(app, field);
            end
        end

        function loadSessionTabs(app, obj, session)
            fields = fieldnames(session);
            for i = 1:length(fields)
                field = fields{i};
                if isfield(obj, field)
                    % TABS WITHIN TABS CASE (UNUSUAL)
                    if strcmp(field,'tabs') 
                        % app.(field) = session.(field);
                        tabFields = fieldnames(session.(field));
                        surveyTabs(app, tabFields);
                        loadSessionTabs(app, obj.tabs, session.(field));
                    else
                        % RECOVER TABS UI ENTRIES
                        sessFields = fieldnames(session.(field));
                        sessFields = string(sessFields);
                        if any(contains(sessFields,"Items"))
                            sessFields = move2front(sessFields,"Items");
                        end
                        for j = 1:length(sessFields)
                            sessField = sessFields(j);                            
                            if any(isprop(obj.(field), sessField) | isstruct(obj.(field)))
                                if strcmp(sessField,"Items") %strcmp(sessField,"Items") && ~strcmp(class(app.(field)),"matlab.ui.control.ToggleSwitch")
                                    % sessItems = session.(field).Items;
                                    % appItems = obj.(field).Items;
                                    % allItems = unique([sessItems; appItems]);             
                                    sessItems = session.(field).Items;
                                    appItems = obj.(field).Items;                                    
                                    allItems = sort(unique([sessItems appItems]));                            
                                    allItems = allItems(~strcmp(allItems,"+"));
                                    allItems = allItems(~strcmp(allItems,"None"));
                                    allItems = ["None" sort(allItems) {"+"}];
                                    obj.(field).Items = allItems;
                                else
                                    disp(sessField); 
                                    disp(field);
                                    obj.(field).(sessField) = session.(field).(sessField);                                    
                                end
                            end
                        end
                    end
                end

            end
        end

        function surveyTabs(app, tabFields)
            tabFields = string(tabFields);
            if ~isempty(tabFields)
                tabFieldParts = arrayfun(@(x)split(x, '_'),tabFields,"UniformOutput",false);
                % tabFieldTags = tabFieldParts(:,2);
                tabFieldTags = arrayfun(@(x) x{1,1}(2),tabFieldParts,"UniformOutput",false);
                tabFieldTagsUnique = unique(cellstr(tabFieldTags));
                tabFieldTagsUniqueNum = cellfun(@(x) string(strrep(x,"T","_")), tabFieldTagsUnique, 'UniformOutput', false);
                
                tabCounts = countTabs(app, (tabFieldTagsUniqueNum));
                tabCountFields = fieldnames(tabCounts);
                for i = 1:length(tabCountFields)
                    tabCountField = tabCountFields{i};
                    tabCount = tabCounts.(tabCountField)-1;
                    for j = 1:tabCount
                        addTabCallback(app,tabCountField);
                    end
                end
            end
        end

        function tabCounts = countTabs(app, tabFieldTags)
            tabCounts = struct;
            for i = 1:length(tabFieldTags)
                tabFieldTag = tabFieldTags{i};
                tabParts = split(tabFieldTag,'_');
                tabTag = tabParts(1);
                tabNum = str2double(tabParts(2));
                if ~isfield(tabCounts, tabTag)
                    tabCounts.(tabTag) = tabNum;
                elseif tabCounts.(tabTag) < tabNum
                    % update to highest number
                    tabCounts.(tabTag) = tabNum;
                end
            end
        end

        function val = isMember(app, obj, field)
            objType = class(obj);
            switch objType
                case 'nCORTEx_console'
                    val = isprop(obj, field);
                case 'struct'
                    val = isfield(obj, field);
                otherwise
                    val = 0;
            end
        end

        function panelFields = getPanelFields(app, panelRef)
            appFn = fieldnames(app);
            panelFields = appFn(contains(appFn,panelRef));
        end

        function alternateTabs(app, panelRef, tabNum)
            appFn = fieldnames(app);
            % find all tabs referenced by a panelGroup (e.g. Pharmacology)
            panelTabs = appFn(contains(appFn,sprintf("%sTab",panelRef)));
            numTabs = length(panelTabs);
            % switch to selected tab and display
            for i=1:numTabs
                tabID = panelTabs{i};
                if contains(tabID,sprintf("Tab%d",tabNum))
                    app.(tabID).BackgroundColor=app.activeGrey;
                    % enable/display all tab1 fields
                    panelFields = getPanelFields(app,panelRef);
                    tabPanelFields_i = panelFields(contains(panelFields,sprintf("%sT%d",panelRef,tabNum)));
                    for j=1:length(tabPanelFields_i)
                        tabField = tabPanelFields_i{j};
                        EN = 1;
                        isHide = 0;
                        enableField(app, tabField, EN, isHide);
                    end
                else
                    app.(tabID).BackgroundColor=app.disableGrey;
                    % disable/hide all other tab fields
                    otherTabNum = sscanf(tabID,strcat(panelRef,"Tab","%s"));
                    otherTabNum = str2double(otherTabNum(1));
                    panelFields = getPanelFields(app,panelRef);
                    tabPanelFields_i = panelFields(contains(panelFields,sprintf("%sT%d",panelRef,otherTabNum)));
                    for j=1:length(tabPanelFields_i)
                        tabField = tabPanelFields_i{j};
                        EN = 0;
                        isHide = 1;
                        enableField(app, tabField, EN, isHide);
                    end
                end
            end
        end

        function logSession(app, expmntPath, SessionName)
            disp('logging session')
            % app.sessionParams.qcPassed=0;
            % dir(app.params.paths.projDir_local)
            % projDir_local = fullfile(app.SelectProjectDirectoryDropDownLabel.Text);
            
            % sessionLogPath = fullfile(app.sessionParams.paths.projDir_cloud,"Experiments",app.sessionParams.expmnt,"SessionLog.csv");            
            % if ~exist(sessionLogPath,"file")
            %     sessionLogTable = initSessionLogTable(app);
            %     sessionLogTable.SessionLabel = string(app.sessionParams.sessionLabel);
            %     sessionLogTable.QCPassed = app.sessionParams.qcPassed;
            %     writetable(sessionLogTable,sessionLogPath);
            % else
            %     tmpSessionLogTable = initSessionLogTable(app);
            %     tmpSessionLogTable.SessionLabel = app.sessionParams.sessionLabel;
            %     tmpSessionLogTable.QCPassed = app.sessionParams.qcPassed;
            %     currentSessionLogTable = readtable(sessionLogPath,"Delimiter",",");
            %     % append new acquisition to the existing session log
            %     newSessionLogTable = [currentSessionLogTable; tmpSessionLogTable];
            %     writetable(newSessionLogTable,sessionLogPath);
            % end
            % Log session in cloud for extraction
            if isempty(expmntPath)
                SessionName = string(app.sessionParams.sessionLabel);
                expmntPath = fullfile(app.sessionParams.paths.projDir_cloud,"Experiments",app.sessionParams.expmnt);                
                TrialMask=app.trialMask;
            else
                TrialMask = [];
            end
            Extracted=0;
            if isempty(TrialMask); TrialMask = 0; end
            formatString = repmat('%d-',size(TrialMask,1),size(TrialMask,2));
            TrialMask = string(compose(formatString,TrialMask));
            extractionRow = table(SessionName,Extracted,TrialMask);            
            log4Extraction(expmntPath,extractionRow,'csv');
            app.trialCount=0;
            app.trialMask=[];
            evalin('base', 'clear logsout');
        end

        function sessionLogTable = initSessionLogTable(app)
            colNames = {'SessionLabel','QCPassed'};
            sessionLogTable = array2table(cell(1,length(colNames)),"VariableNames",colNames);
        end

        function subjNameTable = initSubjNameTable(app)
            colNames = {'SubjectName','IDNum','dateSNI'};
            cellInit = cell(size(colNames,1), size(colNames,2));
            subjNameTable = array2table(cellInit,"VariableNames",colNames);
        end


        function gX = updateGateNum(app, sessionLabel)
            % projDir_local = fullfile(app.SelectProjectDirectoryDropDownLabel.Text);
            projDir_cloud = app.ProjectPathTextEditField_proj.Value;
            % projDir_cloud = app.params.paths.projDir_cloud;
            gX = 0;
            if ~strcmp(app.params.experiment,"None")
                ExtractionLogPath = fullfile(projDir_cloud,"Experiments", app.params.experiment,"Extraction-Logs");
                extractionLogs = dir(ExtractionLogPath);
                extractionLog = extractionLogs(3);
                if exist(fullfile(extractionLog.folder,extractionLog.name),"file")
                    extractionLog = readtable(fullfile(extractionLog.folder,extractionLog.name),"Delimiter",",");
                    sessionLabels = extractionLog(:,"SessionName");
                    ungatedSessLbls = regexprep(table2cell(sessionLabels),"_g\d+","");
                    gX = sum((strcmp(table2array(table(ungatedSessLbls)),sessionLabel)));
                else
                    gX = 0;
                end
            end
        end

        function stopTimers(app)
            if app.bufferTimer.Running=="on"
                stop(app.bufferTimer);
            end
            if app.trialEndTimer.Running=="on"
                stop(app.trialEndTimer);
            end
        end

        function presets = scanSubjectPresets(app)
            subjPresetDir = fullfile(app.SelectProjectDirectoryDropDownLabel.Text,"Experiments","Subject Presets");
            % subjPresetDir = fullfile(app.ProjectPathTextEditField_proj.Value,"Experiments","Subject Presets");
            if exist(subjPresetDir,"dir")
                presets=dir(fullfile(subjPresetDir,"*.mat"));
            else
                presets=[];
            end
        end

        function toggleItem(app, currentHandle, nextHandle)
            % place next onto current
            app.(nextHandle).Position = app.(currentHandle).Position;
            % hide/disable current
            app.(currentHandle).Visible=false;
            % unhide/enable next
            app.(nextHandle).Visible=true;
        end

        function loadSubjectPreset(app, presetFileName)
            % Load preset values
            subjPresetDir = fullfile(app.SelectProjectDirectoryDropDownLabel.Text,"Experiments","Subject Presets");
            load(fullfile(subjPresetDir,strcat(presetFileName,".mat")));
            presetFields = fieldnames(preset);
            % Assign preset values to app
            for i=1:length(presetFields)
                field = presetFields(i);
                field = string(field);

                if isprop(app.(field),"Value")
                    val = preset.(field).Value;
                    app.(field).Value = val;
                    callbackRef = sprintf("%sValueChanged(app)",field);
                    eval(callbackRef);
                elseif isprop(app.(field),"AltText") && isprop(app.(field),"ImageSource")
                    val = preset.(field).AltText;
                    app.(field).AltText = val;
                    % callbackRef = sprintf("Im")
                elseif isprop(app.(field),"Text")
                    val = preset.(field).Text;
                    app.(field).Text = val;
                end
            end
        end

        function subjectLabel = storePreset(app)
            subjectLabel = app.SubjectLabelLabel.Text;
            subjPresetDir = fullfile(app.SelectProjectDirectoryDropDownLabel.Text,"Experiments","Subject Presets");
            appFields = fieldnames(app);
            preset=struct;
            for i=1:length(appFields)
                field = appFields(i);
                field = string(field);
                if any(contains(field, strcat("_",string(app.presetMask))))
                    % preset.(field)=app.(field).Value;
                    if isprop(app.(field),"Value")
                        preset.(field).Value = app.(field).Value;
                    elseif isprop(app.(field),"AltText")
                        preset.(field).AltText = app.(field).AltText;
                    elseif isprop(app.(field),"Text")
                        preset.(field).Text = app.(field).Text;
                    end
                end
            end
            if ~exist(subjPresetDir,"dir")
                mkdir(subjPresetDir);
            end
            save(fullfile(subjPresetDir,strcat(subjectLabel,".mat")),"preset","-mat");
        end


        function logSubjectName(app)
            subjectLabel = app.SubjectLabelLabel.Text;
            % cloudProjPath = app.params.paths.projDir_cloud;
            cloudProjPath = app.ProjectPathTextEditField_proj.Value;
            subjNameLogPath = fullfile(cloudProjPath,"Experiments","subjectLog.csv");
            if ~exist(subjNameLogPath,"file")
                subjNameTable = initSubjNameTable(app);
                subjNameTable.SubjectName = subjectLabel;
                subjNameTable.IDNum = app.subjIDNum;
                writetable(subjNameTable,subjNameLogPath);
            else
                tmpSubjNameTable = initSubjNameTable(app);
                tmpSubjNameTable.SubjectName = subjectLabel;
                tmpSubjNameTable.IDNum = app.subjIDNum;
                currentSubjNameTable = readtable(subjNameLogPath,"Delimiter",",");
                % append new acquisition to the existing subject log
                % tmpSubjNameTable = matchTableTypes(tmpSubjNameTable, currentSubjNameTable);
                newSubjNameTable = [currentSubjNameTable; tmpSubjNameTable];
                writetable(newSubjNameTable,subjNameLogPath);
            end
        end

        function populateDropDown(app, dropDownHandle, items)
            app.(dropDownHandle).Items = [cellstr("None"), categorical(items'), cellstr("+")];
            app.(dropDownHandle).Value = "None";
        end

        function shutdownFcn(app)
            % Shutdown
            disp('App is closing.');
            % Restore the original MATLAB path and other cleanup actions
            path(app.originalMatlabPath);
            % Close the app
            delete(app.UIFigure);
        end

        function folders = listFolders(app, directoryPath)
            % Get a list of all files and folders in this folder.
            files = dir(directoryPath);

            % Get a logical vector that tells which is a directory.
            dirFlags = [files.isdir];

            % Extract only those that are directories.
            subFolders = files(dirFlags);

            % Remove directories you don't want to list (e.g., '.', '..', '.git', 'slxHelpers').
            unwantedDirs = {'.', '..', '.git', 'slxHelpers'};
            subFolders = subFolders(~ismember({subFolders.name}, unwantedDirs));

            % Get a list of the folder names.
            folders = {subFolders.name};
        end

        function adjustPanelPositions(app)
            % Example panels - add your actual panel handles here
            panels = [app.cameraPanel, app.photomPanel, app.sensePanel, app.npxlsPanel, app.photonPanel, app.optoPanel, app.rxPanel, app.gxPanel]; % Corrected to include each panel once

            % Initial position for the first panel, if none are visible
            startPosition = [6, 6, 232, 301]; % Corrected syntax error here

            % Track the right edge of the last visible panel
            lastRightEdge = startPosition(1);

            % Gap between panels
            gap = 15;

            for i = 1:length(panels)
                if strcmp(panels(i).Visible, 'on')
                    % If panel is visible, set its position to be next to the last
                    % with an additional gap
                    panels(i).Position = [lastRightEdge, startPosition(2), startPosition(3), startPosition(4)];

                    % Update lastRightEdge for the next panel, include the gap
                    lastRightEdge = lastRightEdge + startPosition(3) + gap;
                end
            end
        end

        function hideOtherChannels(app, channels, chanRef, panelRef, channelsRef)
            chans = string(fieldnames(channels));
            chans2hide = chans(~contains(chans,chanRef));
            for i = 1:length(chans2hide)
                chan2hide = chans2hide(i);
                tabButtonRef = sprintf("Subtab_%s_%s",panelRef,chan2hide);
                % app.(tabButtonRef).BackgroundColor = app.disableGrey;
                referenceTabField(app,tabButtonRef,"BackgroundColor",app.disableGrey);
                chanFields2hide = fieldnames(channels.(chan2hide));
                for j = 1:length(chanFields2hide)
                    chanField2hide = chanFields2hide{j};
                    if ~strcmp(chanField2hide,tabButtonRef)
                        app.photom.(channelsRef).(chan2hide).(chanField2hide).Visible="off";
                    end
                    % app.photom.channels.(chan2hide).(chanField2hide).Visible="on";
                end
            end
        end


    end

    methods (Access = private)
        function listenForEnd(app, src, event)
            data = read(app.tcp);
            % disp(data)
            if any(~isempty(data)) && any((data~=0))
                stop(app.trialEndTimer);
                enableButton(app, "JOLTSaveButton", 1);
                enableButton(app, "JOLTDiscardButton", 1);
            end
        end

        function bufferMonofil(app, src, event)
            data = read(app.tcp);
            % disp(length(data));
            % monofil = data(2);
            % npxlsAxq = data(1);
            if ~isempty(data)
                % disp(length(app.monofilBuffer));
                % dataLen = length(data);
                % disp(dataLen);
                % strt = length(app.monofilBuffer)-length(app.MonofilAxes.XTick)+1+dataLen;
                app.monofilBuffer=cat(2,app.monofilBuffer(1:end),data);
                % disp(length(app.monofilBuffer));
            end
            if any(~isempty(data)) && any((data==0))
                % disp(data);
                % disp(length(app.monofilBuffer));
                stop(app.bufferTimer);
                pltMonofil(app);
                start(app.trialEndTimer)
            end
        end

        
        function pltMonofil(app)
            % maxStim = max(app.monofilBuffer);
            % minStim = min(app.monofilBuffer(app.monofilBuffer>1));
            % app.MonofilAxes.YLim = [(minStim - 0.05*minStim), (maxStim + 0.05*maxStim)];
            disp(app.monofilBuffer);
            strtIdx = find(app.monofilBuffer==1);
            strtIdx = strtIdx(end)-500; % start at 500 ms before stim
            app.monofilBuffer = app.monofilBuffer(strtIdx:end); % truncate beginning of monofilBuffer collected at trialstart
            bufferLen = length(app.monofilBuffer);
            app.MonofilAxes.XTick = [1:bufferLen]./bufferLen;


            % compute lowess
            monofilBuffer_preLowess = app.monofilBuffer;
            monofilBuffer_preLowess(monofilBuffer_preLowess==1) = mean(monofilBuffer_preLowess);
            monofilBuffer_lowess = lowess(double(monofilBuffer_preLowess)',0.12);

            maxStim = max(monofilBuffer_lowess);
            minStim = min(monofilBuffer_lowess(monofilBuffer_lowess>1));
            app.MonofilAxes.YLim = [(minStim - 0.05*minStim), (maxStim + 0.05*maxStim)];
            % disp(max_idx);
            % xline(app.MonofilAxes,max_idx,"Color",app.cyberGrey);
            for i = 1:length(monofilBuffer_lowess)-20
                X=app.MonofilAxes.XTick;
                x=X(i);
                y_loess=monofilBuffer_lowess(i);
                y_uint8 = app.monofilBuffer(i);
                if y_uint8 == 1
                    xline(app.MonofilAxes,x,"Color",app.cyberGrey);
                elseif y_loess == maxStim
                    xline(app.MonofilAxes,x,"Color",app.cyberRed);
                elseif y_uint8~=0
                    addpoints(app.animLine,x,y_loess);
                end

                % plot(app.MonofilAxes,x,y,"Color",app.cyberGreen);
                % pause(0.000001);
            end
        end

        function addressModifiers(tabParts)
            % check second mod
            if contains(tabParts{3},"chan")
                panelRef = tabParts{2};
                tabNum = split(panelRef,"T");
                tabNum = str2double(tabNum{2});
                channelsRef = sprintf("channels%d",tabNum);

            elseif contains(tabParts{3},"shank")
            end
        end

        function addTabCallback(app, modRef)
            % tab Ref
            tabRef = sprintf("%sT", modRef);
            tab1Ref = sprintf("%sT1",modRef);
            % mask modality associated fields
            appFields = fieldnames(app);
            tabFields = fieldnames(app.tabs);
            tab1Fields = appFields(contains(appFields,tab1Ref));
            tabFields = [appFields(contains(appFields,tabRef)); tabFields(contains(tabFields, tabRef))];
            % get max number of current tabs
            N = length(tabFields) / length(tab1Fields) + 1;
            tabNRef = sprintf("%sT%d", modRef, N);
            % initiate tab N copy (N = maxNumTabs + 1); visibility = off, same
            for i = 1:length(tab1Fields)
                tab1Field = tab1Fields{i};
                tabNField = strrep(tab1Field, tab1Ref, tabNRef);
                tabParts = string(split(tabNField,"_"));
                % contains(tabNField,"_chan");
                % check for second modifier
                % loc = contains(tabParts,"chan");
                % if loc(3)
                % addressModifiers(tabParts);
                % else
                % tabNField = sprintf("%s_%sT%d", tab1Field, modRef, N);
                % app.tabs.(tabNField) = copyobj(app.(tab1Field),app.(sprintf("%sPanel",modRef)));
                app.tabs.(tabNField) = copyobj(app.(tab1Field),app.UIFigure);
                panelPos = app.(sprintf("%sPanel",modRef)).Position;
                modPanelPos = app.ModalityPanel.Position;
                tab1Parent = referenceTabField(app,tab1Field,"Parent",[]);
                tab1ParentTag = tab1Parent.Tag;
                tab1ParentTagParts = split(tab1ParentTag,"_");
                if length(tab1ParentTagParts) > 1
                    tab1PrntRef = tab1ParentTagParts{2};
                    tabNParentTag = strrep(tab1ParentTag,tab1PrntRef,tabNRef);
                    tabNParent = referenceTabField(app,tabNParentTag,"hndl",[]);
                    referenceTabField(app,tabNField,"Parent",tabNParent);
                else
                    app.tabs.(tabNField).Parent = app.(tab1Field).Parent;                
                end
                
                
                app.tabs.(tabNField).Position = app.(tab1Field).Position; %+ [panelPos(1), panelPos(2), 0, 0]; %+ [modPanelPos(1), modPanelPos(2), 0, 0];
                app.tabs.(tabNField).Visible = 'off';
                app.tabs.(tabNField).Tag = tabNField;
                % get type of ui comp. here

                if strcmp(tabNField, sprintf("Tab_%sT%d",modRef,N))
                    % place next to next highest Num tab button
                    % tabNPrevField = sprintf("%s_%sT%d", tabNPreField, modRef, N-1);
                    tabNPrevRef = sprintf("%sT%d",modRef,N-1);
                    tabNPrevField  = strrep(tab1Field, tab1Ref, tabNPrevRef);
                    if N > 2
                        tabPrevPos = app.tabs.(tabNPrevField).Position;
                    else
                        tabPrevPos = app.(tabNPrevField).Position;
                    end

                    space = tabPrevPos(3) + tabPrevPos(3)*0.1;
                    app.tabs.(tabNField).Position = tabPrevPos + [space, 0, 0, 0];
                    app.tabs.(tabNField).Visible = 'on';
                    app.tabs.(tabNField).BackgroundColor = app.disableGrey;
                    app.tabs.(tabNField).ButtonPushedFcn = createCallbackFcn(app, @tabSelectCallback,true);
                else
                    switch class(app.tabs.(tabNField))
                        case 'matlab.ui.control.DropDown'
                            % app.tabs.(tabNField).ValueChangedFcn = createCallbackFcn(app, @dropDownLabelCallback, true);
                            app.tabs.(tabNField).ValueChangedFcn = app.(tab1Field).ValueChangedFcn;
                        case 'matlab.ui.control.Image'
                            app.tabs.(tabNField).ImageClickedFcn = app.(tab1Field).ImageClickedFcn;
                        case 'matlab.ui.control.Button'
                            app.tabs.(tabNField).ButtonPushedFcn = app.(tab1Field).ButtonPushedFcn;
                        case 'matlab.ui.control.StateButton'
                            app.tabs.(tabNField).ValueChangedFcn = app.(tab1Field).ValueChangedFcn;
                        case 'matlab.ui.control.EditField'
                            app.tabs.(tabNField).ValueChangedFcn = app.(tab1Field).ValueChangedFcn;
                    end
                end
                % end


            end

            % generate new panel-specific channels / shanks
            if strcmp(modRef,'photon') || strcmp(modRef,'photom')
                addChannels(app,tabNRef);
            end

            % Automatically switch to the newly added tab
            % Construct a simulated event with the new tab component as the source
            simulatedEvent = struct('Source', app.tabs.(tabNField));
            tabSelectCallback(app, simulatedEvent);
        end

        function addChannels(app, tabRef)
            modRef = split(tabRef,'T');
            tabNum = str2double(modRef(2));
            modRef = modRef(1);
            appFields = fieldnames(app);
            tabFields = fieldnames(app.tabs);
            appFields = [appFields; tabFields];
            chanFieldRef = sprintf("%s_chan1",tabRef);            
            channelFields = appFields(contains(appFields,chanFieldRef));
            subtabButtonRefs = appFields(contains(appFields,"Subtab") & contains(appFields,tabRef));
            numChans = length(subtabButtonRefs);
            
            for i = 1:length(channelFields)
                chanField = channelFields{i};                
                for j = 1:numChans
                    NField = sprintf("%s_chan%d",tabRef,j);
                    chanNField = strrep(chanField,chanFieldRef,NField);
                    if ~contains(chanNField,"Subtab") %&& ~contains(chanField,"chanNumLabel")
                        % initialize if not already
                        if ~isfield(app.(modRef),"channels"); app.(modRef).channels = struct; end
                        % if ~isfield(app.photom.channels,chanField); app.photom.(channelsRef).(chanRef) = struct; end                        
                        if ~isfield(app.(modRef).channels,(chanNField)) %|| ~isvalid(app.photom.channels)
                            field2Copy = referenceTabField(app,chanField,"hndl",[]);
                            try
                                app.(modRef).channels.(chanNField) = copyobj(field2Copy,app.UIFigure);
                            catch
                                disp("%s_%s_BROKE",chanRef,chanNField)
                                continue
                            end
                            app.(modRef).channels.(chanNField).Parent = field2Copy.Parent;
                            app.(modRef).channels.(chanNField).Position = field2Copy.Position; %+ [STEP, 0, 0, 0];
                            app.(modRef).channels.(chanNField).Visible = "off";
                            if isprop(field2Copy,"ValueChangedFcn")
                                app.(modRef).channels.(chanNField).ValueChangedFcn = field2Copy.ValueChangedFcn;
                            end
                        elseif ~isvalid(app.(modRef).channels.(chanNField))
                                field2Copy = referenceTabField(app,chanField,"hndl",[]);
                            try
                                app.(modRef).channels.(chanNField) = copyobj(field2Copy,app.UIFigure);
                            catch
                                disp("%s_%s_BROKE",chanRef,chanNField)
                                continue
                            end
                            app.(modRef).channels.(chanNField).Parent = field2Copy.Parent;
                            app.(modRef).channels.(chanNField).Position = field2Copy.Position; %+ [STEP, 0, 0, 0];
                            app.(modRef).channels.(chanNField).Visible = "off";
                            if isprop(field2Copy,"ValueChangedFcn")
                                app.(modRef).channels.(chanNField).ValueChangedFcn = field2Copy.ValueChangedFcn;
                            end
                        end
                        % % DISABLE STARTER FIELDS AFTER COPIED
                        % referenceTabField(app,chanField,"Visible","off");
                        referenceTabField(app,chanField,"Enable","off"); 
                    end                       
                end                            
            end
        end

        function tabSelectCallback(app, event)
            tabNField = event.Source.Tag;

            % Determine if the tab is directly under app or under app.tabs
            if isprop(app, tabNField)
                % The tab is directly under app
                uiTab = app.(tabNField);
            elseif isfield(app.tabs, tabNField)
                % The tab is under app.tabs
                uiTab = app.tabs.(tabNField);
            else
                warning('Tab "%s" does not exist.', tabNField);
                return; % Exit the function if the tab doesn't exist
            end

            % Now, with the uiTab correctly identified, proceed with selecting the tab
            selectTab(app, uiTab);
        end

        function registerTabs(app)
            appFields = fieldnames(app);
            for i = 1:length(appFields)
                appField = appFields{i};
                if contains(appField,'AddTab_')
                    modRef = split(appField,'_');
                    modRef = string(modRef(2));
                    app.(appField).Tag = modRef;
                    app.(appField).ButtonPushedFcn = createCallbackFcn(app, @AddTab_ButtonPushed, true);
                elseif contains(appField, 'RemoveTab_')
                    modRef = split(appField, '_');
                    modRef = string(modRef(2));
                    app.(appField).Tag = modRef;
                    % Link to a generic button pushed function for tab removal
                    app.(appField).ButtonPushedFcn = createCallbackFcn(app, @(src, event)removeTabCallback(app, modRef), true);
                elseif contains(appField,'Tab_')
                    app.(appField).Tag = appField;
                    if isempty(app.(appField).ButtonPushedFcn); app.(appField).ButtonPushedFcn = createCallbackFcn(app, @Tab_T1ButtonPushed, true); end
                end
            end

        end

        function removeTabCallback(app, modRef)
            % Initial setup to identify relevant fields and tabs
            tabRefPattern = sprintf('%sT', modRef);
            appFields = fieldnames(app);
            tabFields = fieldnames(app.tabs);
            relevantTabFields = [appFields(contains(appFields, tabRefPattern)); tabFields(contains(tabFields, tabRefPattern))];

            % Determine the highest tab number based on the relevant fields
            tabNumbers = zeros(size(relevantTabFields));
            for i = 1:length(relevantTabFields)
                fieldName = relevantTabFields{i};
                numberMatches = regexp(fieldName, [tabRefPattern '(\d+)'], 'tokens');
                if ~isempty(numberMatches)
                    tabNumbers(i) = str2double(numberMatches{1}{1});
                end
            end

            % Find the highest tab number
            N = max(tabNumbers);
            if isempty(N) || isnan(N)
                disp('No tabs to remove.');
                return;
            end

            % If there's a previous tab, automatically switch to it
            if N > 1

                % Remove components associated with the Nth tab
                tabNRef = sprintf('%sT%d', modRef, N);
                for i = 1:length(relevantTabFields)
                    if contains(relevantTabFields{i}, tabNRef)
                        if isfield(app.tabs, relevantTabFields{i})
                            delete(app.tabs.(relevantTabFields{i}));  % Delete the UI component from the figure
                            app.tabs = rmfield(app.tabs, relevantTabFields{i});  % Remove the field from app.tabs
                        elseif isprop(app, relevantTabFields{i})
                            delete(app.(relevantTabFields{i}));  % Delete the UI component from the app
                        end
                    end
                end

                tabPrevRef = sprintf('%sT%d', modRef, N-1);
                % Construct the field name for the previous tab
                fieldName = sprintf('Tab_%s', tabPrevRef);

                % Check if the field exists directly under app or under app.tabs and then construct the simulatedEvent accordingly
                if isprop(app, fieldName)
                    % If the tab is a direct property of app
                    simulatedEvent = struct('Source', app.(fieldName));
                    tabSelectCallback(app, simulatedEvent);
                elseif isfield(app.tabs, fieldName)
                    % If the tab is a field under app.tabs
                    simulatedEvent = struct('Source', app.tabs.(fieldName));
                    tabSelectCallback(app, simulatedEvent);
                else
                    warning('Previous tab "%s" does not exist.', fieldName);
                end
            else
                disp('No previous tab to switch to.');
            end
        end

        % Button pushed function: AddTab_photon
        function AddTab_ButtonPushed(app, event)
            modRef = event.Source.Tag;
            addTabCallback(app,modRef);
        end

        % Button pushed function: RemoveTab_photon
        function RemoveTab_ButtonPushed(app, event)
            modRef = event.Source.Tag;
            removeTabCallback(app,modRef);
        end

        % Button pushed function: Tab_photonT1
        function Tab_T1ButtonPushed(app, event)
            % app.Tab_photonT1.Tag = "Tab_photonT1";
            % selectTab(app,app.Tab_photonT1);
            selectTab(app, app.(event.Source.Tag));
        end

        function dropDownLabelCallback(app, event)
            dropDownField = event.Source.Tag;
            dropDownTitleParts = split(dropDownField,'_');
            fieldTitle = string(dropDownTitleParts(1));
            panelTabRef = string(dropDownTitleParts(2));
            dropDownFieldLabel = sprintf("%sLabel_%s",fieldTitle,panelTabRef);
            if contains(dropDownField,'T1')
                app.(dropDownFieldLabel).Text = app.(dropDownField).Value;
                if strcmp(app.(dropDownField).Value,"+")
                    Items = [app.(dropDownField).Items, inputdlg("enter new drop down entry")];
                    Items = Items(~strcmp(Items,"+"));
                    Items = Items(~strcmp(Items,"None"));
                    app.(dropDownField).Items = ["None",sort(Items),"+"];
                end
            else
                % app.tabs.(dropDownFieldLabel).Text = app.tabs.(dropDownField).Value;
                val = referenceTabField(app,dropDownField,"Value",[]);
                referenceTabField(app,dropDownFieldLabel,"Text",val);                
                if strcmp(val,"+")
                    Items = [app.tabs.(dropDownField).Items, inputdlg("enter new drop down entry")];
                    Items = Items(~strcmp(Items,"+"));
                    Items = Items(~strcmp(Items,"None"));
                    app.tabs.(dropDownField).Items = ["None",sort(Items),"+"];
                end
            end
            LabelButtonPushed(app);
        end

        function dropDownLabelCallbackV2(app, event)
            dropDownField = event.Source.Tag;
            dropDownTitleParts = split(dropDownField,'_');
            fieldTitle = string(dropDownTitleParts(1));
            panelTabRef = string(dropDownTitleParts(2));
            dropDownFieldLabel = sprintf("%sLabel_%s",fieldTitle,panelTabRef);
            if contains(dropDownField,'T1')
                % app.(dropDownFieldLabel).Text = app.(dropDownField).Value;
                % if strcmp(app.(dropDownField).Value,"+")
                if strcmp(event.Value,"+")
                    if contains(dropDownField,"_chan")
                        Items = referenceChanField(app,dropDownField,"Items",[]);
                    else
                        Items = referenceTabField(app,dropDownField,"Items",[]);
                    end
                    Items = [Items, inputdlg("enter new drop down entry")];
                    Items = Items(~strcmp(Items,"+"));
                    Items = Items(~strcmp(Items,"None"));
                    % app.(dropDownField).Items = ["None",sort(Items),"+"];
                    if contains(dropDownField,"_chan")
                        referenceChanField(app,dropDownField,"Items",["None",sort(Items),"+"]);
                    else
                        referenceTabField(app,dropDownField,"Items",["None",sort(Items),"+"]);
                    end
                end
            else
                % % app.tabs.(dropDownFieldLabel).Text = app.tabs.(dropDownField).Value;
                % val = referenceTabField(app,dropDownField,"Value",[]);
                if contains(dropDownField,"_chan")
                    val = referenceChanField(app,dropDownField,"Value",[]);
                else
                    val = referenceTabField(app,dropDownField,"Value",[]);
                end
                % referenceTabFields(app,dropDownFieldLabel)
                if strcmp(val,"+") || strcmp(event.Value,"+")
                    if contains(dropDownField,"_chan")
                        Items = referenceChanField(app,dropDownField,"Items",[]);
                    else
                        Items = referenceTabField(app,dropDownField,"Items",[]);
                    end
                    Items = [Items, inputdlg("enter new drop down entry")];
                    Items = Items(~strcmp(Items,"+"));
                    Items = Items(~strcmp(Items,"None"));
                    % app.(dropDownField).Items = ["None",sort(Items),"+"];
                    if contains(dropDownField,"_chan")
                        referenceChanField(app,dropDownField,"Items",["None",sort(Items),"+"]);
                    else
                        referenceTabField(app,dropDownField,"Items",["None",sort(Items),"+"]);
                    end
                end
            end
            LabelButtonPushed(app);
        end

        function registerDropDowns(app)
            appFields = fieldnames(app);
            for i = 1:length(appFields)
                appField = appFields{i};
                if contains(appField,'DropDown_') %&& ~contains(appField,'Label')
                    app.(appField).Tag = appField;
                    Items = app.(appField).Items;
                    Items = Items(~strcmp(Items,"+"));
                    Items = Items(~strcmp(Items,"None"));
                    app.(appField).Items = ["None",sort(Items),"+"];
                    if isempty(app.(appField).ValueChangedFcn); app.(appField).ValueChangedFcn = createCallbackFcn(app, @dropDownLabelCallback, true); end
                elseif contains(appField,'DropDownV2_') %&& ~contains(appField,'Label')
                    app.(appField).Tag = appField;
                    Items = app.(appField).Items;
                    Items = Items(~strcmp(Items,"+"));
                    Items = Items(~strcmp(Items,"None"));
                    app.(appField).Items = ["None",sort(Items),"+"];
                    if isempty(app.(appField).ValueChangedFcn); app.(appField).ValueChangedFcn = createCallbackFcn(app, @dropDownLabelCallbackV2, true); end
                end
            end
        end

        function parameterTableButtonCallback(app, event)
            paramTableButtonRef = event.Source.Tag; % identify the parameter table
            [tabID, tabNum] = derefFieldTag(app, paramTableButtonRef);
            panelRef = split(tabID,'T');
            panelRef = panelRef{1};
            tabRef = strrep(tabID,'T','');
            % value changed Fcn
            % value = app.(paramTableButtonRef).Value;
            value = referenceTabField(app, paramTableButtonRef,'Value',[]);            
            switch value
                case 1
                    % load_system(app.params.experiment)
                    params = loadParamTable(app, tabID, tabNum);
                    % params = app.camera.(tabRef).spinParams;
                    % app.(panelRef).(tabRef).paramTable.Data = {'Parameter','Value'};
                    paramKeys = fieldnames(params);
                    % paramVals =
                    % app.mdlWks = get_param(app.params.experiment,'ModelWorkspace');
                    % slxParams = app.mdlWks.whos;
                    % Populate table
                    paramData = {};
                    for i = 1:length(paramKeys)
                        % Get parameter name
                        % paramName = slxParams(i).name;
                        % Get parameter value
                        % paramValue = app.mdlWks.getVariable(paramName);
                        paramKey = paramKeys{i};
                        paramVal = params.(paramKey);

                        % Append the parameter and its value to the table
                        newRow = {paramKey, paramVal};
                        % newRowTable = cell2table(newRow, 'VariableNames', {'Parameter', 'Value'});

                        paramTableRef = sprintf("%sParamsParameterTable_%s",tabID);
                        % paramTableRef = sprintf("SpinParamsParameterTable_%s",tabID);
                        % app.(panelRef).(tabRef).paramTable.Data = [app.(panelRef).(tabRef).paramTable.Data; newRow];
                        paramData = [paramData; newRow];

                        % app.slxParamTable.Data = [app.slxParamTable.Data; newRowTable];
                    end
                    if ~isempty(paramData)
                        app.(panelRef).(tabRef).paramTable.Data = cell2table(paramData, "VariableNames",{'Parameter','Value'});
                    end
                    app.(panelRef).(tabRef).paramTable.Visible="on";
                    % app.slxParamTable.Visible = 'on';
                    % app.ParameterTableButton.BackgroundColor = app.disableGrey;
                    % app.slxParamTable.Position = [153,666,214,285];
                    % open_system(app.params.experiment);


                case 0
                    app.(panelRef).(tabRef).paramTable.Visible="off";
                    app.editingModalityLabel_expmnt .Visible = "off";
                    modality_list = ["camera","photom","sense","photon","npxls","rx","gx","opto"];
                    writeParamTable(app, tabID, tabNum, modality_list);
                    % app.slxParamTable.Visible = 'off';
                    % app.slxParamTable.Data = [];
                    % app.ParameterTableButton.BackgroundColor = [0.00,0.00,0.00];
            end
        end

        function params = loadParamTable(app, tabID, tabNum)
            params = struct;
            % get model workspace variables
            load_system(app.params.experiment)
            app.mdlWks = get_param(app.params.experiment,'ModelWorkspace');
            slxParams = app.mdlWks.whos;
            % Populate table
            for i = 1:length(slxParams)
                % Get parameter name
                paramName = slxParams(i).name;
                if contains(paramName,tabID)
                    % Get parameter value
                    paramValue = app.mdlWks.getVariable(paramName);
                    params.(paramName) = paramValue;
                    % tablePos = getTablePosition()
                end
            end

            panelRef = split(tabID,'T');
            panelRef = string(panelRef{1});
            tabRef = sprintf("%s%d",panelRef,tabNum);

            if contains(tabID,'camera')
                SNDropDownRef = sprintf("SerialNumberDropDownV2_%s",tabID);
                targetDropDownRef = sprintf("CameraTargetDropDownV2_%s",tabID);
                % cameraSN = app.(SNDropDownLabelRef).Text;
                cameraSN = referenceTabField(app,SNDropDownRef,'Value',[]);
                cameraTg = referenceTabField(app,targetDropDownRef,'Value',[]);
                % cameraPath = fullfile(app.params.paths.projDir_cloud,"Experiments",app.params.experiment,"Instruments","Camera",cameraSN);
                load(fullfile(app.params.paths.modulePath,'expmntCfg.mat'),'expmntCfg');
                if isfield(expmntCfg,"instruments")
                    cameraSNfull = strcat("S",cameraSN);
                    if isfield(expmntCfg.instruments.camera, cameraSNfull)
                        if isfield(expmntCfg.instruments.camera,cameraSNfull)
                            spinParams = expmntCfg.instruments.camera.(cameraSNfull).spinParams;
                        end
                    end
                end
                % spinParams = load(fullfile(cameraPath,"spinParams.mat"),'spinParams');
                camRef = strrep(tabID,'T','');
                % app.camera.(camRef).spinParams = spinParams.spinParams;
                app.camera.(camRef).spinParams = spinParams;

                % merge with workspace params
                % fieldNames = fieldnames(spinParams.spinParams);
                fieldNames = fieldnames(spinParams);
                for i = 1:numel(fieldNames)
                    % params.(fieldNames{i}) = spinParams.spinParams.(fieldNames{i});
                    params.(fieldNames{i}) = spinParams.(fieldNames{i});
                end

                %params = spinParams.spinParams;
                panelRef = 'camera';
                tabRef = camRef;

                fieldNames = fieldnames(params);
                fs_index = find((contains(fieldNames,tabID)) == 1);
                if ~isempty(fs_index)
                    cam_fs = string(fieldNames{fs_index});
                    params.(cam_fs) = app.FSNumericEntry_cameraT1.Value;
                end

                %app.camera.(camRef).paramTable = uitable("Parent",app.UIFigure,"ColumnEditable",true,"Position",[35,99,260,329],"Visible","off","CellEditCallback",@(event,source)paramTableCellEditCallback(app, event, panelRef, tabRef)); %"Data",probePosData);
                app.camera.(camRef).SN = cameraSN;
                app.camera.(camRef).target = cameraTg;
                app.camera.(camRef).tabID = tabID;
                app.camera.(camRef).savePath = fullfile(app.params.paths.Data.RAW.CAMERA.local, cameraTg, app.SessionLabelLabel.Text);
                % frames counter visual
                app.camera.(camRef).frameCounterTimer = timer("ExecutionMode","fixedSpacing","Period",0.2,"TimerFcn",@(~,~)updateWaitbar_camera(app,app.camera.(camRef)));
                % params = app.camera.(camRef).spinParams;
                % app.camera.(camRef).frameCounterTimer = timer("ExecutionMode","singleShot","TimerFcn",@(~,~)updateWaitbar_camera(app,app.camera.(camRef)));
                % app.bufferTimer = timer("ExecutionMode","fixedRate","Period",0.001,"BusyMode","queue","TimerFcn",@app.bufferMonofil);
                % app.params.slxTimerObj = timer('StartDelay', app.SessionTimerminEditField.Value*60, ...
                % 'TimerFcn', @(~,~)StopButtonPushed(app), ...
                % 'ExecutionMode', 'singleShot');
            end
            % app.slrt.newPhaseTable = uitable(app.UIFigure,"Position",[698,403,300,285],"ColumnEditable",true,"ColumnFormat",{[],{'group1'}},"KeyPressFcn",@(source,event)newPhaseParamTableKeyPressCallback(app, event),"CellEditCallback",@(source,event)newPhaseParamTableCellEditCallback(app, event),"CellSelectionCallback",@(source,event)newPhaseParamTableCellSelectionCallback(app, event),"Data",newPhaseDataTable);
            app.(panelRef).(tabRef).paramTable = uitable("Parent",app.UIFigure,"Tag",sprintf("paramTable_%s",tabID),"ColumnEditable",true,"CellEditCallback",@(source,event)newPhaseParamTableCellEditCallback(app, event),"CellSelectionCallback",@(source,event)newPhaseParamTableCellSelectionCallback(app, event),"KeyPressFcn",@(source,event)newPhaseParamTableKeyPressCallback(app, event),"Position",[34,99,262,329],"Visible","off");
            app.editingModalityLabel_expmnt = uilabel(app.UIFigure, 'Text', 'Editing modality parameters...', 'Position', [34, 429, 262, 35], 'FontName', 'Ubuntu', 'FontSize', 14, 'FontColor', [1 1 1], 'BackgroundColor', [0 0 0]);
        end

        function params = reloadParamTable(app, tabID, tabNum)
            params = struct;
            % get model workspace variables
            load_system(app.params.experiment)
            app.mdlWks = get_param(app.params.experiment,'ModelWorkspace');
            slxParams = app.mdlWks.whos;
            % Populate table
            for i = 1:length(slxParams)
                % Get parameter name
                paramName = slxParams(i).name;
                if contains(paramName,tabID)
                    % Get parameter value
                    paramValue = app.mdlWks.getVariable(paramName);
                    params.(paramName) = paramValue;
                    % tablePos = getTablePosition()
                end
            end

            panelRef = split(tabID,'T');
            panelRef = string(panelRef{1});
            tabRef = sprintf("%s%d",panelRef,tabNum);

            if contains(tabID,'camera')
                SNDropDownRef = sprintf("SerialNumberDropDownV2_%s",tabID);
                targetDropDownRef = sprintf("CameraTargetDropDownV2_%s",tabID);
                % cameraSN = app.(SNDropDownLabelRef).Text;
                cameraSN = referenceTabField(app,SNDropDownRef,'Value',[]);
                cameraTg = referenceTabField(app,targetDropDownRef,'Value',[]);
                cameraPath = fullfile(app.params.paths.projDir_cloud,"Experiments",app.params.experiment,"Instruments","Camera",cameraSN);
                spinParams = load(fullfile(cameraPath,"spinParams.mat"),'spinParams');
                camRef = strrep(tabID,'T','');
                app.camera.(camRef).spinParams = spinParams.spinParams;

                % merge with workspace params
                fieldNames = fieldnames(spinParams.spinParams);
                for i = 1:numel(fieldNames)
                    params.(fieldNames{i}) = spinParams.spinParams.(fieldNames{i});
                end

                %params = spinParams.spinParams;
                panelRef = 'camera';
                tabRef = camRef;

                fieldNames = fieldnames(params);
                fs_index = find((contains(fieldNames,tabID)) == 1);
                cam_fs = string(fieldNames{fs_index});
                params.(cam_fs) = app.FSNumericEntry_cameraT1.Value;

                %app.camera.(camRef).paramTable = uitable("Parent",app.UIFigure,"ColumnEditable",true,"Position",[35,99,260,329],"Visible","off","CellEditCallback",@(event,source)paramTableCellEditCallback(app, event, panelRef, tabRef)); %"Data",probePosData);
                app.camera.(camRef).SN = cameraSN;
                app.camera.(camRef).target = cameraTg;
                app.camera.(camRef).tabID = tabID;
                app.camera.(camRef).savePath = fullfile(app.params.paths.Data.RAW.CAMERA.local, cameraTg, app.SessionLabelLabel.Text);
                % frames counter visual
                app.camera.(camRef).frameCounterTimer = timer("ExecutionMode","fixedRate","Period",0.1,"BusyMode","drop","TimerFcn",@(~,~)updateWaitbar_camera(app,app.camera.(camRef)));
                % params = app.camera.(camRef).spinParams;
                % app.camera.(camRef).frameCounterTimer = timer("ExecutionMode","singleShot","TimerFcn",@(~,~)updateWaitbar_camera(app,app.camera.(camRef)));
                % app.bufferTimer = timer("ExecutionMode","fixedRate","Period",0.001,"BusyMode","queue","TimerFcn",@app.bufferMonofil);
                % app.params.slxTimerObj = timer('StartDelay', app.SessionTimerminEditField.Value*60, ...
                % 'TimerFcn', @(~,~)StopButtonPushed(app), ...
                % 'ExecutionMode', 'singleShot');
            end
            % app.slrt.newPhaseTable = uitable(app.UIFigure,"Position",[698,403,300,285],"ColumnEditable",true,"ColumnFormat",{[],{'group1'}},"KeyPressFcn",@(source,event)newPhaseParamTableKeyPressCallback(app, event),"CellEditCallback",@(source,event)newPhaseParamTableCellEditCallback(app, event),"CellSelectionCallback",@(source,event)newPhaseParamTableCellSelectionCallback(app, event),"Data",newPhaseDataTable);
            % app.(panelRef).(tabRef).paramTable = uitable("Parent",app.UIFigure,"Tag",sprintf("paramTable_%s",tabID),"ColumnEditable",true,"CellEditCallback",@(source,event)newPhaseParamTableCellEditCallback(app, event),"CellSelectionCallback",@(source,event)newPhaseParamTableCellSelectionCallback(app, event),"KeyPressFcn",@(source,event)newPhaseParamTableKeyPressCallback(app, event),"Position",[34,99,262,329],"Visible","off");
            app.editingModalityLabel_expmnt = uilabel(app.UIFigure, 'Text', 'Editing modality parameters...', 'Position', [34, 429, 262, 35], 'FontName', 'Ubuntu', 'FontSize', 14, 'FontColor', [1 1 1], 'BackgroundColor', [0 0 0]);
        end

        function writeParamTable(app, tabID, tabNum, modality_list)
            panelRef = split(tabID,'T');
            panelRef = panelRef{1};
            tabRef = strrep(tabID,'T','');
            data = app.(panelRef).(tabRef).paramTable.Data;

            if ~isempty(data)
                % split api vars from workspace vars
                isWorkspace = false(height(data), 1);
                for i = 1:length(modality_list)
                    modalityWithT = [modality_list{i}, 'T'];
                    isWorkspace = isWorkspace | contains(data.Parameter, modalityWithT);
                end
                data_workspace = data(isWorkspace, :);
                data_api = data(~isWorkspace, :);
    
    
                % save workspace variables separately
                for rowIndex = 1:height(data_workspace)
                    simulatedEvent.Indices = [rowIndex, 2];
                    if isnumeric(data_workspace.Value) || isstring(data_workspace.Value)
                        data_workspace.Value = num2cell(data_workspace.Value);
                    end
                    simulatedEvent.NewData = data_workspace.Value{rowIndex};
                    simulatedEvent.Source.Data = data_workspace;
                    paramTableCellEditCallback(app, simulatedEvent, panelRef, tabID);
                    %simulateParamTableCellEdit(app, panelRef, tabRef, rowIndex, data_workspace.Value{rowIndex});
                end
    
                % each api now will handle itself differently
                if contains(tabID,'camera')
                    spinParams = struct;
                    for i = 1:height(data_api)
                        paramKey = data_api.Parameter{i};
                        paramVal = data_api.Value{i};
                        spinParams.(paramKey) = paramVal;
                    end
                    SNDropDownRef = sprintf("SerialNumberDropDownV2_%s",tabID);
                    cameraSN = referenceTabField(app,SNDropDownRef,'Value',[]);
                    cameraPath = fullfile(app.params.paths.projDir_cloud,"Experiments",app.params.experiment,"Instruments","Camera",cameraSN);
                    save(fullfile(cameraPath,"spinParams.mat"),'spinParams');
                    app.(panelRef).(tabRef).paramTable.Data = [];
                end
            end
        end


        function paramTableCellEditCallback(app, event, panelRef, tabRef)
            indices = event.Indices;
            slxParamData = event.Source.Data;
            paramName = string(slxParamData(indices(1),:).Parameter);
            paramVal = event.NewData;
            if contains(paramName,tabRef)
                % if contains(paramName,"_fs") && contains(paramName,"camera"); paramVal = str2double(paramVal); end
                assignin(app.mdlWks,paramName,paramVal);
                save_system(app.params.experiment);

                % update any panel specific entries that have fields in the
                % panel in addition to the parameters drop down (this is
                % awkward right now as each needs to be added/updated here)
                if strcmp(panelRef, 'camera')
                    % app.(strcat("FSNumericEntry_", tabRef)).Value = paramVal;
                    fieldRef = sprintf("FSNumericEntry_%s",tabRef);
                    referenceTabField(app,fieldRef,"Value",paramVal);
                    % referenceTabField()
                end
            end
        end

        function registerParameterTables(app)
            appFields = fieldnames(app);
            for i = 1:length(appFields)
                appField = appFields{i};
                if contains(appField,'ParameterTableButton_')
                    app.(appField).Tag = appField;
                    app.(appField).ValueChangedFcn = createCallbackFcn(app, @parameterTableButtonCallback, true);
                end
            end
        end

        function selectTab(app, uiTab)
            tabNField = uiTab.Tag;
            tabRef = split(tabNField,'_');
            tabRef = tabRef(2);
            tabSubRef = char(tabRef);
            tabLblRef = string(tabSubRef(1:length(tabSubRef)-2));
            tabNum = str2double(string(tabSubRef(end)));
            app.(sprintf("TabNumLabel_%s",tabLblRef)).Text = num2str(tabNum);
            tabSubRef = string(tabSubRef(1:length(tabSubRef)-1));

            % disable/hide all other tab items
            appFields = fieldnames(app);
            tabFields = fieldnames(app.tabs);
            tabFields_app = appFields(contains(appFields, tabSubRef));
            tabFields_tabs = tabFields(contains(tabFields, tabSubRef));
            for i = 1:length(tabFields_app)
                tabField = tabFields_app{i};
                hideOtherTabs(app, tabField, string(tabRef), tabSubRef, 'app');
            end

            for i = 1:length(tabFields_tabs)
                tabField = tabFields_tabs{i};
                hideOtherTabs(app, tabField, string(tabRef), tabSubRef, 'tabs');
                % Stack ordering
                if contains(tabField,"ProbeAreasDisplayButton")
                    uistack(app.tabs.(tabField),"top");
                end
            end
            % cellfun(@(x) hideOtherTabs(app, x, string(tabRef), tabSubRef, 'app'), tabFields_app, 'UniformOutput', false);
            % cellfun(@(x) hideOtherTabs(app, x, string(tabRef), tabSubRef, 'tabs'), tabFields_tabs, 'UniformOutput', false);
        end

        function hideOtherTabs(app, uiObjRef, tabRef, tabSubRef, objLoc)
            if ~contains(uiObjRef,"_chan") || contains(uiObjRef,"Subtab") || contains(uiObjRef,"chanNumLabel")
                switch objLoc
                    case 'app'
                        if contains(uiObjRef, tabRef) 
                            app.(uiObjRef).Visible = 'on';
                            if strcmp(uiObjRef, sprintf("Tab_%s",tabRef))
                                app.(uiObjRef).BackgroundColor = app.enableWhite;
                            end
                        else
                            if ~contains(uiObjRef, sprintf("Tab_%s",tabSubRef))
                                app.(uiObjRef).Visible = 'off';
                            else
                                app.(uiObjRef).BackgroundColor = app.disableGrey;
                            end
                        end
                    case 'tabs'
                        if contains(uiObjRef, tabRef)
                            app.tabs.(uiObjRef).Visible = 'on';
                            if strcmp(uiObjRef, sprintf("Tab_%s",tabRef))
                                app.tabs.(uiObjRef).BackgroundColor = app.enableWhite;
                            end
                        else
                            if ~contains(uiObjRef, sprintf("Tab_%s",tabSubRef)) 
                                app.tabs.(uiObjRef).Visible = 'off';
                            else
                                app.tabs.(uiObjRef).BackgroundColor = app.disableGrey;
                            end
                        end
                end
            end          
        end

        function populateNeuralTargets(app)
            appFields = fieldnames(app);
            neuralTgtDropDownFields = appFields(contains(appFields,'NeuralTargetDropDown'));
            for i = 1:length(neuralTgtDropDownFields)
                neuralTgtField = neuralTgtDropDownFields{i};
                dropDownObj = app.(neuralTgtField);
                if isprop(dropDownObj,'Items')
                    app.(neuralTgtField).Items = app.allenRegs;
                end
            end
        end

        function atlasRegs = recoverAtlasRegions(app)
            % if isfile('structure_tree_safe_2017.csv')
            atlas = readtable('structure_tree_safe_2017.csv');
            atlasRegs = (categorical((["None";sort(atlas.acronym)])));
            % else
            % atlasRegs = {};
            % end
        end

        function value = referenceTabField(app,fieldRef,prop,setVal)

            tabNum = split(fieldRef,'_');
            tabID = string(tabNum(2));
            tabID = char(tabID);
            modID = split(tabID,"T");
            modID = modID{1};
            tabNum = str2double(tabID(end));
            value = [];
            % if contains(fieldRef,"_chan") && any(contains(fieldnames(app.(modID).channels),fieldRef))
            %     value = referenceChanField(app,fieldRef,prop,setVal);
            % else
            if tabNum > 1
                if strcmp(prop,"hndl")
                    value = app.tabs.(fieldRef);
                elseif ~isempty(setVal)
                    app.tabs.(fieldRef).(prop) = setVal;
                else
                    value = app.tabs.(fieldRef).(prop);
                end
            else
                if strcmp(prop,"hndl")
                    value = app.(fieldRef);
                elseif ~isempty(setVal)
                    app.(fieldRef).(prop) = setVal;
                else
                    value = app.(fieldRef).(prop);
                end
            end
            % end
        end

        function value = referenceChanField(app, fieldRef,prop, setVal)
            refParts = split(fieldRef,'_');
            tabID = refParts(2);
            modRef = split(tabID,'T');
            modRef = modRef{1};
            % chanID = refParts(3);
            if strcmp(prop,"hndl")
                value = app.(modRef).channels.(fieldRef);
            elseif ~isempty(setVal)
                app.(modRef).channels.(fieldRef).(prop) = setVal;
                value = 1;
            else
                value = app.(modRef).channels.(fieldRef).(prop);
            end
        end

        function calibrationPlayButtonPressed(app,event)
            calibPlayBtnRef = event.Source.Tag;

            refParts = split(calibPlayBtnRef,'_');
            senseTabID = string(refParts(2));
            tabNum = strrep(senseTabID,'senseT','');
            senseID = sprintf("sense%s",tabNum);

            value = app.sense.(senseID).calibration.uiControl.playButton.Value;

            switch value
                case 1 % was off, now 'on' or START
                    calibrationModel = app.sense.(senseID).calibration.calibrationModel;
                    loadSimulink(app, calibrationModel, 1);
                    Simulink.sdi.view
                    runSimulink(app, calibrationModel);
                    % initiate TCP: client connect to calibration server
                    app.sense.(senseID).calibration.uiControl.calibClient=tcpclient("192.168.7.5",8001);
                    configureCallback(app.sense.(senseID).calibration.uiControl.calibClient,"byte",1,@(source, event)reinitCalibrationUI(app, event, calibPlayBtnRef))
                    % visual cue
                    app.sense.(senseID).calibration.uiControl.playButton.Icon='Icons/calibIcon.PNG';
                    % app.sense.(senseID).calibration.uiControl.(calibPlayBtnRef).Value = 1;
                case 0 % was on, now 'off' or STOP
                    stop(slrealtime);
                    delete(app.sense.(senseID).calibration.uiControl.txButton);
                    delete(app.sense.(senseID).calibration.uiControl.setPointEntry);
                    delete(app.sense.(senseID).calibration.uiControl.playButton);
                    calibBtnRef = sprintf("CalibrationButton_%s",senseTabID);
                    referenceTabField(app, calibBtnRef,"Visible","on");
                    calibrationData = evalin('base','logsout');
                    [curve, fit] = extractCalibration(calibrationData);
                    app.sense.(senseID).calibration.LUT.fit = fit;
                    app.sense.(senseID).calibration.LUT.curve = curve;
                    clear(app.sense.(senseID).calibration.uiControl.calibClient);


                    % re-init
                    % app.sense.(senseID).calibration.uiControl.playButton.Icon='Icons/calibRunIcon.PNG';
                    % % app.sense.(senseID).calibration.uiControl.(calibPlayBtnRef).Value = 0;
                    % app.sense.(senseID).calibration.uiControl.txButton.Icon = app.cyberGreen;
                    % app.sense.(senseID).calibration.uiControl.txButton.Enable = "on";
            end
        end

        function calibrationTxButtonPressed(app,event)
            calibTxBtnRef = event.Source.Tag;

            refParts = split(calibTxBtnRef,'_');
            senseTabID = string(refParts(2));
            tabNum = strrep(senseTabID,'senseT','');
            senseID = sprintf("sense%s",tabNum);

            setPoint = app.sense.(senseID).calibration.uiControl.setPointEntry.Value;
            app.sense.(senseID).calibration.uiControl.setPointEntry.FontColor = app.disableGrey;
            % setPoint = referenceTabField(app,setPtEntryRef,"Value",[]);

            % start acquisition, send start flag and calibration set point
            write(app.sense.(senseID).calibration.uiControl.calibClient,uint8([1,setPoint]));
            app.sense.(senseID).calibration.uiControl.txButton.Icon = "Icons/tcpTx_inactive.PNG";

        end

        function reinitCalibrationUI(app, event, btnRef)
            [tabID, tabNum] = derefFieldTag(app,btnRef);
            % re-enable TX button/visual cues
            senseID = sprintf("sense%d",tabNum);
            app.sense.(senseID).calibration.uiControl.txButton.Icon= "Icons/tcpTx_active.PNG";
            app.sense.(senseID).calibration.uiControl.txButton.Enable = "on";
        end

        function [tabID, tabNum] = derefFieldTag(app, fieldTag)
            refParts = split(fieldTag,'_');
            tabID = string(refParts(2));
            tabID = char(tabID);
            tabNum = str2double(tabID(end));
        end

        function calibSetPointEntryChanged(app, event)
            editFieldRef = event.Source.Tag;
            [tabID, tabNum] = derefFieldTag(app,editFieldRef);
            senseID = sprintf("sense%d",tabNum);
            app.sense.(senseID).calibration.uiControl.setPointEntry.FontColor = app.cyberGreen;
        end

        function newPhaseButton_savePressed(app, event)
            phaseLbl = string(inputdlg("Enter phase name"));
            load(fullfile(app.params.paths.modulePath,'expmntCfg.mat'),'expmntCfg');
            newPhaseData = app.slrt.newPhaseTable.Data;
            dataFields = newPhaseData.Parameter;
            for i = 1:length(dataFields)
                dataField = string(dataFields{i});
                newPhaseVal = newPhaseData.Value(i);
                if strcmp(class(newPhaseVal),'cell')
                    expmntCfg.phases.(phaseLbl).WorkspaceVars.(dataField) = newPhaseVal{1};
                else
                    expmntCfg.phases.(phaseLbl).WorkspaceVars.(dataField) = newPhaseVal;
                end
            end

            save(fullfile(app.params.paths.modulePath,'expmntCfg.mat'),"expmntCfg");

            phaseNames = fieldnames(expmntCfg.phases);
            phaseNamesStr = string(phaseNames);
            app.PhaseDropDown_slrt.Items = ["None"; sort(phaseNamesStr); {"+"}];
            app.PhaseDropDown_slrt.Value = phaseLbl;
            delete(app.slrt.newPhaseButton_save);
            delete(app.slrt.newPhaseButton_discard);
            delete(app.slrt.newPhaseTable);
            % app.(appField).Items = ["None",sort(Items),"+"];
            PhaseDropDown_slrtValueChanged(app, event);
        end

        function newPhaseButton_discardPressed(app, event)
            delete(app.slrt.newPhaseButton_save);
            delete(app.slrt.newPhaseButton_discard);
            delete(app.slrt.newPhaseTable);
            app.PhaseDropDown_slrt.Value="None";
        end

        function paramTable = compileSlxParamTable(app)
            load_system(app.params.experiment)
            app.mdlWks = get_param(app.params.experiment,'ModelWorkspace');
            slxParams = app.mdlWks.whos;
            slxParamsList = arrayfun(@(x) x.name, slxParams, 'UniformOutput', false);
            % filter out modality specific params
            modality_list = ["camera","photom","sense","photon","npxls","rx","gx","opto"];
            isModality = false(height(slxParamsList), 1);
            for i = 1:length(modality_list)
                modalityWithT = [modality_list{i}, 'T'];
                isModality = isModality | contains(slxParamsList, modalityWithT);
            end
            slxParams = slxParams(~isModality, :);

            % Populate table
            paramTable = {};
            for i = 1:length(slxParams)
                % Get parameter name
                paramName = slxParams(i).name;
                % Get parameter value
                paramValue = app.mdlWks.getVariable(paramName);

                % Append the parameter and its value to the table
                newRow = {paramName, paramValue};
                % newRowTable = cell2table(newRow, 'VariableNames', {'Parameter', 'Value'});
                paramTable = [paramTable; newRow];
            end
            paramTable = cell2table(paramTable, 'VariableNames', {'Parameter', 'Value'});
        end

        function paramData = compileParamTable(app, event)
            paramTableButtonRef = event.Source.Tag; % identify the parameter table
            [tabID, tabNum] = derefFieldTag(app, paramTableButtonRef);
            panelRef = split(tabID,'T');
            panelRef = panelRef{1};
            tabRef = strrep(tabID,'T','');

            params = reloadParamTable(app, tabID, tabNum);          
            paramKeys = fieldnames(params);            
            % Populate table
            paramData = {};
            for i = 1:length(paramKeys)                
                paramKey = paramKeys{i};
                paramVal = params.(paramKey);
                % Append the parameter and its value to the table
                newRow = {paramKey, paramVal};               
                paramTableRef = sprintf("%sParamsParameterTable_%s",tabID);                
                paramData = [paramData; newRow];                
            end
            paramData = cell2table(paramData, "VariableNames",{'Parameter','Value'});            
        end

        function newPhaseParamTableKeyPressCallback(app, event)
            keyID = (event.Key);
            if strcmp(keyID,'escape') 
                event.Source.Data = compileParamTable(app, event);
                % restore original
            end
        end

        function newPhaseParamTableCellSelectionCallback(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                slxParamData = event.Source.Data;
                paramName = string(slxParamData(indices(1),:).Parameter);
                % paramVal = event.NewData;
                paramVal = slxParamData(indices(1),indices(2));
                paramValExp = table2array(paramVal);
                paramValExp = paramValExp{1,1};
                valSize2 = size(paramValExp,2);
                if valSize2 > 1 && isa(paramValExp,'double') %|| (isa(paramValExp,'nan'))
                    % format as text
                    formattedVal = sprintf("%d ",paramValExp);
                    formattedVal = sprintf("[%s]",formattedVal);
                    event.Source.Data(indices(1),indices(2)) = cell2table(cellstr(formattedVal));
                end
            end
                
            % assignin(app.mdlWks,paramName,paramVal);
            % save_system(app.params.experiment);
            % class(val);
        end

        function newPhaseParamTableCellEditCallback(app, event)
            % disp("he y");
            % indices = event.Indices;
            % slxParamData = event.Source.Data;
            % paramName = string(slxParamData(indices(1),:).Parameter);
            % paramVal = event.NewData;
            % assignin(app.mdlWks,paramName,paramVal);
            % save_system(app.params.experiment);
            % class(val);
            indices = event.Indices;
            slxParamData = event.Source.Data;
            paramName = string(slxParamData(indices(1),:).Parameter);
            % paramVal = event.NewData;
            paramVal = slxParamData(indices(1),indices(2));
            paramValExp = table2array(paramVal);
            paramValExp = paramValExp{1,1};
            if isstring(paramValExp)
                paramValExp = split(paramValExp,' ')';
                valSize2 = size(paramValExp,2);
                if valSize2 > 1
                    % format text as double array
                    paramValArray = str2double(paramValExp);      
                    event.Source.Data{indices(1),indices(2)} = {paramValArray};
                    % event.Source.DisplayData(indices(1),indices(2)) = 
                end
            end
        end

        function registerPanelSwitches(app)
            appFields = fieldnames(app);
            switchFields = appFields(contains(appFields,"PanelSwitch_"));
            for i = 1:length(switchFields)
                switchField = switchFields{i};
                app.(switchField).Tag = switchField;
                app.(switchField).AltText="Off";
                % modRef = split(appField,'_');
                % modRef = string(modRef(2));
                % if contains(switchField,"icon")
                app.(switchField).ImageClickedFcn = createCallbackFcn(app, @togglePanelSwitch, true);
                % else
                    % % app.(switchField).ValueChangedFcn = createCallbackFcn(app, @togglePanelSwitch, true);
                % end
            end
        end

        function togglePanelSwitch(app, event)
            switchRef = event.Source.Tag;
            mod = split(switchRef,"_");
            mod = mod{2};
            % switchIconRef = sprintf("%s", switchRef);
            switchValRef = sprintf("%sPanelSwitch_%s", mod, mod);
            switchIconRef = sprintf("%sPanelSwitch_%s", mod, mod);
            % panelRef = split(switchRef,"Switch");
            panelRef = split(switchValRef,"Switch");
            panelRef = string(panelRef{1});
            % ledRef = sprintf("%sLED",panelRef);
            % value = app.(switchRef).Value;
            value = app.(switchValRef).AltText;
            modRef = split(switchRef,'_');
            modRef = modRef{2};

            % SWITCH ICON ANIMATION
            % if contains(switchRef,"icon")
            animateSwitchIcon(app, switchIconRef, value);
            switch value
                case 'On'
                    app.(switchValRef).AltText = 'Off';
                    value='Off';
                case 'Off'
                    app.(switchValRef).AltText = 'On';
                    value='On';
            end
            % end

            switchRef = switchValRef;


            % Check if the Switch is turned 'On'
            if strcmp(value, 'On')
                % app.(ledRef).Color = 'green';
                % Show the cameraPanel if it is not already visible
                if strcmp(app.(panelRef).Visible, 'off')
                    app.(panelRef).Visible = 'on';
                    % Increase the width of the ModalityPanel to accommodate the cameraPanel
                    newWidth = app.ModalityPanel.Position(3) + app.(panelRef).Position(3);
                    % Ensure the new width does not exceed 780
                    app.ModalityPanel.Position(3) = min(newWidth, 780);
                end

                % Toggle visibility based on the switch
                app.(panelRef).Visible = strcmp(value, 'On');
            else
                % app.(ledRef).Color = 'red';
                % app.(ledRef).Color = app.cyberBlack;
                % Hide the cameraPanel if it is currently visible
                if strcmp(app.(panelRef).Visible, 'on')
                    app.(panelRef).Visible = 'off';
                    % Calculate the reduced width
                    %reducedWidth = app.ModalityPanel.Position(3) - app.(panelRef).Position(3);
                    % Update the ModalityPanel width, ensuring it doesn't exceed 780
                    %app.ModalityPanel.Position(3) = min(reducedWidth, 780);
                end
                clearModality(app,modRef);
            end
            adjustPanelPositions(app);
            LabelButtonPushed(app);
            if app.connectTargetButton.Value==1
                app.connectTargetButton.Value=0;
                connectTargetButtonValueChanged(app,event)
            end

            % MODALITY SPECIFIC FUNCTIONS

        end

        function clearModality(app, modRef)
            switch modRef
                case 'camera'
                    app.camera = struct;
                    allFields = compileAppFields(app);
                    camTabFields = allFields(contains(allFields,sprintf("%sT",modRef)));
                    modTabIDs = arrayfun(@(x) split(x,'_'), camTabFields, "UniformOutput",false);
                    modTabIDs = arrayfun(@(x) x{1,1}(2), modTabIDs, "UniformOutput",false);
                    modTabIDs = string(cell2mat(cellstr(modTabIDs)));
                    modTabIDs = unique(modTabIDs);
                    for i = 1:length(modTabIDs)
                        modTabID = modTabIDs(i);
                        snDropDownRef = sprintf("SerialNumberDropDownV2_%s",modTabID);
                        referenceTabField(app,snDropDownRef,"Value","None"); 
                        simEvent.Source.Tag = snDropDownRef;
                        SerialNumberDropDownV2_cameraValueChanged(app, simEvent)
                    end
                otherwise
            end
        end

        function appFields = compileAppFields(app)
            appFields = string(fieldnames(app));
            tabFields = string(fieldnames(app.tabs));
            channelFields = listChannelFields(app);
            appFields = [appFields; tabFields; channelFields];
        end

        function chanFields = listChannelFields(app) 
            modList = app.modalityList;
            chanFields = [];
            for i = 1:length(modList)
                mod = modList(i);
                if isfield(app.(mod),'channels')
                    chanFields = [chanFields; string(fieldnames(app.(mod).channels))];
                end
            end
        end

        function enPanels = listEnabledPanels(app)
            appFields = fieldnames(app);
            panelFields = appFields(contains(appFields,"PanelSwitch_"));
            enPanels = [];
            for i = 1:length(panelFields)
                switchField = panelFields{i};
                % isEN = app.(switchField).Value;
                isEN = app.(switchField).AltText;
                if strcmp(isEN,'On')
                    panelRef = split(switchField,'_');
                    panelRef = string(panelRef{end});
                    enPanels = [enPanels; panelRef];
                end
            end
        end

        function transmitSessionFields(app, cortexClient)
            projectName = split(app.ProjectPathTextEditField_proj.Value,filesep);
            writeline(cortexClient,sprintf("SelectProjectDirectoryDropDown:%s",projectName{end}));
            writeline(cortexClient,sprintf("ExpmntDropDown:%s",app.ExpmntDropDownV2_slrt.Value));
            gatedSL = app.SessionLabelLabel.Text;
            % ungatedSL = split(app.SessionLabelLabel.Text,'_');
            % ungatedSL = ungatedSL(1:end-1);
            % ungatedSL = string(join(ungatedSL,'_'));
            writeline(cortexClient,sprintf("SessionLabelLabel:%s",gatedSL));
        end

        % function FSNumericEntry_cameraValueChanged(app, event)
        %      value = app.FSNumericEntry_cameraT1.Value;
        %      paramName = string(slxParamData(indices(1),:).Parameter);
        %      paramVal = event.NewData;
        %      assignin(app.mdlWks,paramName,paramVal);
        %      save_system(app.params.experiment);
        %  end

        function updatePhase(app, paramName, paramVal, phaseName)
            expmntCfgPath = fullfile(app.ModulesFilePath.Value,app.ExpmntDropDownV2_slrt.Value,"expmntCfg.mat");
            load(expmntCfgPath,"expmntCfg");
            expmntCfg.phases.(phaseName).WorkspaceVars.(paramName) = paramVal;
            save(expmntCfgPath,"expmntCfg");
        end

    end


    methods(Access=private, Static)
        function slrtReader(dq, tcp)
            while true
                % dirEmpty = ~(length(dir(app.params.paths.tempDir))>2);
                data = read(tcp);
                disp(data);
                send(dq,data);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Save the original MATLAB path
            app.originalMatlabPath = path;            

            % Set the CloseRequestFcn
            app.UIFigure.CloseRequestFcn = @(src, event)shutdownFcn(app);

            % refreshDataBufferStatus(app);
            enableButton(app, 'JOLTStartTrialButton', 0);
            enableButton(app, 'JOLTSaveButton', 0);
            enableButton(app, 'JOLTDiscardButton', 0);

            % scan working directory nCORTEx application
            cdir = dir(pwd);
            cTbl = struct2table(cdir);
            if any(contains(cTbl.name,'nCORTEx_host.mlapp'))
                %addpath(genpath("Setup/"));
                %app.params = setHostParams(struct);
                addpath(genpath(pwd))
                app.nCORTEx_repo = pwd;
            else
                disp("n-CORTEx startup failed; please navigate to n-CORTEx repository and call 'cortex('host')'")
            end

            hostName = getHostName();
            sessionPaths = recoverSessionPaths(hostName);
            if ~isempty(sessionPaths)
                app.ModulesFilePath.Value = sessionPaths.modulePath;
                app.BrowseProjects.Enable = 'on';
                app.ProjectPathTextEditField_proj.Value = sessionPaths.projectPath;
                app.ExpmntDropDownV2_slrt.Enable = 'on';
                % Use the listFolders function to get the folders in that path
                experimentFolders = listFolders(app,sessionPaths.modulePath);
                unwantedFolders = [".git", ".qnx", "slxHelpers"]; % List of folders to exclude
                experimentFolders = experimentFolders(~ismember(experimentFolders, unwantedFolders));
                experimentFolders = [{'None'}, sort(experimentFolders), {'+'}];

                % Set the Items property of the ExpmntDropDown to the folders list
                app.ExpmntDropDownV2_slrt.Items = experimentFolders;

                % Set a default value if needed
                app.ExpmntDropDownV2_slrt.Value = "None";

                opts.paths.projDir_cloud = sessionPaths.projectPath;
                [~, projectName, ~] = fileparts(sessionPaths.projectPath);
                opts.projectName = projectName;
                app.params = setHostParams(opts);
                app.params.paths.nCORTEx_repo = app.nCORTEx_repo;
            end
            % enableField(app,'pharmTab1Button',0,1);
            % enableField(app,'pharmTab2Button',0,1);
            % enableField(app,'pharmAddTabButton',0,1);
            app.animLine = animatedline(app.MonofilAxes,"Color",app.cyberGreen);
            app.bufferTimer = timer("ExecutionMode","fixedRate","Period",0.001,"BusyMode","queue","TimerFcn",@app.bufferMonofil);
            app.trialEndTimer = timer("ExecutionMode","fixedRate","Period",0.005,"BusyMode","queue","TimerFcn",@app.listenForEnd);
            app.waitbarTimer_camera = timer("ExecutionMode","fixedRate","Period",0.005,"BusyMode","queue","TimerFcn",@app.updateWaitbar_camera);
            app.monofilBuffer = zeros(1,length(app.MonofilAxes.XTick));
            % populate projDir dropdown
            registerDropDowns(app);
            registerTabs(app);
            registerParameterTables(app);
            registerPanelSwitches(app);
            addChannels(app,"photomT1");
            addChannels(app,"photonT1");
            % addChannels("photonT1");
            %app.SelectProjectDirectoryDropDown_proj.Items = ["None", categorize(app.params.paths.NECdrive_cloud,'Project')];
            % recover Allen Atlas Regions
            app.allenRegs = recoverAtlasRegions(app);
            populateNeuralTargets(app);

            % HUD FOR NONEMPTY CACHE
            cacheAlert(app);
            % disp(length(app.monofilBuffer))
            % app.tcp=tcpclient("192.168.7.5",8001);
            % parpool(2);
            % app.dq=parallel.pool.DataQueue;
            % afterEach(app.dq,@(data) realtimeUpdate(app.MonofilAxes,data));
            % parfeval(p,@slrtReader,app.tcp);

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Make sure ncortex goes back to workding directory
            cd(fullfile(app.nCORTEx_repo))
            
            %% (re)initialize UI settings
            SessionTimerminEditFieldValueChanged(app);
            % writeline(app.cortexClient,sprintf("SessionLabelLabel:%s",app.SessionLabelLabel.Text));
            % writeline(app.cortexClient,sprintf("SelectProjectDirectoryDropDown:%s",app.SelectProjectDirectoryDropDown_proj.Value));
            % writeline(app.cortexClient,sprintf("ExpmntDropDown:%s",app.ExpmntDropDownV2_slrt.Value));
            app.isStopped=0;
            app.StopButton.Enable = "on";
            app.DataStreamButton_expmnt.Enable="off";
            app.DataDiscardButton_expmnt.Enable="off";
            app.StartButton.Enable = "off";
            app.connectTargetButton.Enable="off";
            % reset TX
            app.trialMask = [];
            app.trialCount = -1;
            app.SessionLabelLabel.Text=buildSessionLabel(app);
            % save session params
            app.sessionParams = app.params;            
            app.sessionParams.qcPassed = 0;
            % app.sessionParams.pupilFS=app.PupilFSEditField_cameraT1.Value;
            app.sessionParams.pupilFS=50;
            % app.sessionParams.whiskFS=app.WhiskFSEditField_cameraT1.Value;
            app.sessionParams.whiskFS=10;
            % app.sessionParams.npxlsFS=app.Npxls_FSEditField_npxlsT1.Value;
            LabelButtonPushed(app);
            app.sessionParams.sessionLabel=app.SessionLabelLabel.Text;
            app.sessionParams.expmnt=app.params.experiment;
            experiment = app.ExpmntDropDownV2_slrt.Value;
            switch experiment
                case 'nCORTEx'
                    % %% REARRANGE UI
                    % % hide unused peripherals
                    % attachPanel(app, "JoltPanel", "hide")

                    %% START TASK
                    % model = app.ExpmntDropDownV2_slrt.Text;
                    play_nCORTEx(app, experiment);
                case 'JOLT'
                    % %% REARRANGE UI
                    % attachPanel(app, "JoltPanel", "place")
                    % attachPanel(app, "JoltPanel", "unhide")

                    % initiate background tasks

                    %% START TASK
                    play_JOLT(app, experiment);
                    disp(strcat('Application',{' '},'Started'))
                    % start(app.bufferTimer);
                otherwise
                    play_nCORTEx(app, experiment);
            end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            app.isStopped=1;
            rmpath(genpath(fullfile(app.params.paths.modulePath)));
            experiment = app.ExpmntDropDownV2_slrt.Value;
            switch experiment               
                case 'JOLT'
                    app.JOLTsaveCount.Text='--';
                    app.saveCount=0;
                    app.JOLTdiscardCount.Text='--';
                    app.discardCount=0;                   
                    stopTimers(app);
                    % stop(app.trialEndTimer);
                    enableButton(app,"JOLTStartTrialButton",0);                    
                    % eventually stop npxls acq here
                    % attachPanel(app, "JOLTPanel", "place");
                    attachPanel(app, "JOLTPanel", "hide");
                    attachPanel(app,"SubjectStatusPanel","unhide");
                    % JOLT_ValidateButtonPushed(app);
            end   
    
            % STOP PROCESS
            
            if string(app.slTarget.ModelStatus.State)~="DONE"
                disp("stopping slx")
                stop(app.slTarget)
                disp(strcat('Application',{' '},'Stopped'))                
            end

            offloadRTLog(app);
            disp("offloading SLRT")
            
            try
                stop(app.params.slxTimerObj);
                delete(app.params.slxTimerObj);
            catch
                disp("WARNING: Session timer failure; did your simulation execute properly?");
            end
            
            % % compose realtimeLog
            % logsout = evalin('base','logsout');
            % realtimeLog = struct;
            % realtimeLog.data = logsout;
            % realtimeLog.metadata = app.sessionParams;
            
            % shut down existing cameras            
            cameras = fieldnames(app.camera);
            if ~isempty(cameras)
                disp("terminating CAMERA")
                % cameraPlay(app.camera, app.params.paths.projDir_local, app.sessionParams.sessionLabel, "stop");
                cameraPlay(app.params, app.camera, app.params.paths.Data.RAW.CAMERA.local, app.sessionParams.sessionLabel, "stop");
                % REINITIALIZE CAMERAS FOR POSSIBLE NEXT SESSION
                for i = 1:length(cameras)
                    SNRef = sprintf("SerialNumberDropDownV2_cameraT%d",i);
                    simEvent.Source.Tag = referenceTabField(app,SNRef,"Tag",[]);
                    SerialNumberDropDownV2_cameraValueChanged(app,simEvent);
                end
            end

            % MIGRATE TARGET DATA
            if isfield(app.params, 'connectedClients')
                connectedTargets = app.params.connectedClients;
                for i = 1:length(connectedTargets)
                    target = connectedTargets(i);                    
                    targetSrv = app.(target).cortexClient;                    
                    migrateMsg = sprintf("migrateTmp:%s",target);
                    writeline(targetSrv,migrateMsg);
                end                           
            end


            % REINITIALIZE
            app.StartButton.Enable="off";            
            app.StopButton.Enable="off";
            app.DataDiscardButton_expmnt.Enable = "on";
            app.DataStreamButton_expmnt.Enable = "on";            
            app.DataCacheButton_expmnt.Enable = "on";
            % app.connectTargetButton.Enable="on";
            % if app.connectTargetButton.Value==1
            %     app.connectTargetButton.Value=0;
            %     connectTargetButtonValueChanged(app)
            % end
            app.elapsedTime = 0;
            stop(app.params.waitBarTimer);
            
        end

        % Button pushed function: DataCacheButton_expmnt
        function DataCacheButton_expmntPushed(app, event)
            disp("STORING SESSION");
            % write to cache
            cacheLoc = fullfile(app.params.paths.nCORTEx_repo,"Setup","nCORTExCache.mat");
            load(cacheLoc,'cache');
            hostName = app.sessionParams.hostName;                        
            sessionLabel = string(app.sessionParams.sessionLabel);
            dataDir = app.sessionParams.paths.Data.RAW;
            sessionRow = cell2table({sessionLabel, dataDir},"VariableNames",{'SessionName','DataDir'});                        
            if ~isfield(cache.(hostName),'sessionCache')
                cache.(hostName).sessionCache = [];
            end
            cache.(hostName).sessionCache = [cache.(hostName).sessionCache; sessionRow];
            save(cacheLoc,"cache");
            logSession(app,[],[]);  

            app.DataDiscardButton_expmnt.Enable = "off";
            app.DataStreamButton_expmnt.Enable = "off";
            app.DataCacheButton_expmnt.Enable = "off";
            app.connectTargetButton.Enable="on";
            if app.connectTargetButton.Value==1
                app.connectTargetButton.Value=0;
                connectTargetButtonValueChanged(app)
            end
            app.SubjectDropDown_expmnt.Value="None";
            SubjectDropDown_expmntValueChanged(app);
            % clear subject entry
            % app.

            cacheAlert(app,1);
            cla(app.nCORTEx_waitbar);
            % app.DataCacheButton_expmnt.BackgroundColor = [0.988235294117647,0.415686274509804,0.129411764705882];
        end

        % Button pushed function: DataStreamButton_expmnt
        function DataStreamButton_expmntPushed(app, event)
            clearCameraWaitBars(app,app.camera);
            % IF NOT OFFLOADED, SAVE EMPTY SESSION
            sessionMatFile = sprintf("%s.mat",app.SessionLabelLabel.Text);
            if ~strcmp(app.SubjectDropDown_expmnt.Value,"None")
                checkSlrtPath_local = fullfile(app.params.paths.Data.RAW.SLRT.local,sessionMatFile);
                checkSlrtPath_cloud = fullfile(app.params.paths.Data.RAW.SLRT.cloud,sessionMatFile);
                % SAVE PRESET
                if ~isfile(checkSlrtPath_local) && ~isfile(checkSlrtPath_cloud)
                    userin = questdlg("This session has no data, would you like to save a preset?","Save preset",'Yes','No','');
                    switch userin
                        case "No"
                            % return
                        case "Yes"
                            app.sessionParams = app.params;
                            app.sessionParams.sessionLabel = sprintf("%s",app.SessionLabelLabel.Text);
                            app.sessionParams.expmnt = app.ExpmntDropDownV2_slrt.Value;
                            app.sessionParams.qcPassed = 0;
                            assignin("base","logsout",nan)
                            offloadRTLog(app);
                            uploadRAW(app.sessionParams.paths.Data.RAW, app.sessionParams.sessionLabel,0);            
                            logSession(app,[],[]);
                            return
                    end
                end             
            end
            % RECOVER EXISTING LOCAL CACHE            
            cacheLoc = fullfile(app.params.paths.nCORTEx_repo,"Setup","nCORTExCache.mat");
            hostName = getHostName();
            try
                load(cacheLoc,'cache');
                %% hostName = app.sessionParams.hostName;            
                cachedSessions2Upload = cache.(hostName).sessionCache;            
            catch
                fprintf("no cache exists for this host %s \n",hostName);
                cachedSessions2Upload = [];            
            end
            % SWITCH PRESENT CACHE UPLOAD OPTION
            if ~isempty(cachedSessions2Upload)
                numCached = height(cachedSessions2Upload);
                isUploadCache = questdlg({sprintf("Would you like to view the session cache? Enter 'y/n'",numCached)});
            else
                isUploadCache = "";
            end
            switch isUploadCache
                case "Yes"                    
                    % SELECTED CACHED SESSIONS TO UPLOAD
                    if ~isempty(cachedSessions2Upload)                
                        numCached = height(cachedSessions2Upload);
                        [sel, tf] = listdlg("Name","Session Selection","ListSize",[500,300],"PromptString",[{sprintf("You have %d session(s) left to upload, select to add them to this process",numCached)},{''},{''}], "ListString", [cachedSessions2Upload.SessionName;{''};{''};{''}]);
                        if tf == 1
                            unSels = [];
                            for i=1:length(sel)
                                subSel = sel(i);
                                unSel = find([1:numCached]~=subSel);
                                unSels = [unSels; unSel];
                            end
                            unsel = sum(unSels,2);
                            unsel = unsel > 1;
                            selectedCachedSessions2Upload = cachedSessions2Upload(sel,:);
                            unselectedCachedSessions2Upload = cachedSessions2Upload(unSel,:);
                            cache.(hostName).sessionCache = unselectedCachedSessions2Upload;                            
                            % sessions2Upload = [sessions2Upload; selectedCachedSessions2Upload];
                        elseif tf == 0
                            selectedCachedSessions2Upload = [];
                        end
                    else
                        selectedCachedSessions2Upload = [];
                    end
                    if ~isempty(selectedCachedSessions2Upload)
                        % POST SELECTION OPTIONS
                        action = questdlg({sprintf("What would you like to do with this selection?",numCached)},"Action","Upload","Delete","Redirect & Upload",'T');
                        isDelete = 0;
                        switch action
                            case "Delete"
                                try
                                    for j = 1:height(selectedCachedSessions2Upload)
                                        cachedSession = selectedCachedSessions2Upload(j,:);
                                        dataDir = cachedSession.DataDir;
                                        expmntPath = compileExpmntPath(dataDir);
                                        % remove selected sessions from the associated
                                        % extraction log(s)
                                        ExtractionLogPath = fullfile(expmntPath,"Extraction-Logs");
                                        extractionLogs = struct2table(dir(ExtractionLogPath));
                                        % extractionLog = extractionLogs(3);
                                        for i = 3:height(extractionLogs)
                                            extractionLog = extractionLogs(i,:);
                                            extLogPath = convertCharsToStrings(fullfile(extractionLog.folder,extractionLog.name));
                                            extractionLog = readtable(extLogPath,"Delimiter",",");
                                            deleteSel = [];
                                            for k = 1:height(cachedSession)
                                                sessName = cachedSession(k,:).SessionName;
                                                deleteSel = [deleteSel, strcmp(extractionLog.SessionName,sessName)];
                                            end
                                            deleteSel = sum(deleteSel,2) > 0;
                                            extractionLog(deleteSel,:) = [];
                                            writetable(extractionLog,extLogPath);                                
                                        end   
                                        isDelete = 1;
                                        % selectedCachedSessions2Upload = [];
                                    end
                                catch e
                                    disp(e);
                                end
                            case "Redirect & Upload"
                                newDest = uigetdir([],"Select Cloud Point");
                                newDestProjPart = split(newDest,filesep);
                                newDestProjPart = newDestProjPart{end};
                                if contains(newDestProjPart,"Project_")
                                    selectedCachedSessions2Upload = redirectDestinations(selectedCachedSessions2Upload,newDest);
                                else
                                    errordlg("Selected directory invalid; please select a Project Directory");
                                    return
                                end                            
                        end
                        save(cacheLoc,'cache');
                        cacheAlert(app,0);
                    end
                case "Cancel"
                    return;
                case "No"
                    selectedCachedSessions2Upload = [];       
                otherwise
                    selectedCachedSessions2Upload = [];       
            end
            if ~strcmp(app.SubjectDropDown_expmnt.Value,"None")
                % UPLOAD (AND LOG) CURRENT SESSION            
                if isfile(checkSlrtPath_local) && ~isfile(checkSlrtPath_cloud)
                    uploadRAW(app.sessionParams.paths.Data.RAW, app.sessionParams.sessionLabel,0);            
                    logSession(app,[],[]);        
                end
            end
            if ~isempty(selectedCachedSessions2Upload)
                % UPLOAD CACHED SESSIONS (THEY HAVE ALREADY BEEN LOGGED)
                for i = 1:height(selectedCachedSessions2Upload)
                    session2Upload = selectedCachedSessions2Upload(i,:);
                    % dataDir = session2Upload.DataDir{1};
                    dataDir = session2Upload.DataDir;
                    dDirType = class(dataDir);
                    switch dDirType
                        case "cell"
                            dataDir = dataDir{1};                                                   
                    end
                    sessionLabel = session2Upload.SessionName;
                    % expmntPath = fullfile(app.sessionParams.paths.projDir_cloud,"Experiments",app.sessionParams.expmnt);                
                    uploadRAW(dataDir,sessionLabel,isDelete);
                    expmntPath = compileExpmntPath(dataDir);
                    logSession(app,expmntPath,sessionLabel)
                end                            
            end

            app.SubjectDropDown_expmnt.Value="None";
            SubjectDropDown_expmntValueChanged(app);

            app.DataDiscardButton_expmnt.Enable = "off";
            if isfield(cache.(hostName),"sessionCache")
                if isempty(cache.(hostName).sessionCache) 
                    app.DataStreamButton_expmnt.Enable = "off";
                else
                    app.DataStreamButton_expmnt.Enable = "on";
                end
            else
                 app.DataStreamButton_expmnt.Enable = "off";
            end
            app.DataCacheButton_expmnt.Enable = "off";
            app.connectTargetButton.Enable="on";
            if app.connectTargetButton.Value==1
                app.connectTargetButton.Value=0;
                connectTargetButtonValueChanged(app)
            end
            
           cla(app.nCORTEx_waitbar);
            
        end

        % Button pushed function: DataDiscardButton_expmnt
        function DataDiscardButton_expmntPushed(app, event)
            % clear tempdir
            % rmdir(fullfile(app.params.paths.tempDir), "s");
            % evalin('base', 'clear logsout');
            userin = questdlg("Are you sure you would like to discard this session?",'Data Discard','Yes','No','');
            switch userin
                case "Yes"
                    disp("Discarding session");
                    uploadRAW(app.params.paths.Data.RAW,app.sessionParams.sessionLabel,1);
                    clearCameraWaitBars(app,app.camera);
                    % disable discard button
                    app.DataDiscardButton_expmnt.Enable="off";
                    sessionLabel = app.sessionParams.sessionLabel;
                    deleteMsg = sprintf("discardSession:%s",sessionLabel);
                    transmitCortexMsg(app,deleteMsg);                                    
                    % app.DataStreamButton_expmnt.Enable="off";
                    % resetLoggingActions(app);
                case "No"
                    return
            end

            app.DataDiscardButton_expmnt.Enable = "off";
            app.DataStreamButton_expmnt.Enable = "off";
            app.DataCacheButton_expmnt.Enable = "off";
            app.connectTargetButton.Enable="on";
            if app.connectTargetButton.Value==1
                app.connectTargetButton.Value=0;
                connectTargetButtonValueChanged(app)
            end
            app.SubjectDropDown_expmnt.Value="None";
            SubjectDropDown_expmntValueChanged(app);

            cacheAlert(app,1);
            cla(app.nCORTEx_waitbar);

        end

        % Callback function
        function VisionSwitch_cameraT1ValueChanged(app, event)
            value = app.VisionSwitch_cameraT1.Value;
            switch value
                case 'On'
                    app.cameraPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.cameraPanel.ForegroundColor=[0.36,0.36,0.36];
            end

        end

        % Callback function
        function NeuropixelsSwitch_npxlsValueChanged(app, event)
            value = app.npxlsPanelSwitch_npxls.Value;
            switch value
                case 'On'
                    app.npxlsPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.npxlsPanel.ForegroundColor=[0.36,0.36,0.36];
            end

            LabelButtonPushed(app);
        end

        % Callback function
        function PhotometrySwitch_photomValueChanged(app, event)
            value = app.photomPanelSwitch_photom.Value;
            switch value
                case 'On'
                    app.photomPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.photomPanel.ForegroundColor=[0.36,0.36,0.36];
            end

            LabelButtonPushed(app);
        end

        % Value changed function: connectTargetButton
        function connectTargetButtonValueChanged(app, event)
            value = app.connectTargetButton.Value;
            load(fullfile(app.params.paths.modulePath,"expmntCfg.mat"),'expmntCfg');
            switch value
                case 1 % connect
                    app.params.connectedClients = [];
                    tg = app.slTarget;
                    disp('connecting...')
                    targets = fieldnames(expmntCfg.targets);
                    enabledPanels = listEnabledPanels(app);
                    if ~isempty(enabledPanels)
                        targets = enabledPanels(contains(enabledPanels,targets));
                        for i = 1:length(targets)
                            target = targets{i};
                            ethernetIP = expmntCfg.targets.(target).ethernetIP;
                            fprintf('connecting %s\n',target);
                            try
                                app.(target).cortexClient = tcpclient(ethernetIP,8001,"ConnectTimeout",2);
                                % app.(target).cortexClient = tcpclient(ethernetIP,8003,"ConnectTimeout",2);
                            catch
                                errordlg(sprintf("Failed to connect to target machine %s. Please ensure that cortex target is launched",target),'Connection Error');
                                fprintf("enable cortex target: %s",target);
                                break
                            end
                            transmitSessionFields(app,app.(target).cortexClient);
                            app.params.connectedClients = [app.params.connectedClients; string(target)];
                        end
                    end

                    % app.cortexClient = tcpclient("128.59.87.69",8001,"ConnectTimeout",5);
                    tg.connect;
                    disp(strcat(tg.TargetSettings.name,{' '},'connected'))
                    app.connectTargetButton.Icon='slrtConnectIcon.png';
                    app.slTarget=tg;
                    app.StartButton.Enable="on";

                case 0 % disconnect
                    tg = app.slTarget;
                    disp('disconnecting...')
                    % targets = fieldnames(expmntCfg.targets);
                    % enabledPanels = listEnabledPanels(app);
                    % targets = enabledPanels(contains(enabledPanels,targets));
                    if isfield(app.params, 'connectedClients')
                        connectedTargets = app.params.connectedClients;
                        for i = 1:length(connectedTargets)
                            target = connectedTargets(i);
                            try
                                % c = app.(target).cortexClient;
                                delete(app.(target).cortexClient)
                            catch
                                disp("no cortexClient to disconnect")
                            end
                            % ethernetIP = expmntCfg.targets.(target).ethernetIP;
                            % fprintf('connecting %s\n',target);
                            % try
                            %     app.(target).cortexClient = tcpclient(ethernetIP,8001,"ConnectTimeout",2);
                            % catch
                            %     errordlg(sprintf("Failed to connect to target machine %s. Please ensure that cortex target is launched",target),'Connection Error');
                            %     fprintf("enable cortex target: %s",target);
                            % end
                        end
                        % clear app.cortexClient;
                        tg.disconnect;
                        disp(strcat(tg.TargetSettings.name,{' '},'disconnected'))
                        app.connectTargetButton.Icon='slrtDisconnectIcon.png';
                        app.slTarget=tg;
                        app.StartButton.Enable="off";
                    end
            end
            LabelButtonPushed(app);
            display(app.SessionLabelLabel.Text);
        end

        % Value changed function: SelectProjectDirectoryDropDown_proj
        function SelectProjectDirectoryDropDown_projValueChanged(app, event)
            value = app.SelectProjectDirectoryDropDown_proj.Value;
            app.SubjectDropDown_expmnt.Value="None";
            app.SubjectDropDownLabel.Text="Select Subject";
            app.ExpmntDropDownLabel.Text="Experiment";
            switch value
                case 'Neuromodulation for Pain'
                    % app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.NECdrive_root,'Project_Neuromodulation-for-Pain','Raw Behavior Data');
                    app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.stem,"NEC_Drive_local","Project_Neuromodulation-for-Pain");
                    app.params.paths.projDir_local=fullfile(app.params.paths.stem,"NEC_Drive_local","Project_Neuromodulation-for-Pain");
                    app.params.paths.projDir_cloud=fullfile(app.params.paths.NECdrive_cloud,"Project_Neuromodulation-for-Pain");
                case 'Neurotransmitter Exploration'
                    app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.NECdrive_cloud,'Project_Neurotransmitter-Exploration','Raw Behavior Data');
                    app.params.paths.projDir_cloud=fullfile(app.params.paths.NECdrive_cloud,"Project_Neurotransmitter-Exploration");
                case 'Thalamic Pupil Synchronization'
                    % app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.NECdrive_root,'Project_Thalamic-Pupil-Synchronization','Raw Behavior Data');
                    app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.stem,"NEC_Drive_local","Project_Thalamic-Pupil-Synchronization");
                    app.params.paths.projDir_local=fullfile(app.params.paths.stem,"NEC_Drive_local","Project_Thalamic-Pupil-Synchronization");
                    app.params.paths.projDir_cloud=fullfile(app.params.paths.NECdrive_cloud,"Project_Pupil-Thalamic-Synchronization");
                case 'SSD'
                    % app.SelectProjectDirectoryDropDownLabel.Text = fullfile(params.paths.NECdrive_root,'Project_Neurotransmitter-Exploration','Raw Behavior Data');;
                    app.SelectProjectDirectoryDropDownLabel.Text = 'SSD missing mountpoint';
                    disp('warning: SSD missing mountpoint')
                    app.params.paths.projDir_cloud='';
                case 'Browse'
                    path = uigetdir;
                    pathParts = split(path,filesep);
                    projName = pathParts(end);
                    app.SelectProjectDirectoryDropDownLabel.Text = path;
                    app.params.paths.projDir_local=fullfile(app.params.paths.stem,"NEC_Drive_local",projName);
                    app.params.paths.projDir_cloud=fullfile(app.params.paths.NECdrive_cloud,projName);
                otherwise
                    app.SelectProjectDirectoryDropDownLabel.Text=fullfile(app.params.paths.stem,"NEC_Drive_local",value);
                    app.params.paths.projDir_local=fullfile(app.params.paths.stem,"NEC_Drive_local",value);
                    app.params.paths.projDir_cloud=fullfile(app.params.paths.NECdrive_cloud,value);
            end
            % subjectLog = loadSubjectLog(fullfile(app.params.paths.projDir_cloud,"Experiments"));
            % if ~isempty(subjectLog)
            %     subjNames = subjectLog.SubjectName;
            % else
            %     subjNames = [];
            % end
            experiments = categorize(fullfile(app.params.paths.projDir_cloud,"Experiments"),"isDir");
            % populateDropDown(app, "SelectSubjectDropDown", string(subjNames));
            populateDropDown(app, "ExpmntDropDown", experiments)
            app.ExpmntDropDownV2_slrt.Value="None";
            % if ~isempty(app.cortexClient)
            %     writeline(app.cortexClient,sprintf("SelectProjectDirectoryDropDown:%s",app.SelectProjectDirectoryDropDown_proj.Value));
            % end
            ExpmntDropDownV2_slrtValueChanged(app);
            LabelButtonPushed(app);
        end

        % Callback function
        function ModalitySwitchValueChanged(app, event)
            value = app.ModalitySwitch.Value;
            switch value
                case 'Outputs'
                    app.cameraPanel.Visible="on";
                    app.npxlsPanel.Visible="on";
                    app.photomPanel.Visible="on";

                    app.gxPanel.Visible="off";
                    app.sensePanel.Visible="off";
                    app.rxPanel.Visible="off";
                    % enableField(app,'pharmTab1Button',0,1);
                    % enableField(app,'pharmTab2Button',0,1);
                    % enableField(app,'pharmAddTabButton',0,1);
                    %
                    % enableField(app,'npxlsTab1Button',1,0);
                    % enableField(app,'npxlsTab2Button',1,0);

                case 'Inputs'
                    app.cameraPanel.Visible="off";
                    app.npxlsPanel.Visible="off";
                    app.photomPanel.Visible="off";

                    app.gxPanel.Visible="on";
                    app.sensePanel.Visible="on";
                    app.rxPanel.Visible="on";
                    % enableField(app,'pharmTab1Button',1,0);
                    % enableField(app,'pharmTab2Button',1,0);
                    % enableField(app,'pharmAddTabButton',1,0);
                    %
                    % enableField(app,'npxlsTab1Button',0,1);
                    % enableField(app,'npxlsTab2Button',0,1);
            end
        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            % load slrealtime log data
            logData = evalin('base','logsout');

            % Retrieve signal traces
            PulseGen1Hz = find(logData,'PulseGen1Hz');
            whiskCam_trig = find(logData,'whiskCam_trig');
            pupilCam_trig = find(logData, 'pupilCam_trig');
            npxls_trig = find(logData, 'npxls_trig');

            % compare timing to 1Hz reference
            cycleTimes = locateCycles(app.sessionParams,PulseGen1Hz.Values.Data);
            % whisker trig stats
            numPupilEdges = countEdges(app.sessionParams, pupilCam_trig.Values.Data, []);
            numPupEdgesByCycle = countEdges(app.sessionParams,pupilCam_trig.Values.Data,cycleTimes);
            meanPupilPerSec = mean(numPupEdgesByCycle);
            stdPupilPerSec = std(numPupEdgesByCycle);
            % pupil trig stats
            numWhiskEdges = countEdges(app.sessionParams, whiskCam_trig.Values.Data, []);
            numWhiskEdgesByCycle = countEdges(app.sessionParams,whiskCam_trig.Values.Data,cycleTimes);
            meanWhiskPerSec = mean(numWhiskEdgesByCycle);
            stdWhiskPerSec = std(numWhiskEdgesByCycle);
            % npxls ref trig stats
            numNpxlsEdges = countEdges(app.sessionParams, npxls_trig.Values.Data, []);
            numNpxlsEdgesByCycle = countEdges(app.sessionParams,npxls_trig.Values.Data,cycleTimes);
            meanNpxlsPerSec = mean(numNpxlsEdgesByCycle);
            stdNpxlsPerSec = std(numNpxlsEdgesByCycle);

            % validate pupil and whisker frame counts
            numPupilFrames = countFrames(fullfile(app.sessionParams.paths.tempDir,"Raw Pupil Data", app.SessionLabelLabel.Text));
            numWhiskFrames = countFrames(fullfile(app.sessionParams.paths.tempDir,"Raw Whisker Data", app.SessionLabelLabel.Text));

            % assert validation logic
            pupilFramesOk = (numPupilFrames == numPupilEdges);
            whiskFramesOk = (numWhiskFrames == numWhiskEdges);
            % Pupil valid
            pupilMeanOk = (abs(app.sessionParams.pupilFS - meanPupilPerSec) < app.sessionParams.validationParams.meanThreshold);
            pupilStdOk = (stdPupilPerSec < app.sessionParams.validationParams.stdThreshold);
            pupilStatOk = (pupilMeanOk & pupilStdOk);
            % whisk valid
            whiskMeanOk = (abs(app.sessionParams.whiskFS - meanWhiskPerSec) < app.sessionParams.validationParams.meanThreshold);
            whiskStdOk = (stdWhiskPerSec < app.sessionParams.validationParams.stdThreshold);
            whiskStatOk = (whiskMeanOk & whiskStdOk);
            % Npxls valid
            npxlsMeanOk = (abs(app.sessionParams.npxlsFS - meanNpxlsPerSec) < app.sessionParams.validationParams.meanThreshold);
            npxlsStdOk = (stdNpxlsPerSec < app.sessionParams.validationParams.stdThreshold);
            npxlsStatOk = (npxlsMeanOk & npxlsStdOk);

            sessionOk = (pupilFramesOk & whiskFramesOk & pupilStatOk & whiskStatOk & npxlsStatOk);
            app.sessionParams.qcPassed = sessionOk;

            % disp stats on app

            % fun visual
            for i=1:50
                rand1 = randi([1,300]);
                rand2 = randi([1,500]);
                rand3 = randi([1,200]);

                app.PupilMeanHz.Text=string(rand1);
                app.PupilStdHz.Text=string(rand3);

                app.WhiskMeanHz.Text=string(rand2);
                app.WhiskStdHz.Text=string(rand1);

                app.NpxlsMeanHz.Text=string(rand3);
                app.NpxlsStdHz.Text=string(rand2);
                pause(0.01)
            end

            % report final values
            app.PupilMeanHz.Text=string(meanPupilPerSec);
            app.PupilStdHz.Text=string(stdPupilPerSec);

            app.WhiskMeanHz.Text=string(meanWhiskPerSec);
            app.WhiskStdHz.Text=string(stdWhiskPerSec);

            app.NpxlsMeanHz.Text=string(meanNpxlsPerSec);
            app.NpxlsStdHz.Text=string(stdNpxlsPerSec);

            % color indicator for validity
            colorCodeByValid(app,'PupilMeanHz',pupilMeanOk);
            colorCodeByValid(app,'PupilStdHz',pupilStdOk);
            colorCodeByValid(app,'PupilStatLabel',pupilStatOk);

            colorCodeByValid(app,'WhiskMeanHz',whiskMeanOk);
            colorCodeByValid(app,'WhiskStdHz',whiskStdOk);
            colorCodeByValid(app,'WhiskStatLabel',whiskStatOk);

            colorCodeByValid(app,'NpxlsMeanHz',npxlsMeanOk);
            colorCodeByValid(app,'NpxlsStdHz',npxlsStdOk);
            colorCodeByValid(app,'NpxlsStatLabel',npxlsStatOk);


        end

        % Button pushed function: LabelButton
        function LabelButtonPushed(app, event)
            sessionLabel = buildSessionLabel(app);
            % subjectLabel = buildSubjectLabel(app);
            app.SessionLabelLabel.Text=sessionLabel;
            % app.SubjectLabelLabel.Text=subjectLabel;
            % logSession(app);
            % if ~isempty(app.cortexClient)
            %     ungatedSL = split(sessionLabel,'_');
            %     ungatedSL = ungatedSL(1:end-1);
            %     ungatedSL = join(ungatedSL,'_');
            %     % if strcmp(gX,'g0')
            %     % writeline(app.cortexClient,sprintf("SessionLabelLabel:%s",ungatedSL));
            %     % else
            %     % writeline(app.cortexClient,sprintf("SessionLabelLabel:%s",sessionLabel));
            %
            % end
        end

        % Callback function
        function RxSwitch_rxValueChanged(app, event)
            value = app.rxPanelSwitch_rx.Value;
            % switch value
            %     case 'On'
            %         app.rxPanel.ForegroundColor=[1,1,1];
            %     case 'Off'
            %         app.rxPanel.ForegroundColor=[0.36,0.36,0.36];
            % end
            LabelButtonPushed(app);
        end

        % Callback function
        function gxSwitch_gxValueChanged(app, event)
            value = app.gxPanelSwitch_gx.Value;
            switch value
                case 'On'
                    app.gxPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.gxPanel.ForegroundColor=[0.36,0.36,0.36];
            end
            LabelButtonPushed(app);
        end

        % Callback function
        function SensorySwitch_senseValueChanged(app, event)
            value = app.SensorySwitch_sense.Value;
            switch value
                case 'On'
                    app.sensePanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.sensePanel.ForegroundColor=[0.36,0.36,0.36];
            end
            LabelButtonPushed(app);
        end

        % Value changed function: ExpmntDropDownV2_slrt
        function ExpmntDropDownV2_slrtValueChanged(app, event)
            experiment = app.ExpmntDropDownV2_slrt.Value;
          
            switch experiment
                case 'nCORTEx'
                    %% REARRANGE UI
                    % hide unused peripherals
                    attachPanel(app, "JOLTPanel", "hide");
                    % attachPanel(app, "SessionMetricsPanel","unhide");
                case 'JOLT'
                    %% REARRANGE UI
                    % attachPanel(app, "JOLTPanel", "place");
                    % attachPanel(app, "JOLTPanel", "unhide");
                    % attachPanel(app, "SessionMetricsPanel","hide");
                case '+'
                    newExpName = convertCharsToStrings(inputdlg("Enter the name of your new experiment"));
                    newExpPath = fullfile(app.params.paths.projDir_cloud,"Experiments",newExpName);
                    newExpPath_local = fullfile(app.params.paths.projDir_local,"Experiments",newExpName);
                    disp("BUILDING CLOUD DIRECTORY");
                    buildExperimentDirectory(newExpPath, app.modalityList_RAW, app.extractionLayers);
                    disp("BUILDING LOCAL DIRECTORY");
                    buildExperimentDirectory(newExpPath_local, app.modalityList_RAW, app.extractionLayers);
                    expModulesPath = app.ModulesFilePath.Value;
                    newExpModulePath = fullfile(expModulesPath,newExpName);
                    if ~isfolder(newExpModulePath)
                        % buildExperimentModule(expModulesPath);
                        mkdir(newExpModulePath);
                    end
                    experiment = newExpName;
                    app.ExpmntDropDownV2_slrt.Items = injectNewItem(app.ExpmntDropDownV2_slrt.Items,experiment);
                    app.ExpmntDropDownV2_slrt.Value = experiment;
                otherwise
                    attachPanel(app, "JOLTPanel", "hide");
                    % attachPanel(app, "SessionMetricsPanel","unhide");
            end
            
            app.params.experiment = experiment;
            app.params = locateData(app.params);
            app.PhaseDropDown_slrt.Value="None";
            PhaseDropDown_slrtValueChanged(app);
            % app.params.experiment=experiment;                      
            % set simulink codegen folders
            setSimulinkCodeGenPath(char(fullfile(app.ModulesFilePath.Value,experiment)));
            app.slrt = struct;

            % populate instrument dropdown items
            % app.SerialNumberDropDownV2_cameraT1.Items = ["None", categorize(fullfile(app.params.paths.projDir_cloud,"Experiments",experiment,"Instruments","Camera"),"isDir"),"+"];
            % not 'None' case
            if ~strcmp(experiment,'None')
                app.params = locateData(app.params);
                module = app.ExpmntDropDownV2_slrt.Value;
                app.params.paths.modulePath = fullfile(app.ModulesFilePath.Value,app.ExpmntDropDownV2_slrt.Value);
                app.params.paths.moduleSlxPath =  fullfile(app.params.paths.modulePath, (module + ".slx"));
                try
                        load(fullfile(app.params.paths.modulePath,"expmntCfg.mat"),'expmntCfg');
                         % populate instrument dropdown items
                        if isfield(expmntCfg,"instruments")
                            if isfield(expmntCfg.instruments,"camera")
                                cameraList = convertCharsToStrings(fieldnames(expmntCfg.instruments.camera));
                                cameraList = strrep(cameraList,"S","");                                
                                app.SerialNumberDropDownV2_cameraT1.Items = ["None", cameraList,"+"];
                            end
                        end
                        % cameraList = 
                        % app.SerialNumberDropDownV2_cameraT1.Items = ["None", categorize(fullfile(app.params.paths.projDir_cloud,"Experiments",experiment,"Instruments","Camera"),"isDir"),"+"];
            
                catch
                    expmntCfg = struct;
                    expmntCfg.phases = struct;
                    expmntCfg.targets = struct;
                    expModCfgPath = fullfile(app.params.paths.modulePath,"expmntCfg.mat");
                    if ~isfile(expModCfgPath)
                        save(expModCfgPath,'expmntCfg');
                    end
                end

                phaseNames = fieldnames(expmntCfg.phases);
                phaseNamesStr = string(phaseNames);
                app.PhaseDropDown_slrt.Items = ["None"; sort(phaseNamesStr); {"+"}];
                %app.slrt = locateExperimentModule(app.slrt);
                app.slrt.modulesSlxPath = app.params.paths.moduleSlxPath;

                app.SubjectDropDown_expmnt.Items = ["None", categorize(fullfile(app.params.paths.projDir_cloud,"Experiments",experiment,"Subjects"),"isDir"), "+"];            
            end


            if ~isempty(app.ExpmntDropDownV2_slrt.Value)
                % Enable the Phase dropdowns
                app.PhaseDropDown_slrt.Enable = 'on';
                app.SessionTimerminEditFieldLabel.Enable = 'on';
                app.SessionTimerminEditField.Enable = 'on';
                app.ParameterTableButton.Enable = 'on';
            end
        end

        % Button pushed function: JOLTStartTrialButton
        function JOLTStartTrialButtonPushed(app, event)
            % altMode = app.JOLTSpontButton.Text;
            % app.trialMask = [app.trialMask app.trialCount];
            % switch app.PhaseDropDown_slrt.Value
            %     case  % we are in stim mode
            %         % enableButton(app, "JOLTSaveButton", 1);
            %         % enableButton(app, "JOLTDiscardButton", 1);
            %         % write to target
            %         write(app.tcp,uint8(1));
            %         write(app.tcp,uint8(0));
            %         start(app.bufferTimer);
            %     case "Stim." % we are in spont mode
            %         % spontaneous trial mask reads '0'
            %         % write to target
            %         write(app.tcp,uint8(4));
            %         % write(app.tcp,uint8(0));
            % end

            if contains(app.PhaseDropDown_slrt.Value,'spontaneous') || strcmp(app.PhaseDropDown_slrt.Value,'implant')
                write(app.tcp,uint8(4));
            else
                write(app.tcp,uint8(1));
                write(app.tcp,uint8(0));
                start(app.bufferTimer);
            end
            % disable starting
            enableButton(app, "JOLTStartTrialButton", 0);
            app.trialCount = app.trialCount+1;
        end

        % Button pushed function: JOLTSaveButton
        function JOLTSaveButtonPushed(app, event)
            app.saveCount = app.saveCount + 1;
            app.trialMask = [app.trialMask, app.trialCount];
            % app.trialCount = app.trialCount + 1;
            app.JOLTsaveCount.Text = num2str(app.saveCount);
            cla(app.MonofilAxes);
            app.animLine = animatedline(app.MonofilAxes,"Color",app.cyberGreen);
            % write to target
            write(app.tcp,uint8(2));
            write(app.tcp,uint8(0));
            % update save counter
            app.joltTrialSaveCount = app.joltTrialSaveCount+1;
            % display on progress bar

            % disable saving/discarding
            enableButton(app, "JOLTSaveButton", 0);
            enableButton(app, "JOLTDiscardButton", 0);
            % enable starting
            enableButton(app, "JOLTStartTrialButton", 1);
        end

        % Button pushed function: JOLTDiscardButton
        function JOLTDiscardButtonPushed(app, event)
            app.discardCount = app.discardCount + 1;
            % app.trialCount = app.trialCount + 1;
            app.JOLTdiscardCount.Text = num2str(app.discardCount);
            cla(app.MonofilAxes);
            app.animLine = animatedline(app.MonofilAxes,"Color",app.cyberGreen);
            % write to target
            write(app.tcp,uint8(3));
            write(app.tcp,uint8(0));
            % disable saving/discarding
            enableButton(app, "JOLTSaveButton", 0);
            enableButton(app, "JOLTDiscardButton", 0);
            % enable starting
            enableButton(app, "JOLTStartTrialButton", 1);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % rmdir(app.params.paths.tempDir,"s");

            % Restore the original MATLAB path
            path(app.originalMatlabPath);

            delete(app)
            % terminal parallel processes
            delete(gcp('nocreate'));
        end

        % Button pushed function: SpinviewButton_camera
        function SpinviewButton_cameraPushed(app, event)
            system('spinview');
        end

        % Button pushed function: JOLT_ValidateButton
        function JOLT_ValidateButtonPushed(app, event)


            % Load relevant data
            logData = evalin('base','logsout');
            npxls_trig = find(logData, 'npxls_trig');
            PulseGen1Hz = find(logData,'PulseGen1Hz');

            app.sessionParams.npxlsFS = 250;
            % compare timing to 1Hz reference
            cycleTimes = locateCycles(app.sessionParams,PulseGen1Hz.Values.Data);

            % npxls ref trig stats
            numNpxlsEdges = countEdges(app.sessionParams, npxls_trig.Values.Data, []);
            numNpxlsEdgesByCycle = countEdges(app.sessionParams,npxls_trig.Values.Data,cycleTimes);
            meanNpxlsPerSec = mean(numNpxlsEdgesByCycle);
            stdNpxlsPerSec = std(numNpxlsEdgesByCycle);

            % Npxls valid
            npxlsMeanOk = (abs(app.sessionParams.npxlsFS - meanNpxlsPerSec) < app.sessionParams.validationParams.meanThreshold);
            npxlsStdOk = (stdNpxlsPerSec < app.sessionParams.validationParams.stdThreshold);
            npxlsStatOk = (npxlsMeanOk & npxlsStdOk);

            app.sessionParams.qcPassed = npxlsStatOk;

            % fun visual
            for i=1:50
                rand1 = randi([1,300]);
                rand2 = randi([1,500]);
                rand3 = randi([1,200]);

                app.NpxlsMeanHz_JOLT.Text=string(rand3);
                app.NpxlsStdHz_JOLT.Text=string(rand2);
                pause(0.01)
            end

            % Display Results
            app.NpxlsMeanHz_JOLT.Text=string(meanNpxlsPerSec);
            app.NpxlsStdHz_JOLT.Text=string(stdNpxlsPerSec);
            colorCodeByValid(app,'NpxlsMeanHz_JOLT',npxlsMeanOk);
            colorCodeByValid(app,'NpxlsStdHz_JOLT',npxlsStdOk);
            colorCodeByValid(app,'NpxlsStatLabel_JOLT',npxlsStatOk);

        end

        % Value changed function: JOLTSwipeRightButton
        function JOLTSwipeRightButtonValueChanged(app, event)
            value = app.JOLTSwipeRightButton.Value;
            app.JOLTSwipeNum = app.JOLTSwipeNum+1;
            panelSel = mod(abs(app.JOLTSwipeNum),2);
            switch panelSel
                case 0 % buttons Panel
                    % attachPanel(app,'JOLTButtonsPanel','place');
                    attachPanel(app,'JOLTButtonsPanel','unhide');
                    attachPanel(app,'JOLTValidationPanel','hide');
                case 1 % JOLT valid. Panel
                    % attachPanel(app,'JOLTValidationPanel','place');
                    attachPanel(app,'JOLTValidationPanel','unhide');

                    attachPanel(app,'JOLTButtonsPanel','hide');
            end
        end

        % Value changed function: JOLTSwipeLeftButton
        function JOLTSwipeLeftButtonValueChanged(app, event)
            value = app.JOLTSwipeLeftButton.Value;
            app.JOLTSwipeNum = app.JOLTSwipeNum-1;
            panelSel = mod(abs(app.JOLTSwipeNum),2);
            switch panelSel
                case 0 % buttons Panel
                    % attachPanel(app,'JOLTButtonsPanel','place');
                    attachPanel(app,'JOLTButtonsPanel','unhide');

                    attachPanel(app,'JOLTValidationPanel','hide');
                case 1 % JOLT valid. Panel
                    % attachPanel(app,'JOLTValidationPanel','place');
                    attachPanel(app,'JOLTValidationPanel','unhide');

                    attachPanel(app,'JOLTButtonsPanel','hide');
            end
        end

        % Value changed function: RxDrugDropDownV2_rxT1
        function RxDrugDropDownV2_rxT1ValueChanged(app, event)
            app.RxDrugDropDownLabel_rxT1.Text= app.RxDrugDropDownV2_rxT1.Value;
            LabelButtonPushed(app);
        end

        % Value changed function: RxDeliveryMethodDropDownV2_rxT1
        function RxDeliveryMethodDropDownV2_rxT1ValueChanged(app, event)
            app.RxDeliveryMethodDropDownLabel_rxT1.Text = app.RxDeliveryMethodDropDownV2_rxT1.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function ProbeSideDropDownV2_npxlsT1ValueChanged(app, event)
            app.ProbeSideDropDownLabel_npxlsT1.Text = app.ProbeSideDropDownV2_npxlsT1.Value;
            LabelButtonPushed(app);
        end

        % Value changed function: SubjectDropDown_expmnt
        function SubjectDropDown_expmntValueChanged(app, event)
            value = app.SubjectDropDown_expmnt.Value;
            
            if ~strcmp(app.SubjectDropDown_expmnt.Value,"None")
                LabelButtonPushed(app);
            end
            % reset config
            % rmfield(app.tabs,);
            clearTabs(app,app.tabs);
            app.tabs = struct;
            tmpProjDir_cloud = app.ProjectPathTextEditField_proj.Value;
            if ~strcmp(value,'None'); recoverSession(app); end
            app.params.paths.projDir_cloud = tmpProjDir_cloud;
            % projDir_local = app.SelectProjectDirectoryDropDownLabel.Text;
            subjLogPath = fullfile(app.params.paths.projDir_cloud,"Experiments");
            % presets = loadSubjectLog(app.params.paths.projDir_cloud);
            presets = scanSubjectPresets(app);
            if ~isempty(presets)
                presetNames = {presets.name};
                presetNames = strrep(presetNames,".mat","");
                match = any(contains(string(presetNames), value));
                if match
                    presetName=value;
                    value = "match";
                end
            end

            
            switch value
                case "+"
                    app.SubjectDropDown_expmnt.Value = "None"; %reset to defaults first
                    app.subjIDNum = inputdlg("Enter new subject ID# and populate fields");
                    projCloudPath = fullfile(app.params.paths.projDir_cloud,"Experiments",app.params.experiment,"Subjects",string(app.subjIDNum));
                    mkdir(projCloudPath);
                    app.SubjectDropDown_expmnt.Items = ["None", categorize(fullfile(app.params.paths.projDir_cloud,"Experiments",app.params.experiment,"Subjects"),"isDir"), "+"];
                    app.SubjectDropDown_expmnt.Value = app.subjIDNum;
                    %toggleItem(app, "SubjectDropDownLabel", "SaveSubjectLabelButton");
                    % toggleItem(app, "SessionLabelLabel", "SubjectLabelLabel");
                    % app.SubjectDropDownLabel.Text=value;
                case "match"
                    loadSubjectPreset(app, presetName);
                    subjectLog = loadSubjectLog(subjLogPath);
                    subjectIDNum = subjectLog(contains(subjectLog.SubjectName,app.SubjectDropDown_expmnt.Value),:).IDNum;
                    app.subjIDNum = string(subjectIDNum);
                    app.SubjectDropDownLabel.Text=presetName;
                case "None"
                    app.SubjectStatusPanel.Visible = "off";
                    app.NotesTextArea_expmnt.Visible = "off";
                    app.connectTargetButton.Enable="off";
                    app.DataStreamButton_expmnt.Enable = "off";
                    app.SubjectStatusPanel.Visible = "off";
                    app.NotesTextArea_expmnt.Visible = "off";
                    appFields = fieldnames(app);
                    panelFields = appFields(contains(appFields, "PanelSwitch_"));
                    for i = 1:length(panelFields)
                        switchRef = string(panelFields(i));
                        simulatedEvent.Source.Tag = switchRef;
                        % if app.(switchRef).Value == "On"
                        %     app.(switchRef).Value = "Off";
                        % togglePanelSwitch(app,simulatedEvent);
                        % end
                        if app.(switchRef).AltText== "On"
                            % app.(switchRef).AltText = "Off";
                            togglePanelSwitch(app,simulatedEvent);
                        end
                    end


            end

            if ~strcmp(app.SubjectDropDown_expmnt.Value,"None")
                LabelButtonPushed(app);
            end
            if ~strcmp(value,"None")
                app.connectTargetButton.Enable="on";
                app.DataStreamButton_expmnt.Enable = "on";
                app.QCButton.Enable="on";
                app.SubjectStatusPanel.Visible = "on";
                app.NotesTextArea_expmnt.Visible = "on";
            end

            % send experiment to cortex target
            % if ~isempty(app.cortexClient)
            %     writeline(app.cortexClient,sprintf("ExpmntDropDown:%s",app.ExpmntDropDownV2_slrt.Value));
            % end

            % allow modality fields to be toggled
            appFields = fieldnames(app);
            panelFields = appFields(contains(appFields, "PanelSwitch_"));

            for i = 1:length(panelFields)
                switchRef = string(panelFields(i));
                simulatedEvent.Source.Tag = switchRef;
                app.(switchRef).Enable = "on";                

                % Directly call togglePanelSwitch with the app and simulated event
                if ~strcmp(app.SubjectDropDown_expmnt.Value,"None")
                    togglePanelSwitch(app, simulatedEvent);
                end
            end

            app.DataStreamButton_expmnt.BackgroundColor = app.cyberGreen;
            
        end

        % Button pushed function: SaveSubjectLabelButton
        function SaveSubjectLabelButtonPushed(app, event)
            % Save preset in local proj folder; save subjectName in cloud proj folder
            logSubjectName(app);
            subjectLabel = storePreset(app);
            % Switch dropdown to Subject Save Button
            toggleItem(app, "SaveSubjectLabelButton", "SelectSubjectDropDownLabel");
            % Switch sessionLabel to subjectLabel
            toggleItem(app, "SubjectLabelLabel", "SessionLabelLabel");

            subjectLog = loadSubjectLog(app.params.paths.projDir_cloud);
            if ~isempty(subjectLog)
                subjNames = subjectLog.SubjectName;
            else
                subjNames = [];
            end
            populateDropDown(app, "SelectSubjectDropDown", subjNames);
            app.SubjectDropDownLabel.Text = subjectLabel;
        end

        % Value changed function: RNASensoryDropDownV2_gxT1
        function RNASensoryDropDownV2_gxT1ValueChanged(app, event)
            value = app.RNASensoryDropDownV2_gxT1.Value;
            LabelButtonPushed(app);
        end

        % Value changed function: GeneEditingDropDownV2_gxT1
        function GeneEditingDropDownV2_gxT1ValueChanged(app, event)
            value = app.GeneEditingDropDownV2_gxT1.Value;
            LabelButtonPushed(app);
        end

        % Value changed function: NeuralTargetDropDownV2_gxT1
        function NeuralTargetDropDownV2_gxT1ValueChanged(app, event)
            value = app.NeuralTargetDropDownV2_gxT1.Value;
            LabelButtonPushed(app);
        end

        % Value changed function: PhaseDropDown_slrt
        function PhaseDropDown_slrtValueChanged(app, event)
            value = app.PhaseDropDown_slrt.Value;
            % apps.params.paths.modulePath %= fullfile(app.ModulesFilePath.Value,app.ExpmntDropDownV2_slrt.Value);
            % apps.params.paths.moduleSlxPath =  fullfile(apps.params.paths.modulePath, (module + ".slx"));
            if strcmp(value,"+")

                if app.slxParamTable.Visible==1
                    app.ParameterTableButton.Value=0;
                    ParameterTableButtonValueChanged(app, event)
                end

                % load(fullfile(app.params.paths.modulePath,"expmntCfg.mat"),'expmntCfg');
                load_system(fullfile(app.params.paths.modulePath,app.params.experiment));
                app.mdlWks = get_param(app.params.experiment,'ModelWorkspace');
                slxParams = app.mdlWks.whos;
                slxFields = string(struct2table(slxParams).name);
                newPhaseData = {};
                for i = 1:length(slxParams)
                    % Get parameter name
                    paramName = slxParams(i).name;
                    % Get parameter value
                    paramValue = app.mdlWks.getVariable(paramName);

                    % Append the parameter and its value to the table
                    newRow = {paramName, paramValue};
                    % newRowTable = cell2table(newRow, 'VariableNames', {'Parameter', 'Value'});
                    % newRowTable = (newRow, 'VariableNames', {'Parameter', 'Value'});
                    % newPhaseData = [newPhaseData; newRowTable];
                    newPhaseData = [newPhaseData; newRow];
                end

                newPhaseDataTable = cell2table(newPhaseData,'VariableNames',{'Parameter','Value'});

                app.slrt.newPhaseTable = uitable(app.UIFigure,"Position",[698,403,300,285],"ColumnEditable",true,"ForegroundColor",[1,1,1],"BackgroundColor",app.cyberBlack,"ColumnFormat",{[],{'group1'}},"KeyPressFcn",@(source,event)slxParamTableKeyPress(app, event),"CellEditCallback",@(source,event)newPhaseParamTableCellEditCallback(app, event),"CellSelectionCallback",@(source,event)newPhaseParamTableCellSelectionCallback(app, event),"Data",newPhaseDataTable);
                app.slrt.newPhaseButton_save = uibutton(app.UIFigure,"Position",[660,655,34,31],"BackgroundColor",app.cyberGreen,"Text","","Icon","upload.png","ButtonPushedFcn",@(source, event)newPhaseButton_savePressed(app, event));
                app.slrt.newPhaseButton_discard = uibutton(app.UIFigure,"Position",[660,619,34,31],"BackgroundColor",app.cyberRed,"Text","","Icon","x-icon-white.png","ButtonPushedFcn",@(source, event)newPhaseButton_discardPressed(app, event));


            elseif ~strcmp(value,'None')
                load(fullfile(app.params.paths.modulePath,"expmntCfg.mat"),'expmntCfg');
                load_system(fullfile(app.params.paths.modulePath,app.params.experiment));
                app.mdlWks = get_param(app.params.experiment,'ModelWorkspace');
                slxParams = app.mdlWks.whos;
                slxFields = string(struct2table(slxParams).name);
                phaseParams = expmntCfg.phases.(value).WorkspaceVars;
                phaseParamFields = fieldnames(phaseParams);
                % Populate table
                for i = 1:length(phaseParamFields)
                    % Get parameter name
                    % paramName = slxParams(i).name;
                    paramName = string(phaseParamFields{i});
                    phaseVal = phaseParams.(paramName);
                    % Get parameter value
                    if any(contains(slxFields,paramName))
                        assignin(app.mdlWks,paramName,phaseVal);
                    end
                end
                save_system(app.params.experiment);
                if ~isempty(app.ExpmntDropDownV2_slrt.Value)
                    % Enable the Phase dropdowns
                    app.SubjectDropDown_expmnt.Enable = 'on';
                end
            end

            if ~strcmp(app.SubjectDropDown_expmnt.Value,"None")
                LabelButtonPushed(app);
            end

        end

        % Value changed function: ModalityDropDownV2_senseT1
        function ModalityDropDownV2_senseT1ValueChanged(app, event)
            value = app.ModalityDropDownV2_senseT1.Value;
            app.ModalityDropDownLabel_senseT1.Text = string(value);
            switch value
                case '+'
                    newInputType = inputdlg("Enter sensory input modality");
                    load(fullfile(app.params.paths.projDir_cloud,"Experiments",app.params.experiment,"expmntCfg.mat"),"expmntCfg");
                    if ~exist(expmntCfg.senses,"var"); expmntCfg.senses = struct; end
                    expmntCfg.senses = [expmntCfg.senses, newInputType];
                    app.ModalityDropDownV2_senseT1.Items = expmntCfg.senses;
                    save(fullfile(app.params.paths.projDir_cloud,"Experiments",app.params.experiment,"expmntCfg.mat"),"expmntCfg");
                otherwise
            end
        end

        % Callback function
        function AddTab_npxlsButtonPushed(app, event)
            addTabCallback(app,'npxls');
        end

        % Button pushed function: Tab_npxlsT1
        function Tab_npxlsT1ButtonPushed(app, event)
            app.Tab_npxlsT1.Tag = "Tab_npxlsT1";
            selectTab(app,app.Tab_npxlsT1);
        end

        % Callback function
        function AddTab_photonButtonPushed(app, event)
            addTabCallback(app,'photon');
        end

        % Callback function
        function Tab_photonT1ButtonPushed(app, event)
            app.Tab_photonT1.Tag = "Tab_photonT1";
            selectTab(app,app.Tab_photonT1);
        end

        % Value changed function: ParameterTableButton, 
        % ...and 1 other component
        function ParameterTableButtonValueChanged(app, event)
            value = app.ParameterTableButton.Value;
            switch value
                case 1
                    load_system(app.params.experiment)
                    app.mdlWks = get_param(app.params.experiment,'ModelWorkspace');
                    slxParams = app.mdlWks.whos;
                    slxParamsList = arrayfun(@(x) x.name, slxParams, 'UniformOutput', false);
                    % filter out modality specific params
                    modality_list = ["camera","photom","sense","photon","npxls","rx","gx","opto"];
                    isModality = false(height(slxParamsList), 1);
                    for i = 1:length(modality_list)
                        modalityWithT = [modality_list{i}, 'T'];
                        isModality = isModality | contains(slxParamsList, modalityWithT);
                    end
                    slxParams = slxParams(~isModality, :);

                    % Populate table
                    slxParamData = {};
                    for i = 1:length(slxParams)
                        % Get parameter name
                        paramName = slxParams(i).name;
                        % Get parameter value
                        paramValue = app.mdlWks.getVariable(paramName);

                        % Append the parameter and its value to the table
                        newRow = {paramName, paramValue};
                        % newRowTable = cell2table(newRow, 'VariableNames', {'Parameter', 'Value'});
                        slxParamData = [slxParamData; newRow];
                    end
                    % newRowTable = cell2table(newRow, 'VariableNames', {'Parameter', 'Value'});
                    app.slxParamTable.Data = cell2table(slxParamData, 'VariableNames', {'Parameter', 'Value'});
                    app.slxParamTable.Visible = 'on';
                    app.ParameterTableButton.BackgroundColor = app.disableGrey;
                    % app.slxParamTable.Position = [153,666,214,285];
                    app.slxParamTable.Position = [698,403,300,285];
                    % uistack()
                    % open_system(app.params.experiment);


                case 0
                    app.slxParamTable.Visible = 'off';
                    app.slxParamTable.Data = [];
                    app.ParameterTableButton.BackgroundColor = [0.00,0.00,0.00];
            end

        end

        % Selection changed function: slxParamTable
        function slxParamTableSelectionChanged(app, event)
            % indices = event.Selection;
            % slxParamData = event.Source.Data;
            % paramName = string(slxParamData(indices(1),:).Parameter);
            % % paramVal = event.NewData;
            % paramVal = slxParamData(indices(1),indices(2));
            % paramValExp = table2array(paramVal);
            % paramValExp = paramValExp{1,1};
            % valSize2 = size(paramValExp,2);
            % if valSize2 > 1
            %     % format as text
            %     formattedVal = sprintf("%d ",paramValExp);
            %     event.Source.Data(indices(1),indices(2)) = cell2table(cellstr(formattedVal));
            % end
        end

        % Value changed function: NoteButton_subj
        function NoteButton_subjValueChanged(app, event)
            value = app.NoteButton_subj.Value;
            switch value
                case 1
                    app.NotesTextArea_expmnt.Visible = 'on';
                case 0
                    app.NotesTextArea_expmnt.Visible = 'off';
            end
        end

        % Callback function
        function TwoPhotonSwitch_photonValueChanged(app, event)
            value = app.photonPanelSwitch_photon.Value;
            LabelButtonPushed(app);
        end

        % Button pushed function: ProbeAreasSearchButton_npxlsT1
        function exploreProbeAreaFiles(app, event)
            probeSearchButtonRef = event.Source.Tag;
            [probeFile, probeFileLoc] = uigetfile;
            tabNum = split(probeSearchButtonRef,'_');
            npxlsTabID = string(tabNum(2));
            tabNum = strrep(npxlsTabID,'npxlsT','');
            probeID = sprintf("probe%s",tabNum); % for now, probeID is same as associated tabNum
            app.npxls.(probeID).position = load(fullfile(probeFileLoc,probeFile));
            probeShankRegsLabelRef = sprintf("ProbeShankRegionsLabel_%s",npxlsTabID);
            probeAreas = app.npxls.(probeID).position.probe_areas{1,1};
            if str2double(tabNum)>1
                app.tabs.(probeShankRegsLabelRef).Text = join(probeAreas.acronym,', ');
            else
                app.(probeShankRegsLabelRef).Text = join(probeAreas.acronym,', ');
            end

        end

        % Image clicked function: ProbeAreasDisplayButton_npxlsT1
        function displayProbeArea(app, event)
            probeDisplayButtonRef = event.Source.Tag;
            tabNum = split(probeDisplayButtonRef,'_');
            npxlsTabID = string(tabNum(2));
            tabNum = strrep(npxlsTabID,'npxlsT','');
            probeID = sprintf("probe%s",tabNum); % for now, probeID is same as associated tabNum
            probeShankRegsLabelRef = sprintf("ProbeShankRegionsLabel_%s",npxlsTabID);

            if str2double(tabNum)>1
                value = app.tabs.(probeDisplayButtonRef).AltText;
                switch value
                    case '0' % currently off, turn on
                        probePosData = app.npxls.(probeID).position.probe_areas{1,1};
                        probePosData = probePosData(:,{'name','acronym','probe_tip_distance'});
                        app.npxls.(probeID).positionTable = uitable("Parent",app.UIFigure,"Position",[180,110,300,434],"Data",probePosData);
                        app.tabs.(probeShankRegsLabelRef).FontColor = app.enableWhite;
                        app.tabs.(probeDisplayButtonRef).AltText = '1';
                    case '1'
                        delete(app.npxls.(probeID).positionTable)
                        app.tabs.(probeShankRegsLabelRef).FontColor = app.cyberGreen;
                        app.tabs.(probeDisplayButtonRef).AltText = '0';
                end
            else
                value = app.(probeDisplayButtonRef).AltText;
                switch value
                    case '0' % currently off, turn on
                        probePosData = app.npxls.(probeID).position.probe_areas{1,1};
                        probePosData = probePosData(:,{'name','acronym','probe_tip_distance'});
                        app.npxls.(probeID).positionTable = uitable("Parent",app.UIFigure,"Position",[180,110,300,434],"Data",probePosData);
                        app.(probeShankRegsLabelRef).FontColor = app.enableWhite;
                        app.(probeDisplayButtonRef).AltText = '1';
                    case '1'
                        delete(app.npxls.(probeID).positionTable)
                        app.(probeShankRegsLabelRef).FontColor = app.cyberGreen;
                        app.(probeDisplayButtonRef).AltText = '0';
                end
            end


        end

        % Value changed function: CalibrationButton_senseT1
        function CalibrateSenseApparatus(app, event)
            calibBtnRef = event.Source.Tag;
            tabNum = split(calibBtnRef,'_');
            senseTabID = string(tabNum(2));
            tabNum = strrep(senseTabID,'senseT','');
            senseID = sprintf("sense%s",tabNum); % for now, probeID is same as associated tabNum
            % draw calibration interface and assign callbacks
            apparatusDropDownRef = sprintf("ApparatusDropDown_%s",senseTabID);
            apparatus = referenceTabField(app,apparatusDropDownRef,"Value",[]);
            app.sense.(senseID).calibration.calibrationModel = sprintf("calibrate_%s",apparatus);
            if ~strcmp(apparatus,"None")
                referenceTabField(app, calibBtnRef,"Visible","off");
                txButtonRef = sprintf("transmitterButton_%s",senseTabID);
                setPtEntryRef = sprintf("setPointEntry_%s",senseTabID);
                playButtonRef = sprintf("playButton_%s",senseTabID);
                app.sense.(senseID).calibration.uiControl.txButton = uibutton(app.sensePanel,"state","Position",[47,24,34,48],"BackgroundColor",app.cyberBlack,"Tag",txButtonRef,"Icon","Icons/tcpTx_active.PNG","Text","","ValueChangedFcn",@(source,event)calibrationTxButtonPressed(app,event));
                app.sense.(senseID).calibration.uiControl.setPointEntry = uieditfield(app.sensePanel,"numeric","Position",[85,24,52,48],"BackgroundColor",app.cyberBlack,"FontColor",app.cyberGreen,"Tag",setPtEntryRef,"FontSize",20,"HorizontalAlignment","center","ValueChangedFcn",@(source, event)calibSetPointEntryChanged(app, event));
                app.sense.(senseID).calibration.uiControl.playButton = uibutton(app.sensePanel,"state","Position",[9,24,34,48],"BackgroundColor",app.cyberBlack,"Icon","Icons/calibRunIcon.PNG","Text","","Value",0,"Tag",playButtonRef,"ValueChangedFcn",@(source,event)calibrationPlayButtonPressed(app,event));
                % app.sense.(senseID).calibration.uiControl.playButton.Icon='slrtRunIcon.png';
                app.sense.(senseID).calibration.uiControl.playButton.Value=0;
            end
        end

        % Value changed function: SerialNumberDropDownV2_cameraT1
        function SerialNumberDropDownV2_cameraValueChanged(app, event)
            % value = app.SerialNumberDropDownV2_cameraT1.Value;
            SNDropDownRef = event.Source.Tag; % identify the parameter table
            refParts = split(SNDropDownRef,'DropDown');
            DropDownRef = strrep(SNDropDownRef,refParts{1},'');
            DropDownRef = split(DropDownRef,'_');
            DropDownRef = DropDownRef{1};
            SNDropDownLabelRef = strrep(SNDropDownRef,DropDownRef,'DropDownLabel');
            [tabID, tabNum] = derefFieldTag(app, SNDropDownRef);
            panelRef = split(tabID,'T');
            panelRef = panelRef{1};
            tabRef = strrep(tabID,'T','');
            % value changed Fcn
            % value = app.(paramTableButtonRef).Value;
            value = referenceTabField(app, SNDropDownRef,'Value',[]);
            % referenceTabField(app,SNDropDownLabelRef,'Text',value);
            if ~strcmp(value,"None")
                params = loadParamTable(app, tabID, tabNum);
                app.editingModalityLabel_expmnt.Visible="off";
            end
        end

        % Callback function
        function remove(app, event)
            % if isempty(N) || isnan(N)
        end

        % Button pushed function: BrowseModules
        function SelectModulesFolder(app, event)
            if ~isempty(app.ModulesFilePath.Value)
                % Reset the dependencies
                app.ExpmntDropDownV2_slrt.Items = {'None'};
                app.ExpmntDropDownV2_slrt.Value = 'None';
                app.PhaseDropDown_slrt.Value = 'None';
                app.ExpmntDropDownV2_slrt.Enable = 'off';
                app.PhaseDropDown_slrt.Enable = 'off';
                app.BrowseProjects.Enable = 'on';
            end

            % Open a folder selection dialog box
            modulePath = uigetdir;

            % Check if the user clicked 'Cancel'
            if modulePath == 0
                %disp('No experiment module selected.');
                return;
            end

            % Automatically display the selected path in the specified text box
            app.ModulesFilePath.Value = modulePath;

            if ~isempty(app.ModulesFilePath.Value)
                % Enable the Experiment and Phase dropdowns
                app.BrowseProjects.Enable = 'on';
            end

            % Use the listFolders function to get the folders in that path
            experimentFolders = listFolders(app,modulePath);
            unwantedFolders = [".git", ".qnx", "slxHelpers"]; % List of folders to exclude
            experimentFolders = experimentFolders(~ismember(experimentFolders, unwantedFolders));
            experimentFolders = [{'None'}, sort(experimentFolders), {'+'}];


            % Set the Items property of the ExpmntDropDown to the folders list
            app.ExpmntDropDownV2_slrt.Items = experimentFolders;

            % Set a default value if needed
            app.ExpmntDropDownV2_slrt.Value = "None";


        end

        % Button pushed function: BrowseProjects
        function BrowseProjectDirectory(app, event)
            % Open a folder selection dialog box
            projectPath = uigetdir;

            % Check if the user clicked 'Cancel'
            if projectPath == 0
                disp('No project selected.');
                return;
            end

            % Automatically display the selected path in the specified text box
            app.ProjectPathTextEditField_proj.Value = projectPath;
            [~, projectName, ~] = fileparts(projectPath);

            opts.paths.projDir_cloud = projectPath;
            opts.projectName = projectName;

            if ~isempty(app.ProjectPathTextEditField_proj.Value)
                % Enable the Experiment and Phase dropdowns
                app.ExpmntDropDownV2_slrt.Enable = 'on';
                app.PhaseDropDown_slrt.Enable = 'on';
            end

            app.params = setHostParams(opts);
            app.params.paths.nCORTEx_repo = app.nCORTEx_repo;
            hostName = getHostName();
            % save cache
            cachePath = fullfile("Setup","nCORTExCache.mat");
            if isfile(cachePath)
                load(cachePath,'cache');
                cache.(hostName).modulePath = app.ModulesFilePath.Value;
                cache.(hostName).projectPath = app.ProjectPathTextEditField_proj.Value;
                save(cachePath,'cache');
            end

        end

        % Image clicked function: QCButton
        function showQCPanel(app, event)
            % List of panels to toggle
            panels = [app.SessionQCLabel, app.SessionQCPanel, app.ModalityPanel, app.ExperimentSetupLabel];

            for i = 1:length(panels)
                % Check if the current panel is visible
                if strcmp(panels(i).Visible, 'on')
                    % If it's visible, turn it off
                    panels(i).Visible = 'off';
                    app.QCButton.ImageSource=fullfile(app.nCORTEx_repo,"Icons","QC.png");
                else
                    % If it's not visible, turn it on
                    panels(i).Visible = 'on';
                    app.QCButton.ImageSource=fullfile(app.nCORTEx_repo,"Icons","QC_off.png");
                end
            end

        end

        % Value changed function: SessionTimerminEditField
        function SessionTimerminEditFieldValueChanged(app, event)
            value = app.SessionTimerminEditField.Value;
            load_system(fullfile(app.params.paths.modulePath,app.params.experiment));
            set_param(app.params.experiment,'StopTime', string(value*60));
            save_system(fullfile(app.params.paths.modulePath,app.params.experiment));
        end

        % Drop down opening function: ExpmntDropDownV2_slrt
        function ExpmntDropDownV2_slrtOpeningFcn(app, event)
            app.ExpmntDropDownV2_slrt.Value = 'None';
        end

        % Callback function
        function SubjectDropDown_subjOpeningFcn(app, event)
            %app.SubjectDropDown_expmnt.Value = "None";
            % allow modality fields to be toggled
            appFields = fieldnames(app);
            panelFields = appFields(contains(appFields, "PanelSwitch_"));
            for i = 1:length(panelFields)
                switchRef = string(panelFields(i));
                simulatedEvent.Source.Tag = switchRef;
                if app.(switchRef).Visible == "On"
                    app.(switchRef).Visible = "Off";
                    togglePanelSwitch(app,simulatedEvent);
                end
            end
        end

        % Callback function
        function FSNumericEntry_cameraT1ValueChanged(app, event)
            value = app.FSNumericEntry_cameraT1.Value;
            paramName = string(slxParamData(indices(1),:).Parameter);
            paramVal = event.NewData;
            assignin(app.mdlWks,paramName,paramVal);
            save_system(app.params.experiment);
        end

        % Value changed function: FSNumericEntry_cameraT1
        function FSNumericEntry_cameraValueChanged(app, event)
            FSNumericEntryRef = event.Source.Tag;
            tabRef = split(FSNumericEntryRef,"_");
            tabRef = tabRef{2};
            % value = app.(FSNumericEntryRef).Value;
            paramVal = (referenceTabField(app,FSNumericEntryRef,"Value",[]));
            app.mdlWks = get_param(app.params.experiment,'ModelWorkspace');
            % paramName = string(slxParamData(indices(1),:).Parameter);
            paramName = sprintf("%s_fs",tabRef);
            assignin(app.mdlWks,paramName,paramVal);
            save_system(app.params.experiment);

        end

        % Value changed function: VectorDropDownV2_photomT1_chan1
        function VectorLabel(app, event)
            value = app.VectorDropDownV2_photomT1_chan1.Value;

        end

        % Callback function
        function Tab_photom_ch_ButtonPushed(app, event)
            % uistack(app.tabs.channelPanel_photomT2,"top");
            ChanPanRef = event.Source.Tag;
            tabParts = split(ChanPanRef,"_");
            panelRef = tabParts{2};
            chanRef = tabParts{3};
            chan1Ref = "chan1";
            tabNum = split(panelRef,"T");
            tabNum = str2double(tabNum{2});
            channelsRef = sprintf("channels%d",tabNum);
            appFields = fieldnames(app);
            tabFields = fieldnames(app.tabs);
            appFields = [appFields; tabFields];
            photomChanFields = appFields(contains(appFields,sprintf("%s_%s",panelRef,chan1Ref)));
            tabButtonRef = sprintf("Subtab_%s_%s",panelRef,chanRef);
            chan1TabButtonRef = sprintf("Subtab_%s_%s",panelRef,chan1Ref);
            % app.(tabButtonRef).BackgroundColor = app.enableWhite;
            referenceTabField(app,tabButtonRef,"BackgroundColor",app.enableWhite);
            % app.(tabButtonRef).Enable = "on";
            referenceTabField(app,tabButtonRef,"Enable","on");
            % app.(sprintf("chanNumLabel_%s_chan",panelRef)).Text = (strrep(chanRef,"chan",""));
            chanNumLabelRef = sprintf("chanNumLabel_%s_chan",panelRef);
            referenceTabField(app,chanNumLabelRef,"Text",(strrep(chanRef,"chan","")));
            % app.(tabButtonRef).Visible = "on";

            % generate numbered chanFields
            for i = 1:length(photomChanFields)
                chanField = photomChanFields{i};
                chanNField = strrep(chanField,chan1Ref,chanRef);
                if ~contains(chanNField,"Subtab_photom") && ~contains(chanNField,"chanNumLabel")
                    % initialize if not already
                    if ~isfield(app.photom,channelsRef); app.photom.(channelsRef) = struct; end
                    if ~isfield(app.photom.(channelsRef),chanRef); app.photom.(channelsRef).(chanRef) = struct; end
                    if ~isfield(app.photom.(channelsRef).(chanRef),chanNField)
                        % if strcmp(chanRef,chan1Ref)
                        %     % if this is chan1 reassign handles
                        %     app.photom.channels.(chanRef).(chanNField) = app.(chanField);
                        %     app.(chanField).Visible="off";
                        % else % make new handles
                        field2Copy = referenceTabField(app,chanField,"hndl",[]);
                        % app.photom.(channelsRef).(chanRef).(chanNField) = copyobj(app.(chanField),app.UIFigure);
                        try
                            app.photom.(channelsRef).(chanRef).(chanNField) = copyobj(field2Copy,app.UIFigure);
                        catch
                            disp("%s_%s_BROKE",chanRef,chanNField)
                            continue
                        end
                        % app.photom.(channelsRef).(chanRef).(chanNField).Parent = app.(chanField).Parent;
                        app.photom.(channelsRef).(chanRef).(chanNField).Parent = field2Copy.Parent;
                        % app.photom.(channelsRef).(chanRef).(chanNField).Position = app.(chanField).Position;
                        % STEP = (tabNum) * 20
                        app.photom.(channelsRef).(chanRef).(chanNField).Position = field2Copy.Position; %+ [STEP, 0, 0, 0];
                        % app.(chanField).Visible="off";
                        % % DISABLE STARTER FIELDS AFTER COPIED
                        referenceTabField(app,chanField,"Visible","off");
                        % app.(chanField).Enable="off";
                        referenceTabField(app,chanField,"Enable","off");
                        % app.tabs.(tabNField).Position = app.(tab1Field).Position;
                        % end
                    end
                    % show those indexed by chanRef
                    app.photom.(channelsRef).(chanRef).(chanNField).Visible = "on";
                    app.photom.(channelsRef).(chanRef).(chanNField).Enable = "on";
                    % referenceTabField(app,chanField,"Visible","off");
                    % app.(chanField).Enable="off";
                    % referenceTabField(app,chanField,"Enable","off");


                    % hide all except for those indexed by chanRef


                    % app.tabs.(tabNField) = copyobj(app.(tab1Field),app.UIFigure);
                    % panelPos = app.(sprintf("%sPanel",modRef)).Position;
                    % modPanelPos = app.ModalityPanel.Position;
                    % app.tabs.(tabNField).Parent = app.(tab1Field).Parent;
                    % app.tabs.(tabNField).Position = app.(tab1Field).Position; %+ [panelPos(1), panelPos(2), 0, 0]; %+ [modPanelPos(1), modPanelPos(2), 0, 0];
                    % app.tabs.(tabNField).Visible = 'off';
                    % app.tabs.(tabNField).Tag = tabNField;
                elseif contains(chanNField,"Subtab_photom")
                    % app.photom.(channelsRef).(chanRef).(chanNField).Visible = "on";
                    % app.photom.(channelsRef).(chanRef).(chanNField).Enable = "on";
                    % app.photom.channelsRef.(chanRef).(chanNField) = copyobj(app.(tabButtonRef),app.UIFigure);
                    % app.photom.channelsRef.(chanRef).(chanNField).Parent = app.(tabButtonRef).Parent;
                    % app.photom.channelsRef.(chanRef).(chanNField).Position = app.(tabButtonRef).Position;
                    % app.(chanField).Visible="off";
                    % app.(chanField).Enable="off";
                    % referenceTabField(app,chanField,"Visible","off");                
                    % referenceTabField(app,chanField,"Enable","off");
                end                             

            end
            % app.(chan1TabButtonRef).Visible="off";
            % app.(chan1TabButtonRef).Enable="off";
            hideOtherChannels(app,app.photom.(channelsRef), chanRef, panelRef, channelsRef);
        end

        % Value changed function: NeuralTargetDropDownV2_photomT1
        function NeuralTargetDropDownV2_photomT1ValueChanged(app, event)
            panelTabRef = event.Source.Tag;
            tabParts = split(panelTabRef,"_");
            panelRef = tabParts{2};
            referenceTabField(app,panelTabRef,"Value",[]);

            % dropDownTitleParts = split(dropDownField,'_');
            % fieldTitle = string(dropDownTitleParts(1));
            % panelTabRef = string(dropDownTitleParts(2));
            % dropDownFieldLabel = sprintf("%sLabel_%s",fieldTitle,panelTabRef);

            % app.(dropDownFieldLabel).Text = app.(dropDownField).Value;
            val = referenceTabField(app,panelTabRef,"Value",[]);
            if strcmp(val,"+")
                Items = referenceTabField(app,panelTabRef,"Items",[]);
                % Items = [app.(dropDownField).Items, inputdlg("enter new drop down entry")];
                Items = [Items, inputdlg("enter new drop down entry")];
                Items = Items(~strcmp(Items,"+"));
                Items = Items(~strcmp(Items,"None"));
                Items = ["None",sort(Items),"+"];
                referenceTabField(app,panelTabRef,"Items",Items);
                % app.(dropDownField).Items = ["None",sort(Items),"+"];
            end


            % appFields = fieldnames(app);
            % tabFields = fieldnames(app.tabs);
            % tabButtonRef = sprintf("Subtab_%s_chan",panelRef);
            % photomChanTabButtonFields = appFields(contains(appFields,tabButtonRef));
            % photomChanTabButtonFields = [photomChanTabButtonFields; tabFields(contains(tabFields,tabButtonRef))];
            % for i = flip(1:length(photomChanTabButtonFields),2)
            %     chanTabRef = photomChanTabButtonFields{i};
            %     simEvent.Source.Tag = chanTabRef;
            %     Tab_photom_ch_ButtonPushed(app,simEvent);
            % end


        end

        % Cell edit callback: slxParamTable
        function slxParamTableCellEdit(app, event)
            indices = event.Indices;
            slxParamData = event.Source.Data;
            paramName = string(slxParamData(indices(1),:).Parameter);
            paramVal = event.NewData;
            % REFORMAT IF NEEDED
            % paramValExp = split(paramVal," ")';
            paramVal = strrep(paramVal,'[','');
            paramVal = strrep(paramVal,' ]','');
            if ischar(paramVal)
                paramValExp = split(paramVal,' ');
                % paramValExp = paramValExp{1,1};
                % valSize2 = size(paramValExp,2);
                valSize2 = size(paramValExp,2);
                if valSize2 > 1
                    % format as text
                    % formattedVal = sprintf("%d ",paramVal);
                    paramVal = str2double(paramValExp);
                    event.Source.Data{indices(1),indices(2)} = {paramVal};
                end
            else
                event.Source.Data{indices(1),indices(2)} = {paramVal};
            end
            % REWRITE NEW ENTRY TO PHASE PRESET
            phaseName = app.PhaseDropDown_slrt.Value;
            if ~strcmp(phaseName,"None")
                updatePhase(app, paramName, paramVal, phaseName);
            end
            % REWRITE NEW ENTRY TO SLX FILE
            assignin(app.mdlWks,paramName,paramVal);
            save_system(app.params.experiment);
            
        end

        % Key press function: slxParamTable
        function slxParamTableKeyPress(app, event)
            keyID = (event.Key);
            if strcmp(keyID,'escape')
                % originalData = event.Source.originalData;
                event.Source.Data = compileSlxParamTable(app);
                if ~iscell(event.Source.Data.Value)
                    event.Source.Data.Value = num2cell(event.Source.Data.Value(:,:));
                end
                % restore original
            elseif strcmp(keyID,'return')
                indices = event.Source.Selection;
                dataSel = event.Source.Data{indices(1),indices(2)};
                dataSel = dataSel{1,1};
                dataUnbracket = strrep(dataSel,'[','');
                dataUnbracket = strrep(dataUnbracket,' ]','');
                if ischar(dataUnbracket)
                    dataSplit = split(dataUnbracket,' ');
                    if contains(dataSel,' ')
                        try
                            % formattedVal = sprintf("%d ",dataSplit);
                            unformattedVal = str2double(dataSplit);
                            event.Source.Data{indices(1),indices(2)} = {unformattedVal'};
                        catch
                        end
                    end
                else
                    event.Source.Data{indices(1),indices(2)} = {dataUnbracket'};
                end


            end
            
        end

        % Cell selection callback: slxParamTable
        function slxParamTableCellSelection(app, event)
            % indices = event.Indices;
            % slxParamData = event.Source.Data;
            % if ~isempty(indices)
            %     paramName = string(slxParamData(indices(1),:).Parameter);
            %     % paramVal = event.NewData;
            %     paramVal = slxParamData(indices(1),indices(2));
            %     paramValExp = table2array(paramVal);
            %     paramValExp = paramValExp{1,1};
            %     valSize2 = size(paramValExp,2);
            %     if valSize2 > 1
            %         % format as text
            %         formattedVal = sprintf("%d ",paramValExp);
            %         event.Source.Data(indices(1),indices(2)) = cell2table(cellstr(formattedVal));
            %     end
            % end

            indices = event.Indices;
            if ~isempty(indices)
                if ~iscell(event.Source.Data.Value)
                    event.Source.Data.Value = num2cell(event.Source.Data.Value);
                end
                slxParamData = event.Source.Data;                
                paramName = string(slxParamData(indices(1),:).Parameter);
                % paramVal = event.NewData;
                paramVal = slxParamData(indices(1),indices(2));
                paramValExp = table2array(paramVal);
                % try
                paramValExp = paramValExp{1,1};
                % catch
                    % continue
                valSize2 = size(paramValExp,2);
                if valSize2 > 1 && isa(paramValExp,'double') %|| (isa(paramValExp,'nan'))
                    % format as text
                    formattedVal = sprintf("%d ",paramValExp);
                    formattedVal = sprintf("[%s]",formattedVal);
                    event.Source.Data(indices(1),indices(2)) = cell2table(cellstr(formattedVal));
                end
            end
            
        end

        % Button pushed function: Subtab_photomT1_chan1, 
        % ...and 7 other components
        function chanButtonPushed(app, event)
            modChanRef = event.Source.Tag;            
            tabParts = split(modChanRef,"_");
            panelRef = tabParts{2};
            chanRef = tabParts{3};
            % chan1Ref = "chan1";
            tabNum = split(panelRef,"T");
            modRef = tabNum{1};                     
            tabButtonRef = sprintf("Subtab_%s_%s",panelRef,chanRef);            
            % HIGHLIGHT CHANNEL SELECTION
            appFields = fieldnames(app);
            
            subtabButtonRefs = appFields(contains(appFields,"Subtab") & contains(appFields,modRef));
            numChans = length(subtabButtonRefs);
            for i = 1:numChans
                chanTabButtonRef = sprintf("Subtab_%s_chan%d",panelRef,i);
                if contains(chanTabButtonRef,chanRef)
                    referenceTabField(app,chanTabButtonRef,"BackgroundColor",app.enableWhite);
                    % referenceTabField(app,chanTabButtonRef,"Enable","on");
                    chanNumLabelRef = sprintf("chanNumLabel_%s_chan",panelRef);
                else
                    referenceTabField(app,chanTabButtonRef,"BackgroundColor",app.disableGrey);
                    % referenceTabField(app,chanTabButtonRef,"Enable","off");
                end
            end
            % DENOTE SELECTION NUMBER
            referenceTabField(app,chanNumLabelRef,"Text",(strrep(chanRef,"chan","")));
            % app.(tabButtonRef).Visible = "on";
            chanFields = fieldnames(app.(modRef).channels);
            for i = 1:length(chanFields)
                try
                    chanField = chanFields{i};
                    if contains(chanField,chanRef)
                        app.(modRef).channels.(chanField).Visible="on";
                        app.(modRef).channels.(chanField).Enable="on";
                    else
                        app.(modRef).channels.(chanField).Visible="off";
                        app.(modRef).channels.(chanField).Enable="off";
                    end
                    % HIDE ORIGINAL
                    if contains(chanField,"chan1")
                        referenceTabField(app,chanField,"Visible","off");                
                        referenceTabField(app,chanField,"Enable","off");
                    end
                catch
                    disp(sprintf("BROKE_%s",chanField))
                end
            end
            % hideOtherChannels(app,app.photom.(channelsRef), chanRef, panelRef, channelsRef);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.nCORTEx_waitbar = uiaxes(app.UIFigure);
            title(app.nCORTEx_waitbar, 'Title')
            xlabel(app.nCORTEx_waitbar, 'X')
            ylabel(app.nCORTEx_waitbar, 'Y')
            zlabel(app.nCORTEx_waitbar, 'Z')
            app.nCORTEx_waitbar.XTick = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.nCORTEx_waitbar.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Icons', 'cortexProgressBar.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Enable = 'off';
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'cortexDash2.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.Enable = 'off';
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Icons', 'cortexDash1.png');
            app.SessionQCPanel = uipanel(app.UIFigure);
            app.SessionQCPanel.Visible = 'off';
            app.JOLTPanel = uipanel(app.UIFigure);
            app.JOLTPanel.Title = 'JOLT';
            app.JOLTPanel.Visible = 'off';
            app.JOLTSwipeRightButton = uibutton(app.JOLTPanel, 'state');
            app.JOLTSwipeRightButton.ValueChangedFcn = createCallbackFcn(app, @JOLTSwipeRightButtonValueChanged, true);
            app.JOLTSwipeRightButton.Text = '>';
            app.JOLTSwipeLeftButton = uibutton(app.JOLTPanel, 'state');
            app.JOLTSwipeLeftButton.ValueChangedFcn = createCallbackFcn(app, @JOLTSwipeLeftButtonValueChanged, true);
            app.JOLTSwipeLeftButton.Text = '<';
            app.JOLTButtonsPanel = uipanel(app.JOLTPanel);
            app.JOLTButtonsPanel.Title = 'JOLTButtonsPanel';
            app.MonofilAxes = uiaxes(app.JOLTButtonsPanel);
            xlabel(app.MonofilAxes, 'X')
            ylabel(app.MonofilAxes, 'Y')
            zlabel(app.MonofilAxes, 'Z')
            app.MonofilAxes.XLim = [0 1];
            app.MonofilAxes.YLim = [0 255];
            app.MonofilAxes.XTick = [0 0.0005 0.001 0.0015 0.002 0.0025 0.003 0.0035 0.004 0.0045 0.005 0.0055 0.006 0.0065 0.007 0.0075 0.008 0.0085 0.009 0.0095 0.01 0.0105 0.011 0.0115 0.012 0.0125 0.013 0.0135 0.014 0.0145];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
            app.MonofilAxes.XTickLabel = '';
            app.MonofilAxes.YTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29];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
            app.MonofilAxes.YTickLabel = '';
            app.JOLTStartTrialButton = uibutton(app.JOLTButtonsPanel, 'push');
            app.JOLTStartTrialButton.ButtonPushedFcn = createCallbackFcn(app, @JOLTStartTrialButtonPushed, true);
            app.JOLTStartTrialButton.Icon = fullfile(pathToMLAPP, 'Icons', 'player_icon.png');
            app.JOLTStartTrialButton.Text = '';
            app.JOLTSaveButton = uibutton(app.JOLTButtonsPanel, 'push');
            app.JOLTSaveButton.ButtonPushedFcn = createCallbackFcn(app, @JOLTSaveButtonPushed, true);
            app.JOLTSaveButton.Icon = fullfile(pathToMLAPP, 'Icons', 'check.png');
            app.JOLTSaveButton.Text = '';
            app.JOLTDiscardButton = uibutton(app.JOLTButtonsPanel, 'push');
            app.JOLTDiscardButton.ButtonPushedFcn = createCallbackFcn(app, @JOLTDiscardButtonPushed, true);
            app.JOLTDiscardButton.Icon = fullfile(pathToMLAPP, 'Icons', 'x-icon-white.png');
            app.JOLTDiscardButton.Text = '';
            app.JOLTsaveCount = uilabel(app.JOLTButtonsPanel);
            app.JOLTsaveCount.Text = '--';
            app.JOLTdiscardCount = uilabel(app.JOLTButtonsPanel);
            app.JOLTdiscardCount.Text = '--';
            app.JOLTValidationPanel = uipanel(app.JOLTPanel);
            app.JOLTValidationPanel.Title = 'JOLTButtons';
            app.JOLTValidationPanel.Visible = 'off';
            app.JOLT_ValidateButton = uibutton(app.JOLTValidationPanel, 'push');
            app.JOLT_ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @JOLT_ValidateButtonPushed, true);
            app.JOLT_ValidateButton.Text = 'Validate';
            app.NpxlsMeanHz_JOLT = uilabel(app.JOLTValidationPanel);
            app.NpxlsMeanHz_JOLT.Text = '--';
            app.NpxlsStdHz_JOLT = uilabel(app.JOLTValidationPanel);
            app.NpxlsStdHz_JOLT.Text = '--';
            app.NpxlsStatLabel_JOLT = uilabel(app.JOLTValidationPanel);
            app.NpxlsStatLabel_JOLT.Text = 'Npxls:';
            app.SessionMetricsPanel = uipanel(app.UIFigure);
            app.SessionMetricsPanel.Enable = 'off';
            app.SessionMetricsPanel.Title = 'Session Metrics';
            app.SessionMetricsPanel.Visible = 'off';
            app.UIAxes_PupilFrames = uiaxes(app.SessionMetricsPanel);
            title(app.UIAxes_PupilFrames, 'Title')
            xlabel(app.UIAxes_PupilFrames, 'X')
            ylabel(app.UIAxes_PupilFrames, 'Y')
            zlabel(app.UIAxes_PupilFrames, 'Z')
            app.UIAxes_PupilFrames.XTick = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.UIAxes_PupilFrames.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_WhiskFrames = uiaxes(app.SessionMetricsPanel);
            title(app.UIAxes_WhiskFrames, 'Title')
            xlabel(app.UIAxes_WhiskFrames, 'X')
            ylabel(app.UIAxes_WhiskFrames, 'Y')
            zlabel(app.UIAxes_WhiskFrames, 'Z')
            app.UIAxes_WhiskFrames.XTick = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.UIAxes_WhiskFrames.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.PupilLabel = uilabel(app.SessionMetricsPanel);
            app.PupilLabel.Enable = 'off';
            app.PupilLabel.Visible = 'off';
            app.PupilLabel.Text = 'Pupil';
            app.WhiskLabel = uilabel(app.SessionMetricsPanel);
            app.WhiskLabel.Enable = 'off';
            app.WhiskLabel.Visible = 'off';
            app.WhiskLabel.Text = 'Whisker';
            app.WhiskFrmCount = uilabel(app.SessionMetricsPanel);
            app.WhiskFrmCount.Enable = 'off';
            app.WhiskFrmCount.Visible = 'off';
            app.WhiskFrmCount.Text = '- -';
            app.PupilFrmCount = uilabel(app.SessionMetricsPanel);
            app.PupilFrmCount.Enable = 'off';
            app.PupilFrmCount.Visible = 'off';
            app.PupilFrmCount.Text = '- -';
            app.ValidateButton = uibutton(app.SessionMetricsPanel, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Enable = 'off';
            app.ValidateButton.Visible = 'off';
            app.ValidateButton.Text = 'Validate';
            app.HitRate = uilabel(app.SessionMetricsPanel);
            app.HitRate.Enable = 'off';
            app.HitRate.Visible = 'off';
            app.HitRate.Text = '-- %';
            app.HitRateLabel = uilabel(app.SessionMetricsPanel);
            app.HitRateLabel.Enable = 'off';
            app.HitRateLabel.Visible = 'off';
            app.HitRateLabel.Text = 'Hit Rate';
            app.PupilMeanHz = uilabel(app.SessionMetricsPanel);
            app.PupilMeanHz.Text = '--';
            app.WhiskMeanHz = uilabel(app.SessionMetricsPanel);
            app.WhiskMeanHz.Text = '--';
            app.NpxlsMeanHz = uilabel(app.SessionMetricsPanel);
            app.NpxlsMeanHz.Text = '--';
            app.PupilStdHz = uilabel(app.SessionMetricsPanel);
            app.PupilStdHz.Text = '--';
            app.WhiskStdHz = uilabel(app.SessionMetricsPanel);
            app.WhiskStdHz.Text = '--';
            app.NpxlsStdHz = uilabel(app.SessionMetricsPanel);
            app.NpxlsStdHz.Text = '--';
            app.PupilStatLabel = uilabel(app.SessionMetricsPanel);
            app.PupilStatLabel.Text = 'Pupil:';
            app.WhiskStatLabel = uilabel(app.SessionMetricsPanel);
            app.WhiskStatLabel.Text = 'Whisker:';
            app.NpxlsStatLabel = uilabel(app.SessionMetricsPanel);
            app.NpxlsStatLabel.Text = 'Npxls:';
            app.MeanHzLabel = uilabel(app.SessionMetricsPanel);
            app.MeanHzLabel.Text = 'Mean (Hz)';
            app.StdHzLabel = uilabel(app.SessionMetricsPanel);
            app.StdHzLabel.Text = 'Std (Hz)';
            app.ModalityPanel = uipanel(app.UIFigure);
            app.ModalityPanel.Title = 'Panel';
            app.sensePanel = uipanel(app.ModalityPanel);
            app.sensePanel.Title = 'Sensory';
            app.sensePanel.Visible = 'off';
            app.ModalityDropDownLabel_senseT1 = uilabel(app.sensePanel);
            app.ModalityDropDownLabel_senseT1.Text = 'Modality';
            app.ModalityDropDownV2_senseT1 = uidropdown(app.sensePanel);
            app.ModalityDropDownV2_senseT1.Items = {'None', 'Tactile', 'Auditory', 'Gustatory', 'Visual', '+'};
            app.ModalityDropDownV2_senseT1.ValueChangedFcn = createCallbackFcn(app, @ModalityDropDownV2_senseT1ValueChanged, true);
            app.ModalityDropDownV2_senseT1.Value = 'None';
            app.SensoryTargetDropDownLabel_senseT1 = uilabel(app.sensePanel);
            app.SensoryTargetDropDownLabel_senseT1.Text = 'Target';
            app.SensoryTargetDropDownV2_senseT1 = uidropdown(app.sensePanel);
            app.SensoryTargetDropDownV2_senseT1.Items = {'None', 'Whole whisker pad', '+'};
            app.SensoryTargetDropDownV2_senseT1.Value = 'None';
            app.ApparatusDropDownLabel_senseT1 = uilabel(app.sensePanel);
            app.ApparatusDropDownLabel_senseT1.Text = 'Device';
            app.ApparatusDropDownV2_senseT1 = uidropdown(app.sensePanel);
            app.ApparatusDropDownV2_senseT1.Items = {'None', 'Piezo_v1', 'VonFrey', '+'};
            app.ApparatusDropDownV2_senseT1.Value = 'None';
            app.TabNumLabel_sense = uilabel(app.sensePanel);
            app.TabNumLabel_sense.Text = '1';
            app.CalibrationButton_senseT1 = uibutton(app.sensePanel, 'state');
            app.CalibrationButton_senseT1.ValueChangedFcn = createCallbackFcn(app, @CalibrateSenseApparatus, true);
            app.CalibrationButton_senseT1.Tag = 'CalibrationButton_senseT1';
            app.CalibrationButton_senseT1.Text = 'Calibrate';
            app.Tab_senseT1 = uibutton(app.sensePanel, 'push');
            app.Tab_senseT1.Text = '';
            app.RemoveTab_sense = uibutton(app.sensePanel, 'push');
            app.RemoveTab_sense.Text = '-';
            app.AddTab_sense = uibutton(app.sensePanel, 'push');
            app.AddTab_sense.Text = '+';
            app.SensoryTargetSideDropDownLabel_senseT1 = uilabel(app.sensePanel);
            app.SensoryTargetSideDropDownLabel_senseT1.Text = 'L/R';
            app.SensoryTargetSideDropDown_senseT1 = uidropdown(app.sensePanel);
            app.SensoryTargetSideDropDown_senseT1.Items = {'None', 'L', 'R', 'BL', 'Mid', 'NA', '+'};
            app.SensoryTargetSideDropDown_senseT1.Value = 'None';
            app.senseParameterTableButton_senseT1 = uibutton(app.sensePanel, 'state');
            app.senseParameterTableButton_senseT1.Tag = 'SpinParamsParameterTable_cameraT1';
            app.senseParameterTableButton_senseT1.Icon = fullfile(pathToMLAPP, 'Icons', 'paramIcon2.png');
            app.senseParameterTableButton_senseT1.Text = '';
            app.photomPanel = uipanel(app.ModalityPanel);
            app.photomPanel.Title = 'Photometry';
            app.photomPanel.Visible = 'off';
            app.NeuralTargetDropDownLabel_photomT1 = uilabel(app.photomPanel);
            app.NeuralTargetDropDownLabel_photomT1.Text = 'Target';
            app.NeuralTargetDropDownV2_photomT1 = uidropdown(app.photomPanel);
            app.NeuralTargetDropDownV2_photomT1.Items = {'None', '+'};
            app.NeuralTargetDropDownV2_photomT1.ValueChangedFcn = createCallbackFcn(app, @NeuralTargetDropDownV2_photomT1ValueChanged, true);
            app.NeuralTargetDropDownV2_photomT1.Value = 'None';
            app.NeuralHardwareDropDownLabel_photomT1 = uilabel(app.photomPanel);
            app.NeuralHardwareDropDownLabel_photomT1.Text = 'Neural HW';
            app.NeuralHardwareDropDownV2_photomT1 = uidropdown(app.photomPanel);
            app.NeuralHardwareDropDownV2_photomT1.Items = {'None', 'Ø200 µm Core, 0.39 NA', 'Ø200 µm Core, 0.50 NA', '+'};
            app.NeuralHardwareDropDownV2_photomT1.Value = 'None';
            app.TabNumLabel_photom = uilabel(app.photomPanel);
            app.TabNumLabel_photom.Text = '1';
            app.NeuralTargetSideDropDownLabel_photomT1 = uilabel(app.photomPanel);
            app.NeuralTargetSideDropDownLabel_photomT1.Text = 'L/R';
            app.NeuralTargetSideDropDown_photomT1 = uidropdown(app.photomPanel);
            app.NeuralTargetSideDropDown_photomT1.Items = {'None', 'L', 'R', '+'};
            app.NeuralTargetSideDropDown_photomT1.Value = 'None';
            app.Tab_photomT1 = uibutton(app.photomPanel, 'push');
            app.Tab_photomT1.Text = '';
            app.AddTab_photom = uibutton(app.photomPanel, 'push');
            app.AddTab_photom.Text = '+';
            app.RemoveTab_photom = uibutton(app.photomPanel, 'push');
            app.RemoveTab_photom.Text = '-';
            app.channelPanel_photomT1 = uipanel(app.photomPanel);
            app.channelPanel_photomT1.Title = 'Panel2';
            app.channelPanel_photomT1.Tag = 'channelPanel_photomT1';
            app.SensorDropDownLabel_photomT1_chan1 = uilabel(app.channelPanel_photomT1);
            app.SensorDropDownLabel_photomT1_chan1.Text = 'Fluorophore';
            app.SensorDropDownV2_photomT1_chan1 = uidropdown(app.channelPanel_photomT1);
            app.SensorDropDownV2_photomT1_chan1.Items = {'None', 'Isosbestic', 'rcNE1-1', 'jGCaMP8m', 'jRGECO1a', 'SR101', 'mCherry', 'tdTomato', 'FITC', 'eGFP', 'Methoxy-X04', 'GCaMP6s', '+'};
            app.SensorDropDownV2_photomT1_chan1.Enable = 'off';
            app.SensorDropDownV2_photomT1_chan1.Value = 'None';
            app.ExcitWavelengthDropDownLabel_photomT1_chan1 = uilabel(app.channelPanel_photomT1);
            app.ExcitWavelengthDropDownLabel_photomT1_chan1.Text = 'Ex. Wavelength';
            app.ExcitWavelengthDropDownV2_photomT1_chan1 = uidropdown(app.channelPanel_photomT1);
            app.ExcitWavelengthDropDownV2_photomT1_chan1.Items = {'None', '560nm', '470nm', '405nm', '+'};
            app.ExcitWavelengthDropDownV2_photomT1_chan1.Enable = 'off';
            app.ExcitWavelengthDropDownV2_photomT1_chan1.Value = 'None';
            app.PowerNumericEntry_photomT1_chan1 = uieditfield(app.channelPanel_photomT1, 'numeric');
            app.PowerNumericEntry_photomT1_chan1.Enable = 'off';
            app.PowerNumericEntryLabel_photomT1_chan1 = uilabel(app.channelPanel_photomT1);
            app.PowerNumericEntryLabel_photomT1_chan1.Enable = 'off';
            app.PowerNumericEntryLabel_photomT1_chan1.Text = 'uW';
            app.PowerLabel_photomT1_chan1 = uilabel(app.channelPanel_photomT1);
            app.PowerLabel_photomT1_chan1.Text = 'Power';
            app.VectorDropDownLabel_photomT1_chan1 = uilabel(app.channelPanel_photomT1);
            app.VectorDropDownLabel_photomT1_chan1.Text = 'Vector 1';
            app.VectorDropDownV2_photomT1_chan1 = uidropdown(app.channelPanel_photomT1);
            app.VectorDropDownV2_photomT1_chan1.Items = {'None', 'AAV9-hsyn-rNE1.1', 'AAV9-hsyn-r5HT2.0', 'AAV9-syn-Flex-jGCaMP8m-WPRE', '+'};
            app.VectorDropDownV2_photomT1_chan1.ValueChangedFcn = createCallbackFcn(app, @VectorLabel, true);
            app.VectorDropDownV2_photomT1_chan1.Enable = 'off';
            app.VectorDropDownV2_photomT1_chan1.Placeholder = 'Vector 1';
            app.VectorDropDownV2_photomT1_chan1.Value = 'None';
            app.photomChannelsLabel_photomT1_chan = uilabel(app.channelPanel_photomT1);
            app.photomChannelsLabel_photomT1_chan.Text = 'Channel';
            app.chanNumLabel_photomT1_chan = uilabel(app.channelPanel_photomT1);
            app.chanNumLabel_photomT1_chan.Text = '1';
            app.Subtab_photomT1_chan4 = uibutton(app.channelPanel_photomT1, 'push');
            app.Subtab_photomT1_chan4.ButtonPushedFcn = createCallbackFcn(app, @chanButtonPushed, true);
            app.Subtab_photomT1_chan4.Tag = 'Subtab_photomT1_chan4';
            app.Subtab_photomT1_chan4.Text = '';
            app.Subtab_photomT1_chan3 = uibutton(app.channelPanel_photomT1, 'push');
            app.Subtab_photomT1_chan3.ButtonPushedFcn = createCallbackFcn(app, @chanButtonPushed, true);
            app.Subtab_photomT1_chan3.Tag = 'Subtab_photomT1_chan3';
            app.Subtab_photomT1_chan3.Text = '';
            app.Subtab_photomT1_chan2 = uibutton(app.channelPanel_photomT1, 'push');
            app.Subtab_photomT1_chan2.ButtonPushedFcn = createCallbackFcn(app, @chanButtonPushed, true);
            app.Subtab_photomT1_chan2.Tag = 'Subtab_photomT1_chan2';
            app.Subtab_photomT1_chan2.Text = '';
            app.Subtab_photomT1_chan1 = uibutton(app.channelPanel_photomT1, 'push');
            app.Subtab_photomT1_chan1.ButtonPushedFcn = createCallbackFcn(app, @chanButtonPushed, true);
            app.Subtab_photomT1_chan1.Tag = 'Subtab_photomT1_chan1';
            app.Subtab_photomT1_chan1.Text = '';
            app.photomParameterTableButton_photomT1 = uibutton(app.photomPanel, 'state');
            app.photomParameterTableButton_photomT1.Tag = 'SpinParamsParameterTable_cameraT1';
            app.photomParameterTableButton_photomT1.Icon = fullfile(pathToMLAPP, 'Icons', 'paramIcon2.png');
            app.photomParameterTableButton_photomT1.Text = '';
            app.cameraPanel = uipanel(app.ModalityPanel);
            app.cameraPanel.Title = 'Camera';
            app.cameraPanel.Visible = 'off';
            app.CameraProgressBar_cameraT1 = uiaxes(app.cameraPanel);
            title(app.CameraProgressBar_cameraT1, 'Title')
            xlabel(app.CameraProgressBar_cameraT1, 'X')
            ylabel(app.CameraProgressBar_cameraT1, 'Y')
            zlabel(app.CameraProgressBar_cameraT1, 'Z')
            app.CameraProgressBar_cameraT1.XTick = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.CameraProgressBar_cameraT1.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.SpinviewButton_camera = uibutton(app.cameraPanel, 'push');
            app.SpinviewButton_camera.ButtonPushedFcn = createCallbackFcn(app, @SpinviewButton_cameraPushed, true);
            app.SpinviewButton_camera.Text = 'Launch Spinview';
            app.TabNumLabel_camera = uilabel(app.cameraPanel);
            app.TabNumLabel_camera.Text = '1';
            app.SpinParamsParameterTableButton_cameraT1 = uibutton(app.cameraPanel, 'state');
            app.SpinParamsParameterTableButton_cameraT1.Tag = 'SpinParamsParameterTable_cameraT1';
            app.SpinParamsParameterTableButton_cameraT1.Icon = fullfile(pathToMLAPP, 'Icons', 'paramIcon2.png');
            app.SpinParamsParameterTableButton_cameraT1.Text = '';
            app.SerialNumberDropDownLabel_cameraT1 = uilabel(app.cameraPanel);
            app.SerialNumberDropDownLabel_cameraT1.Text = 'S/N';
            app.CameraTargetDropDownLabel_cameraT1 = uilabel(app.cameraPanel);
            app.CameraTargetDropDownLabel_cameraT1.Text = 'Target';
            app.CameraTargetDropDownV2_cameraT1 = uidropdown(app.cameraPanel);
            app.CameraTargetDropDownV2_cameraT1.Items = {'None', 'Pupil_close_view', 'Whisker_top_view', '+'};
            app.CameraTargetDropDownV2_cameraT1.Value = 'None';
            app.SerialNumberDropDownV2_cameraT1 = uidropdown(app.cameraPanel);
            app.SerialNumberDropDownV2_cameraT1.Items = {'None', '+'};
            app.SerialNumberDropDownV2_cameraT1.ValueChangedFcn = createCallbackFcn(app, @SerialNumberDropDownV2_cameraValueChanged, true);
            app.SerialNumberDropDownV2_cameraT1.Tag = 'SerialNumberDropDownV2_cameraT1';
            app.SerialNumberDropDownV2_cameraT1.Value = 'None';
            app.AddTab_camera = uibutton(app.cameraPanel, 'push');
            app.AddTab_camera.Text = '+';
            app.RemoveTab_camera = uibutton(app.cameraPanel, 'push');
            app.RemoveTab_camera.Text = '-';
            app.Tab_cameraT1 = uibutton(app.cameraPanel, 'push');
            app.Tab_cameraT1.Text = '';
            app.NeuralNetDropDownLabel_cameraT1 = uilabel(app.cameraPanel);
            app.NeuralNetDropDownLabel_cameraT1.Text = 'Neural Net';
            app.NeuralNetworkDropDownV2_cameraT1 = uidropdown(app.cameraPanel);
            app.NeuralNetworkDropDownV2_cameraT1.Items = {'None', '+'};
            app.NeuralNetworkDropDownV2_cameraT1.Value = 'None';
            app.frameRate_cameraT1 = uilabel(app.cameraPanel);
            app.frameRate_cameraT1.Text = 'Camera frame rate:';
            app.FSNumericEntry_cameraT1 = uieditfield(app.cameraPanel, 'numeric');
            app.FSNumericEntry_cameraT1.ValueChangedFcn = createCallbackFcn(app, @FSNumericEntry_cameraValueChanged, true);
            app.FSNumericEntry_cameraT1.Tag = 'FSNumericEntry_cameraT1';
            app.FSNumericEntry_cameraT1.Value = 10;
            app.FSNumericEntryLabel_cameraT1 = uilabel(app.cameraPanel);
            app.FSNumericEntryLabel_cameraT1.Text = '/ sec';
            app.npxlsPanel = uipanel(app.ModalityPanel);
            app.npxlsPanel.Title = 'Neuropixels';
            app.npxlsPanel.Visible = 'off';
            app.ProbeSideDropDownLabel_npxlsT1 = uilabel(app.npxlsPanel);
            app.ProbeSideDropDownLabel_npxlsT1.Text = 'Probe side';
            app.ProbeSideDropDownV2_npxlsT1 = uidropdown(app.npxlsPanel);
            app.ProbeSideDropDownV2_npxlsT1.Items = {'None', 'L', 'R'};
            app.ProbeSideDropDownV2_npxlsT1.Value = 'None';
            app.ProbeVersionDropDownLabel_npxlsT1 = uilabel(app.npxlsPanel);
            app.ProbeVersionDropDownLabel_npxlsT1.Text = 'Probe version';
            app.ProbeVersionDropDownV2_npxlsT1 = uidropdown(app.npxlsPanel);
            app.ProbeVersionDropDownV2_npxlsT1.Items = {'None', '1.0', '2.0'};
            app.ProbeVersionDropDownV2_npxlsT1.Value = 'None';
            app.ProbeSNDropDownLabel_npxlsT1 = uilabel(app.npxlsPanel);
            app.ProbeSNDropDownLabel_npxlsT1.Text = 'Probe S/N:';
            app.ProbeSNDropDownV2_npxlsT1 = uidropdown(app.npxlsPanel);
            app.ProbeSNDropDownV2_npxlsT1.Items = {'None', '19454400001', '19454400002', '19454420782', '19454420722'};
            app.ProbeSNDropDownV2_npxlsT1.Value = 'None';
            app.HeadstageSNDropDownLabel_npxlsT1 = uilabel(app.npxlsPanel);
            app.HeadstageSNDropDownLabel_npxlsT1.Text = 'Headstage S/N';
            app.HeadstageSNDropDownV2_npxlsT1 = uidropdown(app.npxlsPanel);
            app.HeadstageSNDropDownV2_npxlsT1.Items = {'None', 'NP2_HS-21460173', 'NP2_HS-21460178', 'NP2_HS-20211759'};
            app.HeadstageSNDropDownV2_npxlsT1.Value = 'None';
            app.ProbeShankRegionsLabel_npxlsT1 = uilabel(app.npxlsPanel);
            app.ProbeShankRegionsLabel_npxlsT1.Text = '--';
            app.TabNumLabel_npxls = uilabel(app.npxlsPanel);
            app.TabNumLabel_npxls.Text = '1';
            app.ProbeAreasSearchButton_npxlsT1 = uibutton(app.npxlsPanel, 'push');
            app.ProbeAreasSearchButton_npxlsT1.ButtonPushedFcn = createCallbackFcn(app, @exploreProbeAreaFiles, true);
            app.ProbeAreasSearchButton_npxlsT1.Tag = 'ProbeAreasSearchButton_npxlsT1';
            app.ProbeAreasSearchButton_npxlsT1.Text = '...';
            app.ProbeAreasDisplayButton_npxlsT1 = uiimage(app.npxlsPanel);
            app.ProbeAreasDisplayButton_npxlsT1.ImageClickedFcn = createCallbackFcn(app, @displayProbeArea, true);
            app.ProbeAreasDisplayButton_npxlsT1.Tag = 'ProbeAreasDisplayButton_npxlsT1';
            app.ProbeAreasDisplayButton_npxlsT1.AltText = '0';
            app.ProbeAreasDisplayButton_npxlsT1.ImageSource = fullfile(pathToMLAPP, 'Icons', 'emptyFrame.PNG');
            app.Tab_npxlsT1 = uibutton(app.npxlsPanel, 'push');
            app.Tab_npxlsT1.ButtonPushedFcn = createCallbackFcn(app, @Tab_npxlsT1ButtonPushed, true);
            app.Tab_npxlsT1.Text = '';
            app.AddTab_npxls = uibutton(app.npxlsPanel, 'push');
            app.AddTab_npxls.Text = '+';
            app.RemoveTab_npxls = uibutton(app.npxlsPanel, 'push');
            app.RemoveTab_npxls.Text = '-';
            app.npxlsParameterTableButton_npxlsT1 = uibutton(app.npxlsPanel, 'state');
            app.npxlsParameterTableButton_npxlsT1.Tag = 'SpinParamsParameterTable_cameraT1';
            app.npxlsParameterTableButton_npxlsT1.Icon = fullfile(pathToMLAPP, 'Icons', 'paramIcon2.png');
            app.npxlsParameterTableButton_npxlsT1.Text = '';
            app.photonPanel = uipanel(app.ModalityPanel);
            app.photonPanel.Title = 'Multi Photon';
            app.photonPanel.Visible = 'off';
            app.TabNumLabel_photon = uilabel(app.photonPanel);
            app.TabNumLabel_photon.Text = '1';
            app.Tab_photonT1 = uibutton(app.photonPanel, 'push');
            app.Tab_photonT1.Text = '';
            app.AddTab_photon = uibutton(app.photonPanel, 'push');
            app.AddTab_photon.Text = '+';
            app.RemoveTab_photon = uibutton(app.photonPanel, 'push');
            app.RemoveTab_photon.Text = '-';
            app.channelPanel_photonT1 = uipanel(app.photonPanel);
            app.channelPanel_photonT1.Title = 'Panel2';
            app.channelPanel_photonT1.Tag = 'channelPanel_photomT1';
            app.SensorDropDownLabel_photonT1_chan1 = uilabel(app.channelPanel_photonT1);
            app.SensorDropDownLabel_photonT1_chan1.Text = 'Fluorophore';
            app.SensorDropDownV2_photonT1_chan1 = uidropdown(app.channelPanel_photonT1);
            app.SensorDropDownV2_photonT1_chan1.Items = {'None', 'Isosbestic', 'rcNE1-1', 'jGCaMP8m', 'jRGECO1a', 'SR101', 'mCherry', 'tdTomato', 'FITC', 'eGFP', 'Methoxy-X04', 'GCaMP6s', '+'};
            app.SensorDropDownV2_photonT1_chan1.Enable = 'off';
            app.SensorDropDownV2_photonT1_chan1.Value = 'None';
            app.ExcitWavelengthDropDownLabel_photonT1_chan1 = uilabel(app.channelPanel_photonT1);
            app.ExcitWavelengthDropDownLabel_photonT1_chan1.Text = 'Ex. Wavelength';
            app.ExcitWavelengthDropDownV2_photonT1_chan1 = uidropdown(app.channelPanel_photonT1);
            app.ExcitWavelengthDropDownV2_photonT1_chan1.Items = {'None', '560nm', '470nm', '405nm', '+'};
            app.ExcitWavelengthDropDownV2_photonT1_chan1.Enable = 'off';
            app.ExcitWavelengthDropDownV2_photonT1_chan1.Value = 'None';
            app.PowerNumericEntry_photonT1_chan1 = uieditfield(app.channelPanel_photonT1, 'numeric');
            app.PowerNumericEntry_photonT1_chan1.Enable = 'off';
            app.PowerNumericEntryLabel_photonT1_chan1 = uilabel(app.channelPanel_photonT1);
            app.PowerNumericEntryLabel_photonT1_chan1.Enable = 'off';
            app.PowerNumericEntryLabel_photonT1_chan1.Text = 'uW';
            app.PowerLabel_photonT1_chan1 = uilabel(app.channelPanel_photonT1);
            app.PowerLabel_photonT1_chan1.Text = 'Power';
            app.VectorDropDownLabel_photonT1_chan1 = uilabel(app.channelPanel_photonT1);
            app.VectorDropDownLabel_photonT1_chan1.Text = 'Vector 1';
            app.VectorDropDownV2_photonT1_chan1 = uidropdown(app.channelPanel_photonT1);
            app.VectorDropDownV2_photonT1_chan1.Items = {'None', 'AAV9-hsyn-rNE1.1', 'AAV9-hsyn-r5HT2.0', 'AAV9-syn-Flex-jGCaMP8m-WPRE', '+'};
            app.VectorDropDownV2_photonT1_chan1.Enable = 'off';
            app.VectorDropDownV2_photonT1_chan1.Placeholder = 'Vector 1';
            app.VectorDropDownV2_photonT1_chan1.Value = 'None';
            app.photonChannelsLabel_photonT1_chan = uilabel(app.channelPanel_photonT1);
            app.photonChannelsLabel_photonT1_chan.Text = 'Channel';
            app.chanNumLabel_photonT1_chan = uilabel(app.channelPanel_photonT1);
            app.chanNumLabel_photonT1_chan.Text = '1';
            app.Subtab_photonT1_chan4 = uibutton(app.channelPanel_photonT1, 'push');
            app.Subtab_photonT1_chan4.ButtonPushedFcn = createCallbackFcn(app, @chanButtonPushed, true);
            app.Subtab_photonT1_chan4.Tag = 'Subtab_photonT1_chan4';
            app.Subtab_photonT1_chan4.Text = '';
            app.Subtab_photonT1_chan3 = uibutton(app.channelPanel_photonT1, 'push');
            app.Subtab_photonT1_chan3.ButtonPushedFcn = createCallbackFcn(app, @chanButtonPushed, true);
            app.Subtab_photonT1_chan3.Tag = 'Subtab_photonT1_chan3';
            app.Subtab_photonT1_chan3.Text = '';
            app.Subtab_photonT1_chan2 = uibutton(app.channelPanel_photonT1, 'push');
            app.Subtab_photonT1_chan2.ButtonPushedFcn = createCallbackFcn(app, @chanButtonPushed, true);
            app.Subtab_photonT1_chan2.Tag = 'Subtab_photonT1_chan2';
            app.Subtab_photonT1_chan2.Text = '';
            app.Subtab_photonT1_chan1 = uibutton(app.channelPanel_photonT1, 'push');
            app.Subtab_photonT1_chan1.ButtonPushedFcn = createCallbackFcn(app, @chanButtonPushed, true);
            app.Subtab_photonT1_chan1.Tag = 'Subtab_photonT1_chan1';
            app.Subtab_photonT1_chan1.Text = '';
            app.NeuralTargetSideDropDownLabel_photonT1 = uilabel(app.photonPanel);
            app.NeuralTargetSideDropDownLabel_photonT1.Text = 'L/R';
            app.NeuralTargetSideDropDown_photonT1 = uidropdown(app.photonPanel);
            app.NeuralTargetSideDropDown_photonT1.Items = {'None', 'L', 'R', '+'};
            app.NeuralTargetSideDropDown_photonT1.Value = 'None';
            app.NeuralTargetDropDownLabel_photonT1 = uilabel(app.photonPanel);
            app.NeuralTargetDropDownLabel_photonT1.Text = 'Target';
            app.NeuralTargetDropDownV2_photonT1 = uidropdown(app.photonPanel);
            app.NeuralTargetDropDownV2_photonT1.Items = {'None', '+'};
            app.NeuralTargetDropDownV2_photonT1.Value = 'None';
            app.NeuralHardwareDropDownLabel_photonT1 = uilabel(app.photonPanel);
            app.NeuralHardwareDropDownLabel_photonT1.Text = 'Neural HW';
            app.NeuralHardwareDropDownV2_photonT1 = uidropdown(app.photonPanel);
            app.NeuralHardwareDropDownV2_photonT1.Items = {'None', 'Ø200 µm Core, 0.39 NA', 'Ø200 µm Core, 0.50 NA', '+'};
            app.NeuralHardwareDropDownV2_photonT1.Value = 'None';
            app.photonParameterTableButton_photomT1 = uibutton(app.photonPanel, 'state');
            app.photonParameterTableButton_photomT1.Tag = 'SpinParamsParameterTable_cameraT1';
            app.photonParameterTableButton_photomT1.Icon = fullfile(pathToMLAPP, 'Icons', 'paramIcon2.png');
            app.photonParameterTableButton_photomT1.Text = '';
            app.optoPanel = uipanel(app.ModalityPanel);
            app.optoPanel.Title = 'Optogenetics';
            app.optoPanel.Visible = 'off';
            app.NeuralTargetDropDownLabel_optoT1 = uilabel(app.optoPanel);
            app.NeuralTargetDropDownLabel_optoT1.Text = 'Target';
            app.NeuralTargetDropDownV2_optoT1 = uidropdown(app.optoPanel);
            app.NeuralTargetDropDownV2_optoT1.Items = {'None', '+'};
            app.NeuralTargetDropDownV2_optoT1.Value = 'None';
            app.OpsinDropDownLabel_optoT1 = uilabel(app.optoPanel);
            app.OpsinDropDownLabel_optoT1.Text = 'Opsin';
            app.OpsinDropDownV2_optoT1 = uidropdown(app.optoPanel);
            app.OpsinDropDownV2_optoT1.Items = {'None', 'H134R', '+'};
            app.OpsinDropDownV2_optoT1.Value = 'None';
            app.LaserWavelengthDropDownLabel_optoT1 = uilabel(app.optoPanel);
            app.LaserWavelengthDropDownLabel_optoT1.Text = 'Excitation Wvlength';
            app.LaserWavelengthDropDownV2_optoT1 = uidropdown(app.optoPanel);
            app.LaserWavelengthDropDownV2_optoT1.Items = {'None', '470nm', '+'};
            app.LaserWavelengthDropDownV2_optoT1.Value = 'None';
            app.TabNumLabel_opto = uilabel(app.optoPanel);
            app.TabNumLabel_opto.Text = '1';
            app.NeuralTargetSideDropDownLabel_optoT1 = uilabel(app.optoPanel);
            app.NeuralTargetSideDropDownLabel_optoT1.Text = 'L/R';
            app.NeuralTargetSideDropDown_optoT1 = uidropdown(app.optoPanel);
            app.NeuralTargetSideDropDown_optoT1.Items = {'None', 'L', 'R', '+'};
            app.NeuralTargetSideDropDown_optoT1.Value = 'None';
            app.Tab_optoT1 = uibutton(app.optoPanel, 'push');
            app.Tab_optoT1.Text = '';
            app.AddTab_opto = uibutton(app.optoPanel, 'push');
            app.AddTab_opto.Text = '+';
            app.RemoveTab_opto = uibutton(app.optoPanel, 'push');
            app.RemoveTab_opto.Text = '-';
            app.optoParameterTableButton_optoT1 = uibutton(app.optoPanel, 'state');
            app.optoParameterTableButton_optoT1.ValueChangedFcn = createCallbackFcn(app, @ParameterTableButtonValueChanged, true);
            app.optoParameterTableButton_optoT1.Tag = 'optoParamsParameterTable_optoT1';
            app.optoParameterTableButton_optoT1.Icon = fullfile(pathToMLAPP, 'Icons', 'paramIcon2.png');
            app.optoParameterTableButton_optoT1.Text = '';
            app.SetStimulationParams_optoT1 = uibutton(app.optoPanel, 'push');
            app.SetStimulationParams_optoT1.Text = 'Stimulation Parameters';
            app.PowerNumericEntry_optoT1 = uieditfield(app.optoPanel, 'numeric');
            app.PowerNumericEntry_optoT1.Enable = 'off';
            app.PowerNumericEntryLabel_optoT1 = uilabel(app.optoPanel);
            app.PowerNumericEntryLabel_optoT1.Enable = 'off';
            app.PowerNumericEntryLabel_optoT1.Text = 'uW';
            app.PowerLabel_optoT1 = uilabel(app.optoPanel);
            app.PowerLabel_optoT1.Text = 'Power';
            app.rxPanel = uipanel(app.ModalityPanel);
            app.rxPanel.Title = 'Rx';
            app.rxPanel.Visible = 'off';
            app.RxDrugDropDownLabel_rxT1 = uilabel(app.rxPanel);
            app.RxDrugDropDownLabel_rxT1.Text = 'Drug';
            app.RxDrugDropDownV2_rxT1 = uidropdown(app.rxPanel);
            app.RxDrugDropDownV2_rxT1.Items = {'None', 'Gq-DREADD', '+'};
            app.RxDrugDropDownV2_rxT1.ValueChangedFcn = createCallbackFcn(app, @RxDrugDropDownV2_rxT1ValueChanged, true);
            app.RxDrugDropDownV2_rxT1.Value = 'None';
            app.RxDeliveryMethodDropDownLabel_rxT1 = uilabel(app.rxPanel);
            app.RxDeliveryMethodDropDownLabel_rxT1.Text = 'Deliv. Method';
            app.RxDeliveryMethodDropDownV2_rxT1 = uidropdown(app.rxPanel);
            app.RxDeliveryMethodDropDownV2_rxT1.Items = {'None', 'CNO', '+'};
            app.RxDeliveryMethodDropDownV2_rxT1.ValueChangedFcn = createCallbackFcn(app, @RxDeliveryMethodDropDownV2_rxT1ValueChanged, true);
            app.RxDeliveryMethodDropDownV2_rxT1.Value = 'None';
            app.RxTimer_rxT1 = uibutton(app.rxPanel, 'state');
            app.RxTimer_rxT1.Text = 'Rx';
            app.RxDoseDropDownLabel_rxT1 = uilabel(app.rxPanel);
            app.RxDoseDropDownLabel_rxT1.Text = 'Dose';
            app.RxDoseDropDownV2_rxT1 = uidropdown(app.rxPanel);
            app.RxDoseDropDownV2_rxT1.Items = {'None', 'CNO', '+'};
            app.RxDoseDropDownV2_rxT1.Value = 'None';
            app.TabNumLabel_rx = uilabel(app.rxPanel);
            app.TabNumLabel_rx.Text = '1';
            app.Tab_rxT1 = uibutton(app.rxPanel, 'push');
            app.Tab_rxT1.Text = '';
            app.AddTab_rx = uibutton(app.rxPanel, 'push');
            app.AddTab_rx.Text = '+';
            app.RemoveTab_rx = uibutton(app.rxPanel, 'push');
            app.RemoveTab_rx.Text = '-';
            app.rxParameterTableButton_rxT1 = uibutton(app.rxPanel, 'state');
            app.rxParameterTableButton_rxT1.Tag = 'optoParamsParameterTable_optoT1';
            app.rxParameterTableButton_rxT1.Icon = fullfile(pathToMLAPP, 'Icons', 'paramIcon2.png');
            app.rxParameterTableButton_rxT1.Text = '';
            app.gxPanel = uipanel(app.ModalityPanel);
            app.gxPanel.Title = 'Molecular';
            app.gxPanel.Visible = 'off';
            app.NeuralTargetDropDownLabel_gxT1 = uilabel(app.gxPanel);
            app.NeuralTargetDropDownLabel_gxT1.Text = 'Target';
            app.NeuralTargetDropDownV2_gxT1 = uidropdown(app.gxPanel);
            app.NeuralTargetDropDownV2_gxT1.Items = {'None', '+'};
            app.NeuralTargetDropDownV2_gxT1.ValueChangedFcn = createCallbackFcn(app, @NeuralTargetDropDownV2_gxT1ValueChanged, true);
            app.NeuralTargetDropDownV2_gxT1.Value = 'None';
            app.GeneEditingDropDownLabel_gxT1 = uilabel(app.gxPanel);
            app.GeneEditingDropDownLabel_gxT1.Text = 'Substrate';
            app.GeneEditingDropDownV2_gxT1 = uidropdown(app.gxPanel);
            app.GeneEditingDropDownV2_gxT1.Items = {'None', '+'};
            app.GeneEditingDropDownV2_gxT1.ValueChangedFcn = createCallbackFcn(app, @GeneEditingDropDownV2_gxT1ValueChanged, true);
            app.GeneEditingDropDownV2_gxT1.Value = 'None';
            app.RNASensoryDropDownLabel_gxT1 = uilabel(app.gxPanel);
            app.RNASensoryDropDownLabel_gxT1.Text = 'Vector';
            app.RNASensoryDropDownV2_gxT1 = uidropdown(app.gxPanel);
            app.RNASensoryDropDownV2_gxT1.Items = {'None', '+'};
            app.RNASensoryDropDownV2_gxT1.ValueChangedFcn = createCallbackFcn(app, @RNASensoryDropDownV2_gxT1ValueChanged, true);
            app.RNASensoryDropDownV2_gxT1.Value = 'None';
            app.ManipulationDropDownLabel_gxT1 = uilabel(app.gxPanel);
            app.ManipulationDropDownLabel_gxT1.Text = 'Manipulation';
            app.ManipulationDropDownV2_gxT1 = uidropdown(app.gxPanel);
            app.ManipulationDropDownV2_gxT1.Items = {'None', '+'};
            app.ManipulationDropDownV2_gxT1.Value = 'None';
            app.TabNumLabel_gx = uilabel(app.gxPanel);
            app.TabNumLabel_gx.Text = '1';
            app.NeuralTargetSideDropDownLabel_gxT1 = uilabel(app.gxPanel);
            app.NeuralTargetSideDropDownLabel_gxT1.Text = 'L/R';
            app.NeuralTargetSideDropDown_gxT1 = uidropdown(app.gxPanel);
            app.NeuralTargetSideDropDown_gxT1.Items = {'None', 'L', 'R', '+'};
            app.NeuralTargetSideDropDown_gxT1.Value = 'None';
            app.Tab_gxT1 = uibutton(app.gxPanel, 'push');
            app.Tab_gxT1.Text = '';
            app.AddTab_gx = uibutton(app.gxPanel, 'push');
            app.AddTab_gx.Text = '+';
            app.RemoveTab_gx = uibutton(app.gxPanel, 'push');
            app.RemoveTab_gx.Text = '-';
            app.gxParameterTableButton_gxT1 = uibutton(app.gxPanel, 'state');
            app.gxParameterTableButton_gxT1.Tag = 'optoParamsParameterTable_optoT1';
            app.gxParameterTableButton_gxT1.Icon = fullfile(pathToMLAPP, 'Icons', 'paramIcon2.png');
            app.gxParameterTableButton_gxT1.Text = '';
            app.SubjectStatusPanel = uipanel(app.UIFigure);
            app.SubjectStatusPanel.Title = 'Subject Status';
            app.SubjectStatusPanel.Visible = 'off';
            app.DateofBirthLabel = uilabel(app.SubjectStatusPanel);
            app.DateofBirthLabel.Text = 'DoB:';
            app.WeightEditFieldLabel_subj = uilabel(app.SubjectStatusPanel);
            app.WeightEditFieldLabel_subj.Text = 'Weight (g):';
            app.WeightEditField_subj = uieditfield(app.SubjectStatusPanel, 'numeric');
            app.surgPanel = uipanel(app.SubjectStatusPanel);
            app.surgPanel.Title = 'Surgery';
            app.ProcedureDropDownLabel_surgT1 = uilabel(app.surgPanel);
            app.ProcedureDropDownLabel_surgT1.Text = 'Procedure:';
            app.ProcedureDropDownV2_surgT1 = uidropdown(app.surgPanel);
            app.ProcedureDropDownV2_surgT1.Items = {'None', 'Virus injection', 'Optical fiber implant', 'Neuropixel implant', 'Cranial window implant', 'Headplate implant', 'Virus injection + optical fiber implant', 'Virus injectin + cranial window', 'Sciatic nerve injury (R hind limb)', 'Sciatic nerve injury (L hind limb)', '+'};
            app.ProcedureDropDownV2_surgT1.Placeholder = 'Select a surgical procedure';
            app.ProcedureDropDownV2_surgT1.Value = 'None';
            app.AddTab_surg = uibutton(app.surgPanel, 'push');
            app.AddTab_surg.Text = '+';
            app.TabNumLabel_surg = uilabel(app.surgPanel);
            app.TabNumLabel_surg.Text = '1';
            app.SurgeryDateDatePickerLabel_surgT1 = uilabel(app.surgPanel);
            app.SurgeryDateDatePickerLabel_surgT1.Text = 'Procedure date:';
            app.SurgeryDateDatePicker_surgT1 = uidatepicker(app.surgPanel);
            app.SurgeryDateDatePicker_surgT1.DisplayFormat = 'uuuu-MM-dd';
            app.Tab_surgT1 = uibutton(app.surgPanel, 'push');
            app.Tab_surgT1.Text = '';
            app.RemoveTab_surg = uibutton(app.surgPanel, 'push');
            app.RemoveTab_surg.Text = '-';
            app.genoPanel = uipanel(app.SubjectStatusPanel);
            app.genoPanel.Title = 'Genotype';
            app.AddTab_geno = uibutton(app.genoPanel, 'push');
            app.AddTab_geno.Text = '+';
            app.Tab_genoT1 = uibutton(app.genoPanel, 'push');
            app.Tab_genoT1.Text = '';
            app.AlleleDropDownLabel_genoT1 = uilabel(app.genoPanel);
            app.AlleleDropDownLabel_genoT1.Text = '( ? / ? )';
            app.AlleleDropDown_genoT1 = uidropdown(app.genoPanel);
            app.AlleleDropDown_genoT1.Items = {'(+/+)', '(+/-)', '(WT)'};
            app.AlleleDropDown_genoT1.Placeholder = '(?/?)';
            app.AlleleDropDown_genoT1.Value = '(+/+)';
            app.TabNumLabel_geno = uilabel(app.genoPanel);
            app.TabNumLabel_geno.Text = '1';
            app.GenotypeDropDownLabel_genoT1 = uilabel(app.genoPanel);
            app.GenotypeDropDownLabel_genoT1.Text = 'Gene:';
            app.GenotypeDropDownV2_genoT1 = uidropdown(app.genoPanel);
            app.GenotypeDropDownV2_genoT1.Items = {'None', 'Wt', 'Ai32', 'Dbh-Cre', 'Gq-DREADD', '+'};
            app.GenotypeDropDownV2_genoT1.Placeholder = 'Select a gene';
            app.GenotypeDropDownV2_genoT1.Value = 'None';
            app.RemoveTab_geno = uibutton(app.genoPanel, 'push');
            app.RemoveTab_geno.Text = '-';
            app.DoBDatePicker_subj = uidatepicker(app.SubjectStatusPanel);
            app.DoBDatePicker_subj.DisplayFormat = 'uuuu-MM-dd';
            app.NotesLabel_expmnt = uilabel(app.SubjectStatusPanel);
            app.NotesLabel_expmnt.Text = 'Notes:';
            app.WatermLEditFieldLabel_subj = uilabel(app.SubjectStatusPanel);
            app.WatermLEditFieldLabel_subj.Text = 'Water (mL):';
            app.WatermLEditField_subj = uieditfield(app.SubjectStatusPanel, 'numeric');
            app.WatermLEditField_subj.Limits = [0 Inf];
            app.NoteButton_subj = uibutton(app.SubjectStatusPanel, 'state');
            app.NoteButton_subj.ValueChangedFcn = createCallbackFcn(app, @NoteButton_subjValueChanged, true);
            app.NoteButton_subj.Icon = fullfile(pathToMLAPP, 'Icons', 'note.png');
            app.NoteButton_subj.Text = '';
            app.SexDropDownLabel_subj = uilabel(app.SubjectStatusPanel);
            app.SexDropDownLabel_subj.Text = 'Sex:';
            app.SexDropDownV2_subj = uidropdown(app.SubjectStatusPanel);
            app.SexDropDownV2_subj.Items = {'M', 'F'};
            app.SexDropDownV2_subj.Value = 'M';
            app.SelectProjectDirectoryDropDownLabel = uilabel(app.UIFigure);
            app.SelectProjectDirectoryDropDownLabel.Enable = 'off';
            app.SelectProjectDirectoryDropDownLabel.Visible = 'off';
            app.SelectProjectDirectoryDropDownLabel.Text = 'Select Project Directory';
            app.DataStreamButton_expmnt = uibutton(app.UIFigure, 'push');
            app.DataStreamButton_expmnt.ButtonPushedFcn = createCallbackFcn(app, @DataStreamButton_expmntPushed, true);
            app.DataStreamButton_expmnt.Icon = fullfile(pathToMLAPP, 'Icons', 'upload.png');
            app.DataStreamButton_expmnt.Enable = 'off';
            app.DataStreamButton_expmnt.Text = '';
            app.SelectProjectDirectoryDropDown_proj = uidropdown(app.UIFigure);
            app.SelectProjectDirectoryDropDown_proj.Items = {'None', 'Project_Neuromodulation-for-Pain', 'Neuromodulation for Pain', 'Neurotransmitter Exploration', 'Thalamic Pupil Synchronization', 'SSD', '+', 'Browse'};
            app.SelectProjectDirectoryDropDown_proj.ValueChangedFcn = createCallbackFcn(app, @SelectProjectDirectoryDropDown_projValueChanged, true);
            app.SelectProjectDirectoryDropDown_proj.Enable = 'off';
            app.SelectProjectDirectoryDropDown_proj.Visible = 'off';
            app.SelectProjectDirectoryDropDown_proj.Value = 'None';
            app.connectTargetButton = uibutton(app.UIFigure, 'state');
            app.connectTargetButton.ValueChangedFcn = createCallbackFcn(app, @connectTargetButtonValueChanged, true);
            app.connectTargetButton.Enable = 'off';
            app.connectTargetButton.Icon = 'slrtDisconnectIcon.png';
            app.connectTargetButton.Text = 'Button';
            app.SessionTimerminEditField = uieditfield(app.UIFigure, 'numeric');
            app.SessionTimerminEditField.ValueChangedFcn = createCallbackFcn(app, @SessionTimerminEditFieldValueChanged, true);
            app.SessionTimerminEditField.Enable = 'off';
            app.SessionTimerminEditField.Value = 30;
            app.SubjectDropDownLabel = uilabel(app.UIFigure);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.SubjectDropDown_expmnt = uidropdown(app.UIFigure);
            app.SubjectDropDown_expmnt.Items = {'None', '+'};
            app.SubjectDropDown_expmnt.ValueChangedFcn = createCallbackFcn(app, @SubjectDropDown_expmntValueChanged, true);
            app.SubjectDropDown_expmnt.Enable = 'off';
            app.SubjectDropDown_expmnt.Value = 'None';
            app.ExpmntDropDownV2_slrt = uidropdown(app.UIFigure);
            app.ExpmntDropDownV2_slrt.Items = {'None'};
            app.ExpmntDropDownV2_slrt.DropDownOpeningFcn = createCallbackFcn(app, @ExpmntDropDownV2_slrtOpeningFcn, true);
            app.ExpmntDropDownV2_slrt.ValueChangedFcn = createCallbackFcn(app, @ExpmntDropDownV2_slrtValueChanged, true);
            app.ExpmntDropDownV2_slrt.Enable = 'off';
            app.ExpmntDropDownV2_slrt.Value = 'None';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'slrtStopIcon.png';
            app.StopButton.Enable = 'off';
            app.StopButton.Text = '';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'slrtRunIcon.png';
            app.StartButton.Enable = 'off';
            app.StartButton.Text = '';
            app.LabelButton = uibutton(app.UIFigure, 'push');
            app.LabelButton.ButtonPushedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.LabelButton.Visible = 'off';
            app.LabelButton.Text = 'Label';
            app.DataDiscardButton_expmnt = uibutton(app.UIFigure, 'push');
            app.DataDiscardButton_expmnt.ButtonPushedFcn = createCallbackFcn(app, @DataDiscardButton_expmntPushed, true);
            app.DataDiscardButton_expmnt.Icon = fullfile(pathToMLAPP, 'Icons', 'x-icon-white.png');
            app.DataDiscardButton_expmnt.Enable = 'off';
            app.DataDiscardButton_expmnt.Text = '';
            app.ExpmntDropDownLabel = uilabel(app.UIFigure);
            app.ExpmntDropDownLabel.Text = 'Experiment';
            app.SaveSubjectLabelButton = uibutton(app.UIFigure, 'push');
            app.SaveSubjectLabelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSubjectLabelButtonPushed, true);
            app.SaveSubjectLabelButton.Visible = 'off';
            app.SaveSubjectLabelButton.Text = 'Save Subject';
            app.PhaseDropDown_slrt = uidropdown(app.UIFigure);
            app.PhaseDropDown_slrt.Items = {'None'};
            app.PhaseDropDown_slrt.ValueChangedFcn = createCallbackFcn(app, @PhaseDropDown_slrtValueChanged, true);
            app.PhaseDropDown_slrt.Enable = 'off';
            app.PhaseDropDown_slrt.Tooltip = {'Select experiment phase'};
            app.PhaseDropDown_slrt.Value = 'None';
            app.SessionTimerminEditFieldLabel = uilabel(app.UIFigure);
            app.SessionTimerminEditFieldLabel.Enable = 'off';
            app.SessionTimerminEditFieldLabel.Text = 'Session Timer (min )';
            app.ParameterTableButton = uibutton(app.UIFigure, 'state');
            app.ParameterTableButton.ValueChangedFcn = createCallbackFcn(app, @ParameterTableButtonValueChanged, true);
            app.ParameterTableButton.Enable = 'off';
            app.ParameterTableButton.Icon = fullfile(pathToMLAPP, 'Icons', 'paramIcon2.png');
            app.ParameterTableButton.Text = '';
            app.ExperimentSetupLabel = uilabel(app.UIFigure);
            app.ExperimentSetupLabel.Text = '  Experiment Setup';
            app.gxPanelSwitchLabel_gx = uilabel(app.UIFigure);
            app.gxPanelSwitchLabel_gx.Text = 'Molecular';
            app.optoPanelSwitchLabel_opto = uilabel(app.UIFigure);
            app.optoPanelSwitchLabel_opto.Text = 'Optogenetics';
            app.photomPanelSwitchLabel_photom = uilabel(app.UIFigure);
            app.photomPanelSwitchLabel_photom.Text = 'Photometry';
            app.sensePanelSwitchLabel_sense = uilabel(app.UIFigure);
            app.sensePanelSwitchLabel_sense.Text = 'Sensory';
            app.photonPanelSwitchLabel_photon = uilabel(app.UIFigure);
            app.photonPanelSwitchLabel_photon.Text = '2 Photon';
            app.QCButton = uiimage(app.UIFigure);
            app.QCButton.ImageClickedFcn = createCallbackFcn(app, @showQCPanel, true);
            app.QCButton.Enable = 'off';
            app.QCButton.ImageSource = fullfile(pathToMLAPP, 'Icons', 'QC_off.png');
            app.SessionQCButtonLabel = uilabel(app.UIFigure);
            app.SessionQCButtonLabel.Text = 'Session QC';
            app.SubjectStatusLabel = uilabel(app.UIFigure);
            app.SubjectStatusLabel.Text = '  Subject Status';
            app.npxlsPanelSwitchLabel_npxls = uilabel(app.UIFigure);
            app.npxlsPanelSwitchLabel_npxls.Text = 'Neuropixels';
            app.rxPanelSwitchLabel_rx = uilabel(app.UIFigure);
            app.rxPanelSwitchLabel_rx.Text = 'Pharmacology';
            app.ModulesEditFieldLabel = uilabel(app.UIFigure);
            app.ModulesEditFieldLabel.Text = 'Modules';
            app.ModulesFilePath = uieditfield(app.UIFigure, 'text');
            app.ModulesFilePath.Placeholder = 'Select ''Experiment Modules'' folder';
            app.BrowseModules = uibutton(app.UIFigure, 'push');
            app.BrowseModules.ButtonPushedFcn = createCallbackFcn(app, @SelectModulesFolder, true);
            app.BrowseModules.Text = '...';
            app.ProjectLabel = uilabel(app.UIFigure);
            app.ProjectLabel.Text = 'Project';
            app.ProjectPathTextEditField_proj = uieditfield(app.UIFigure, 'text');
            app.ProjectPathTextEditField_proj.Placeholder = 'Select project directory';
            app.BrowseProjects = uibutton(app.UIFigure, 'push');
            app.BrowseProjects.ButtonPushedFcn = createCallbackFcn(app, @BrowseProjectDirectory, true);
            app.BrowseProjects.Enable = 'off';
            app.BrowseProjects.Text = '...';
            app.SessionQCLabel = uilabel(app.UIFigure);
            app.SessionQCLabel.Visible = 'off';
            app.SessionQCLabel.Text = '  Session Metrics';
            app.slxParamTable = uitable(app.UIFigure);
            app.slxParamTable.ColumnName = {'Parameter'; 'Value'};
            app.slxParamTable.RowName = {};
            app.slxParamTable.ColumnEditable = true;
            app.slxParamTable.CellEditCallback = createCallbackFcn(app, @slxParamTableCellEdit, true);
            app.slxParamTable.CellSelectionCallback = createCallbackFcn(app, @slxParamTableCellSelection, true);
            app.slxParamTable.SelectionChangedFcn = createCallbackFcn(app, @slxParamTableSelectionChanged, true);
            app.slxParamTable.Visible = 'off';
            app.slxParamTable.KeyPressFcn = createCallbackFcn(app, @slxParamTableKeyPress, true);
            app.NotesTextArea_expmnt = uitextarea(app.UIFigure);
            app.NotesTextArea_expmnt.Visible = 'off';
            app.NotesTextArea_expmnt.Placeholder = 'Experiment comments';
            app.DataCacheButton_expmnt = uibutton(app.UIFigure, 'push');
            app.DataCacheButton_expmnt.ButtonPushedFcn = createCallbackFcn(app, @DataCacheButton_expmntPushed, true);
            app.DataCacheButton_expmnt.Icon = fullfile(pathToMLAPP, 'Icons', 'cache-centered.png');
            app.DataCacheButton_expmnt.Enable = 'off';
            app.DataCacheButton_expmnt.Text = '';
            app.SessionLabelLabel = uilabel(app.UIFigure);
            app.SessionLabelLabel.Text = '--';
            app.optoPanelSwitch_opto = uiimage(app.UIFigure);
            app.optoPanelSwitch_opto.Enable = 'off';
            app.optoPanelSwitch_opto.ImageSource = fullfile(pathToMLAPP, 'Icons', 'cortexSwitch_off.png');
            app.gxPanelSwitch_gx = uiimage(app.UIFigure);
            app.gxPanelSwitch_gx.Enable = 'off';
            app.gxPanelSwitch_gx.ImageSource = fullfile(pathToMLAPP, 'Icons', 'cortexSwitch_off.png');
            app.rxPanelSwitch_rx = uiimage(app.UIFigure);
            app.rxPanelSwitch_rx.Enable = 'off';
            app.rxPanelSwitch_rx.ImageSource = fullfile(pathToMLAPP, 'Icons', 'cortexSwitch_off.png');
            app.photonPanelSwitch_photon = uiimage(app.UIFigure);
            app.photonPanelSwitch_photon.Enable = 'off';
            app.photonPanelSwitch_photon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'cortexSwitch_off.png');
            app.npxlsPanelSwitch_npxls = uiimage(app.UIFigure);
            app.npxlsPanelSwitch_npxls.Enable = 'off';
            app.npxlsPanelSwitch_npxls.ImageSource = fullfile(pathToMLAPP, 'Icons', 'cortexSwitch_off.png');
            app.sensePanelSwitch_sense = uiimage(app.UIFigure);
            app.sensePanelSwitch_sense.Enable = 'off';
            app.sensePanelSwitch_sense.ImageSource = fullfile(pathToMLAPP, 'Icons', 'cortexSwitch_off.png');
            app.photomPanelSwitch_photom = uiimage(app.UIFigure);
            app.photomPanelSwitch_photom.Enable = 'off';
            app.photomPanelSwitch_photom.ImageSource = fullfile(pathToMLAPP, 'Icons', 'cortexSwitch_off.png');
            app.cameraPanelSwitch_camera = uiimage(app.UIFigure);
            app.cameraPanelSwitch_camera.Enable = 'off';
            app.cameraPanelSwitch_camera.ImageSource = fullfile(pathToMLAPP, 'Icons', 'cortexSwitch_off.png');
            app.StatusBar = slrealtime.ui.control.StatusBar(app.UIFigure);
            app.cameraPanelSwitchLabel_camera = uilabel(app.UIFigure);
            app.cameraPanelSwitchLabel_camera.Text = 'Camera';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nCORTEx_host

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end