classdef nCORTEx_target < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ControlButton                   matlab.ui.control.Button
        ExperimentModulesFolderButton   matlab.ui.control.StateButton
        SessionLabelButton              matlab.ui.control.Button
        SelectProjectDirectoryDropDown  matlab.ui.control.DropDown
        ExpmntDropDown                  matlab.ui.control.DropDown
        ExpmntDropDownLabel             matlab.ui.control.Label
        ExtractButton                   matlab.ui.control.Button
        SubjectSelectionPanel           matlab.ui.container.Panel
        subjectListPanel                matlab.ui.container.Panel
        connectIcon                     matlab.ui.control.Image
        Image4_3                        matlab.ui.control.Image
        Image4_2                        matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Image3_3                        matlab.ui.control.Image
        Image3_2                        matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        geo2                            matlab.ui.control.Image
        SelectProjectDirectoryDropDownLabel  matlab.ui.control.Label
        ExtractionItemPanel             matlab.ui.container.Panel
        moduleSlot5                     matlab.ui.control.Image
        moduleSlot4                     matlab.ui.control.Image
        moduleSlot3                     matlab.ui.control.Image
        moduleSlot2                     matlab.ui.control.Image
        moduleSlot1                     matlab.ui.control.Image
    end

    
    properties (Access = public)
        params = [];
        project = [];
        subjExtractTable = [];   
        projDir_cloud;
        projDir_local;
        cyberYellow = [1.00,1.00,0.00];
        cyberGreen=[0.24,0.94,0.46];
        disableGreen=[0.05,0.14,0.08];
        cyberOrange=[1.00,0.41,0.16];
        cyberGrey=[0.18,0.18,0.22];
        cyberRed=[1.00,0.33,0.42];
        activeGrey=[0.96,0.96,0.96];
        disableGrey=[0.50,0.50,0.50];
        disableBlack=[0,0,0];
        enableWhite=[1.00,1.00,1.00];
        panelGrey = [0.07,0.07,0.10];
        selBtns = struct;
        extrctItms = struct;
        extractItems = [];
        modalities = [];
        staticColor=[];
        expmnt="None";
        servers = struct;
        serverModule = struct;
        nCORTEx_repo= [];
        ModulesFilePath;
        proxon = struct;
        nexon=struct;
    end
    
    methods (Access = public)

        % function runExtraction(app)
        %     % extract data for each selected modality
        %     % write each extraction to project-located datastore             
        %     dataStore = [];
        %     for i = 1:length(app.extractItems)
        %         modality = app.extractItems(i);                
        %         subjectsToExtract = app.subjExtractTable.subjID(app.SubjExtractTable.extract==1);                
        %         dataStore = outerjoin(dataStore, extraction(app.params, modality, subjectsToExtract));
        %     end
        % end

        function extractTable = initExtractTable(app)
            colNames = {'SubjectName','Extract'};
            extractTable = array2table(cell(1,length(colNames)),"VariableNames",colNames);
        end        
        
        function waitBarCallback(app)                       
            for i = 1:length(app.extractItems)
                extrctItm = app.extractItems(i);
                pq = app.extrctItms.(extrctItm).pq;
                progress = poll(pq);
                if ~isempty(progress)
                    wbRatio = progress{2};                    
                    waitBarRef = sprintf("%sWaitBar",extrctItm);
                    drawrectangle(app.extrctItms.(extrctItm).(waitBarRef),'Position', [0, 0,wbRatio, 1],'InteractionsAllowed','none','Color',app.staticColor);
                end
            end
        end

        function serverQCallback(app, pq)
            data = poll(pq);
            msgParts = split(data,":");
            key = msgParts{1};
            val = msgParts{2};
            switch(key)
                case 'connectionChanged'
                    app.servers.(val).indicator = toggleIndicator(app,app.servers.(val).indicator);
            end
        end

        function indicatorOut = toggleIndicator(app,indicator)
            indicatorOut = indicator;
            imgSrc = indicator.ImageSource;            
            switch imgSrc
                case 'Icons\serverModule_disconnected.PNG'
                    indicatorOut.ImageSource = 'Icons\serverModule_connected.PNG';
                case 'Icons\serverModule_connected.PNG'
                    indicatorOut.ImageSource = 'Icons\serverModule_standby.PNG';
                case 'Icons\serverModule_standby.PNG'
                    indicatorOut.ImageSource = 'Icons\serverModule_connected.PNG';
            end
        end        

        function cortexServer = initiateCortexServer(app)     
            try
                cortexServer = tcpserver(app.params.ethernetIP,uint64(8001),"ConnectionChangedFcn",@(src, evnt)cortexServerConnected(app));
            catch
                cortexServer =  tcpserver(app.params.ethernetIP,uint64(8003),"ConnectionChangedFcn",@(src, evnt)cortexServerConnected(app));
            end
            callbackHndl = str2func('cortexServerCallback');            
            try
                % configureCallback(cortexServer,"terminator",@(~,~)callbackHndl(app, cortexServer));
                configureCallback(cortexServer,"terminator",@(~,~)callbackHndl(app, cortexServer));
            catch e
                disp(e.message);
                % delete(cortexServer);
                % clear cortexServer;
                % cortexServer = tcpserver(app.params.ethernetIP,uint64(8001),"ConnectionChangedFcn",@(src, evnt)cortexServerConnected(app));
                % callbackHndl = str2func('cortexServerCallback');
                % configureCallback(cortexServer,"terminator",@(~,~)callbackHndl(app, cortexServer));
            end
        end

        function cortexServerConnected(app)
            % if app.servers.cortexServer.Connected == 1
            % if app.proxon.proxy_ncortex.Server.Connected == 1
            if app.proxon.index_type1.ncortex.Server.Connected == 1
                app.serverModule.serverConnectButton.ImageSource = "Icons/connectButton_connected.PNG";
                app.serverModule.serverIcon.ImageSource = "Icons/serverIcon_connected.PNG";
                app.serverModule.serverIcon.Position = [22,5,189,30];
                app.serverModule.serverEN = 1;
            elseif app.proxon.index_type1.ncortex.Server.Connected == 0
                app.serverModule.serverConnectButton.ImageSource = "Icons/connectButton_standby.PNG";
                app.serverModule.serverIcon.ImageSource = "Icons/serverIcon_standby.PNG";
                app.serverModule.serverIcon.Position = [23,-4,182,48];               
                app.serverModule.serverEN = 1;
                app.SelectProjectDirectoryDropDown.Value="None";
                SelectProjectDirectoryDropDownValueChanged(app);
                app.ExpmntDropDown.Value="None";
                ExpmntDropDownValueChanged(app);
            end
        end

        function cortexServerCallback(app, cortexServer)
            disp("Rx from acquisition");
            msg = readline(cortexServer);
            registerMsg(app, msg);
        end       

        function registerMsg(app, msg)
            msgParts = split(msg,":");
            key = msgParts(1);
            val = msgParts(2);
            switch key
                case 'SessionLabelLabel'
                    % SGLX Case
                    app.params.sessionLabel = val;                    
                    % if ~strcmp(app.params.hostName,"USERBRU-2FNENOI")
                    try
                        hSGL = SpikeGL(char(app.params.ethernetIP));
                        ungatedSL = split(val,'_');
                        ungatedSL = ungatedSL(1:end-1);
                        ungatedSL = string(join(ungatedSL,'_'));
                        if ~IsRunning(hSGL); SetRunName(hSGL,char(ungatedSL)); end                    
                    catch e
                    end                    
                    % PL Case
                    ctxServers = string(fieldnames(app.servers));
                    if any(contains(ctxServers,"photon"))
                        % savePath = fullfile(app.params.paths.Data.RAW.PHOTON.local,val);
                        % app.servers.("photon").modSrv;
                        % % pl.SetSavePath(savePath);
                        % scriptCmd = sprintf("-SetSavePath %s",app.params.paths.Data.RAW.PHOTON.local);
                        % pl.SendScriptCommands(scriptCmd)
                        % scriptCmd = sprintf("-SetFileName %s",app.params.sessionLabel);                        
                        % update callback params
                        % configureCallback(app.servers.("photon").modSrv)
                        % configureCallback(server,"byte",25,@(src, evnt)readDataFcnHndl(app.params,server,modalityServer.modSrv));    
                    end
                    % UPDATE MODALITY SERVERS
                    initiateModalityServers(app)
                case 'migrateTmp'
                    tmpName = sprintf("%sTmp",val);
                    if isunix
                        tmpPath = fullfile("~/%s",tmpName);
                    elseif ispc
                        tmpPath = fullfile(sprintf("C:/%s",tmpName));
                    end
                    tmpItems = dir(tmpPath);
                    tmpItems = struct2table(tmpItems);
                    % move each to target nCORTEx_local modality
                    modFldr = upper(val);
                    modFldr = fullfile(app.params.paths.Data.RAW.(modFldr).local);
                    for i = 3:height(tmpItems)
                        tmpItm = tmpItems(i,:);
                        tmpName = convertCharsToStrings(tmpItm.name);
                        tmpOrigin = fullfile(tmpPath,tmpName);
                        if strcmp(tmpName,app.params.sessionLabel)                        
                            movefile(tmpOrigin,modFldr);
                        end
                    end
                    return;
                case 'discardSession'
                    session2Discard = val;
                    uploadRAW(app.params.paths.Data.RAW,session2Discard,1);
                otherwise
                    app.params.(key) = val;
            end
            
            appFields = fieldnames(app);
            for i = 1:length(appFields)
                appField = appFields{i};
                if contains(appField,key)
                    if isprop(app.(appField),'Value')
                        if any(contains(app.(appField).Items,val))
                            app.(appField).Value = val; 
                            app.(appField).ValueChangedFcn([],app)
                        end
                    elseif isprop(app.(appField),'Text')
                        app.(appField).Text = val;
                    end
                end                
            end
        end


        function resetExtraction(app, panelRef)
            % for given modality, access both corresponding extraction log and
            % selected subjects and set isExtracted entries to '0'/'false'

            % update corresponding numToProc label
            updateNumToProc(app, panelRef);
        end   
    
        function subjExtrStatusButtonCallback(app, isExtract, subjID)
            subjExtractTable = app.subjExtractTable;
            subjExtractTable(subjExtractTable.subjID == subjID).extract = isExtract;

            app.subjExtractTable = subjExtractTable;
            % update all extraction counts (i.e. numToProc labels)
            updateExtractionStats(app);
        end     
     
        function writeNumFancy(app, labelRef, num)
            % write number into a given label Id in a 'fun' way
        end

        function drawSubjectSelection(app)
            % get subject List
            % subjLog = readtable(fullfile(app.projDir_cloud,"Experiments","subjectLog.csv"));
            % subjectList = subjLog.SubjectName;
            SubjectName = string(categorize(fullfile(app.params.paths.projDir_cloud,"Experiments",app.params.extractCfg.experiment,"Subjects"),"isDir"))';
            Extract = zeros(length(SubjectName),1);
            app.subjExtractTable = table(SubjectName, Extract);
            % app.subjExtractTable.Extract = zeros(length(subjectList),1);            
            subjectNum = strrep(SubjectName,'-','');
            subjectNum = str2double(subjectNum);
            % place button for each subject
            yTop = 10;
            yStep = 30;
            x = 6;
            if length(SubjectName)>6
                w = 390;
            else
                w = 410;
            end
            h = 25;
            for i = 1:length(SubjectName)
                subject = SubjectName{i};   
                subjNum = subjectNum(i);
                if isnan(subjNum)
                    try
                        subjNum = strrep(subject,"SUBJ","");
                        subjNum = split(subjNum,"_");
                        subjNum = string(subjNum{1});
                        subjNum = regexprep(subjNum,'[a-zA-Z\s]','');
                        subjNum = str2double(subjNum);
                    catch
                    end
                end
                y = yTop + (i-1)*yStep;                                
                position = [x, y, w, h];
                buttonRef = sprintf("SelectButton_%d",subjNum);
                % Create Button
                % app.selBtns.(buttonRef) = uibutton(app.subjectListPanel,"style","pushbutton");                
                app.selBtns.(buttonRef) = uicontrol(app.subjectListPanel,"style","pushbutton");                
                app.selBtns.(buttonRef).Position = position;
                % app.selBtns.(buttonRef).Text=subject;
                app.selBtns.(buttonRef).String=subject;
                % app.selBtns.(buttonRef).FontColor = app.staticColor;
                app.selBtns.(buttonRef).ForegroundColor = app.staticColor;
                app.selBtns.(buttonRef).BackgroundColor = app.disableBlack;   
                app.selBtns.(buttonRef).Tag = num2str(subjNum);
                app.selBtns.(buttonRef).Value=1;
                % app.selBtns.(buttonRef).ValueChangedFcn=createCallbackFcn(app,@subjSelButtonPushed,true);              
                app.selBtns.(buttonRef).Callback=createCallbackFcn(app,@subjSelButtonPushed,true);
                app.selBtns.(buttonRef).ButtonDownFcn=createCallbackFcn(app,@subjSelButtonPushed,true);
                % createCallbackFcn(app, @SelectProjectDirectoryDropDownValueChanged, true);
            end
        end

        function subjSelButtonPushed(app, event)
            figHandle = ancestor(event.Source, 'figure');
            clickType = get(figHandle, 'SelectionType');
            subjNum = event.Source.Tag;
            % subject = event.Source.Text;
            subject = event.Source.String;
            % get button ID
            buttonRef = sprintf("SelectButton_%s",subjNum);            
            % toggle button color
            state = app.selBtns.(buttonRef).Value;
            switch state
                case 1 % button currently off/unselected
                    app.selBtns.(buttonRef).BackgroundColor = app.staticColor;
                    % app.selBtns.(buttonRef).FontColor = app.disableBlack;
                    app.selBtns.(buttonRef).ForegroundColor = app.disableBlack;
                    app.subjExtractTable(contains(app.subjExtractTable.SubjectName,subject),:).Extract=1;
                    % reassign subject 
                    app.params.subject = subject;
                    switch clickType
                        case 'normal'
                        case 'alt'                            
                            % load subj cfg file
                            subjectPath = fullfile(app.params.paths.projDir_cloud,"Experiments/",app.params.experiment,"Subjects",subject);
                            try
                                load(fullfile(subjectPath,"subjectCfg.mat"))
                                app.params.subjectCfg=subjectCfg;
                                % configure subject (with proxy array)
                                app.proxon.index_type1.ncortex_1.configureSubject();
                            catch e
                                disp(getReport(e));
                                subjectCfg=[];
                                app.params.subjectCfg=subjectCfg;
                            end
                            
                    end
                    app.selBtns.(buttonRef).Value = 0;
                case 0 % button currently on/selected                 
                    app.selBtns.(buttonRef).BackgroundColor = app.disableBlack;
                    app.selBtns.(buttonRef).ForegroundColor = app.staticColor;
                    app.subjExtractTable(contains(app.subjExtractTable.SubjectName,subject),:).Extract=0;
                    % reassign subject 
                    app.params.subject = '';  
                    switch clickType
                       case 'normal'
                       case 'alt'                                                                                   
                            app.params.subjectCfg=[];
                    end
                    app.selBtns.(buttonRef).Value = 1;
            end                 
            updateSessions2Extract(app);
        end
        
        function drawServerModule(app)
            % status bar
            % app.serverModule.serverStatusBar = uiimage("Parent",app.UIFigure,"ImageSource","Icons/serverStatusBar.PNG","Position",[-13,-127,455,363]);
            % server connect button
            app.serverModule.serverConnectButton = uiimage("Parent",app.UIFigure,"ImageSource","Icons/connectButton_disconnected.PNG","Position",[42,-57,162,157],"ImageClickedFcn",createCallbackFcn(app, @serverConnectButtonPressed,true));
            app.serverModule.serverEN = 0;
            % server connect icon 
            app.serverModule.serverIcon = uiimage("Parent",app.UIFigure,"ImageSource","Icons/serverIcon_disconnected.PNG","Position",[23,-4,182,48],"ImageClickedFcn",createCallbackFcn(app, @serverConnectButtonPressed,true));
            % server modules 130,-11,69,75 : : 156,-11,69,75         
        end

        function serverConnectButtonPressed(app, event)            
            switch app.serverModule.serverEN
                case 0 % currently off - turn ON (standby)
                    % if ~isfield(app.servers,"cortexServer")
                    % app.servers.cortexServer = initiateCortexServer(app);
                    initiateModalityServers(app);
                    % end                    
                    % for i = 1:length(app.modalities)
                    %     modality = lower(app.modalities(i));
                    %     readDataFcns = string(categorize(fullfile(app.params.paths.stem,"Code_Repo","n-CORTEx","utils"),"readDataFcn"));
                    %     isProg = any(contains(readDataFcns,(modality)));
                    %     if isProg && ~strcmp(modality,'slrt')
                    %         serverInitHndl = str2func(sprintf("server_%s",modality));                            
                    %         app.servers.(modality).Q.q = parallel.pool.DataQueue;
                    %         app.servers.(modality).Q.q.afterEach(@(data)serverQCallback(app))                            
                    %         app.servers.(modality).Q.pq = parallel.pool.PollableDataQueue;
                    %         app.servers.(modality).srv = serverInitHndl(app.params);
                    %         app.servers.(modality).sgSrv = server_slrt(app.params,app.servers.(modality).srv);
                    %         app.servers.(modality).indicator = uiimage("Parent",app.UIFigure,"ImageSource","Icons/serverModule_connected.PNG","Position",[130,-11,69,75]+[26*(i-1),0,0,0]);
                    %     end                        
                    %     % app.servers.pl = server_photon(app.params);                           
                    % end                    
                    app.serverModule.serverConnectButton.ImageSource = "Icons/connectButton_standby.PNG";
                    app.serverModule.serverIcon.ImageSource = "Icons/serverIcon_standby.PNG";
                    app.serverModule.serverIcon.Position = [23,-4,182,48];
                    app.serverModule.serverEN = 1;
                case 1 % currently on - turn OFF
                    app.servers = struct;
                    app.serverModule.serverConnectButton.ImageSource = "Icons/connectButton_disconnected.PNG";
                    app.serverModule.serverIcon.ImageSource = "Icons/serverIcon_disconnected.PNG";
                    app.serverModule.serverIcon.Position = [23,-4,182,48];
                    app.serverModule.serverEN = 0;
            end
        end

        function initiateModalityServers(app) 
            m = 1;
            for i = 1:length(app.modalities)
                modality = string(lower(app.modalities(i)));
                readDataFcns = string(categorize(fullfile(app.params.paths.stem,"Code_Repo","n-CORTEx","Setup","Server"),"readDataFcn"));
                isProg = any(contains(readDataFcns,(modality)));
                if isProg && ~strcmp(modality,'slrt')
                    serverInitHndl = str2func(sprintf("server_%s",modality));    
                    % Refresh/reset if already exists
                    if (isfield(app.servers,modality))
                        if (isfield(app.servers.(modality),'indicator'))
                            delete(app.servers.(modality).sgSrv);
                            delete(app.servers.(modality).indicator); 
                            % clear(app.servers.(modality).indicator);
                            app.servers = rmfield(app.servers,modality); 
                        end                                              
                    end
                    app.servers.(modality).Q.q = parallel.pool.DataQueue;
                    app.servers.(modality).Q.pq = parallel.pool.PollableDataQueue;
                    app.servers.(modality).Q.q.afterEach(@(data)serverQCallback(app, app.servers.(modality).Q.pq));
                    app.servers.(modality).modSrv = serverInitHndl(app.params);
                    app.servers.(modality).sgSrv = server_slrt(app.params,app.servers.(modality));
                    indicatorFcn = str2func(sprintf("readDataFcn_%s",modality));
                    app.servers.(modality).indicator = uiimage("Parent",app.UIFigure,"ImageSource","Icons/serverModule_standby.PNG","Position",[144,-21,82,87]+[28*(m-1),0,0,0],"ImageClickedFcn",@(src, event)indicatorFcn(app.params,app.servers.(modality).sgSrv,app.servers.(modality).modSrv));
                    m=m+1;
                end                        
                % app.servers.pl = server_photon(app.params);                      
            end                     
        end

        function drawExtractionItems(app)
            delete(app.ExtractionItemPanel)
            if app.expmnt ~= "None"
                app.ExtractionItemPanel = uipanel(app.UIFigure,"Position",[9,72,479,573],"BorderColor",[0.1216 0.1216 0.1608], "ForegroundColor",[0.1216 0.1216 0.1608],"BackgroundColor",[0.1216 0.1216 0.1608]);
                % app.ExtractionItemPanel = uipanel(app.UIFigure,"Position",[9,218,479,427],"BorderColor",[0.1216 0.1216 0.1608], "ForegroundColor",[0.1216 0.1216 0.1608],"BackgroundColor",[0.1216 0.1216 0.1608]);                

                pattern = "_extraction_log.csv";
                extractionLogPath = fullfile(app.projDir_cloud,"Experiments",app.expmnt,"Extraction-Logs");
                extractList = categorize(extractionLogPath,"extraction_log");
                extractList = strrep(cellstr(extractList),pattern,"");                
                app.extractItems = extractList;
                h = 90;
                w = 455;
                yTop = 453; 
                yStep = h + 10; 
                x = 6;
                % resetButton pos
                rstPos = [19, 46, 47, 23];
                % waitBar pos
                waitPos = [14,14,393,22];
                % num2Extrct pos
                extrctCntPos = [329,40,79,39];
                % switch pos
                swtchPos = [429,46,15,35];
                % Load module selection
                load(fullfile(app.params.paths.expmntPath_cloud,"extractCfg.mat"),"extractCfg");
                app.params.extractCfg.modSel=extractCfg.modSel;
                for i = 1:length(extractList)
                    extrctItm = extractList(i);                    
                    %% PANEL
                    itmPanelRef = sprintf("%sPanel",extrctItm);                    
                    extrctLogFname = sprintf("%s_extraction_log.csv",extrctItm);
                    extrctItm = split(extrctItm,'_');
                    extrctItm = extrctItm(1);

                    y = yTop - (i-1)*yStep;
                    app.extrctItms.(extrctItm) = struct;
                    % load(fullfile(extractionLogPath,extrctLogFname),'extractionLog');                    
                    extractionLog = readtable(fullfile(extractionLogPath,extrctLogFname),"Delimiter",",");
                    app.extrctItms.(extrctItm).extractionLog = extractionLog;
                    app.extrctItms.(extrctItm).tmpExtractionLog = extractionLog;
                    extrctModFcnPath = fullfile(app.params.paths.stem,"Code_Repo","n-CORTEx","Extraction","Extraction-Modules");
                    extrctModFldrPath = fullfile(app.params.paths.projDir_cloud,"Experiments",app.params.extractCfg.experiment,"Data",extrctItm);
                    app.extrctItms.(extrctItm).extrctModules = buildExtrctModules(app.params, extrctModFcnPath, extrctModFldrPath, extrctItm);
                    app.extrctItms.(extrctItm).Q.q = parallel.pool.DataQueue;
                    app.extrctItms.(extrctItm).Q.pq = parallel.pool.PollableDataQueue;
                    app.extrctItms.(extrctItm).(itmPanelRef) = uipanel(app.ExtractionItemPanel);
                    app.extrctItms.(extrctItm).(itmPanelRef).BorderColor = [0.0706 0.0706 0.102];
                    app.extrctItms.(extrctItm).(itmPanelRef).ForegroundColor = app.staticColor;
                    app.extrctItms.(extrctItm).(itmPanelRef).BorderType = 'none';
                    app.extrctItms.(extrctItm).(itmPanelRef).BorderWidth = 0;
                    app.extrctItms.(extrctItm).(itmPanelRef).Title = extrctItm;
                    app.extrctItms.(extrctItm).(itmPanelRef).BackgroundColor = app.panelGrey;
                    app.extrctItms.(extrctItm).(itmPanelRef).Scrollable = 'off';
                    app.extrctItms.(extrctItm).(itmPanelRef).Position = [x y w h];
                    
                    %% RESET BUTTON
                    resetRef = sprintf("%sResetButton",extrctItm);
                    app.extrctItms.(extrctItm).(resetRef) = uibutton(app.extrctItms.(extrctItm).(itmPanelRef),"state");
                    app.extrctItms.(extrctItm).(resetRef).BackgroundColor = app.disableBlack;
                    app.extrctItms.(extrctItm).(resetRef).FontColor = app.staticColor;
                    app.extrctItms.(extrctItm).(resetRef).Position = rstPos;
                    app.extrctItms.(extrctItm).(resetRef).Text = 'reset';
                    app.extrctItms.(extrctItm).(resetRef).ValueChangedFcn = createCallbackFcn(app, @resetButtonPressed,true);

                    %% SET LAYER DIR BUTTON (IF FLAGGED)
                    if strcmp(extrctItm,"DTS")
                        locateRef = sprintf("%sLocateButton",extrctItm);
                        app.extrctItms.(extrctItm).(locateRef) = uibutton(app.extrctItms.(extrctItm).(itmPanelRef),"push");
                        app.extrctItms.(extrctItm).(locateRef).BackgroundColor = app.disableBlack;
                        app.extrctItms.(extrctItm).(locateRef).FontColor = app.staticColor;
                        app.extrctItms.(extrctItm).(locateRef).Position = [rstPos(1:2),0,0] + [50,0, 23, 23];
                        app.extrctItms.(extrctItm).(locateRef).Text = '...';
                        app.extrctItms.(extrctItm).(locateRef).Tooltip = sprintf('locate %s directory',extrctItm);
                        app.extrctItms.(extrctItm).(locateRef).ButtonPushedFcn= createCallbackFcn(app, @locateDTSButtonPushed);
                    end

                    %% RAW RECYCLE BUTTON (IF FLAGGED)
                    if strcmp(extrctItm,"RAW")
                        recycleRef = sprintf("%sRecycleButton",extrctItm);
                        app.extrctItms.(extrctItm).(recycleRef) = uibutton(app.extrctItms.(extrctItm).(itmPanelRef),"push");
                        app.extrctItms.(extrctItm).(recycleRef).BackgroundColor = app.disableBlack;
                        app.extrctItms.(extrctItm).(recycleRef).FontColor = app.staticColor;
                        app.extrctItms.(extrctItm).(recycleRef).Position = [rstPos(1:2),0,0] + [50,0, 23, 23];
                        app.extrctItms.(extrctItm).(recycleRef).Text = '';
                        app.extrctItms.(extrctItm).(recycleRef).Tooltip = sprintf('Re-extract previously extracted sessions',extrctItm);
                        app.extrctItms.(extrctItm).(recycleRef).ButtonPushedFcn= createCallbackFcn(app, @recycleRAWButtonPushed);
                    end

                    %% WAITBAR 
                    waitBarRef = sprintf("%sWaitBar",extrctItm);
                    app.extrctItms.(extrctItm).(waitBarRef) = uiaxes(app.extrctItms.(extrctItm).(itmPanelRef));
                    title(app.extrctItms.(extrctItm).(waitBarRef), 'Title');
                    xlabel(app.extrctItms.(extrctItm).(waitBarRef), 'X');
                    ylabel(app.extrctItms.(extrctItm).(waitBarRef), 'Y');
                    zlabel(app.extrctItms.(extrctItm).(waitBarRef), 'Z');
                    app.extrctItms.(extrctItm).(waitBarRef).Toolbar.Visible = 'off';
                    app.extrctItms.(extrctItm).(waitBarRef).FontName = 'Ubuntu';
                    app.extrctItms.(extrctItm).(waitBarRef).XColor = [0.1804 0.1804 0.2196];
                    app.extrctItms.(extrctItm).(waitBarRef).XTick = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
                    app.extrctItms.(extrctItm).(waitBarRef).YColor = [0.1804 0.1804 0.2196];
                    app.extrctItms.(extrctItm).(waitBarRef).YTick = [0 0.2 0.4 0.6 0.8 1];
                    app.extrctItms.(extrctItm).(waitBarRef).ZColor = [0.1804 0.1804 0.2196];
                    app.extrctItms.(extrctItm).(waitBarRef).Color = [0.1804 0.1804 0.2196];
                    app.extrctItms.(extrctItm).(waitBarRef).ClippingStyle = 'rectangle';
                    app.extrctItms.(extrctItm).(waitBarRef).GridColor = [0.149 0.149 0.149];
                    app.extrctItms.(extrctItm).(waitBarRef).Box = 'on';
                    app.extrctItms.(extrctItm).(waitBarRef).FontSize = 1;
                    app.extrctItms.(extrctItm).(waitBarRef).Title 
                    app.extrctItms.(extrctItm).(waitBarRef).Position = waitPos;

                    %% EXTRACTION COUNT
                    numToProcRef = sprintf("%sNumToProc",extrctItm);
                    app.extrctItms.(extrctItm).(numToProcRef) = uilabel(app.extrctItms.(extrctItm).(itmPanelRef));
                    app.extrctItms.(extrctItm).(numToProcRef).HorizontalAlignment = 'center';
                    app.extrctItms.(extrctItm).(numToProcRef).FontSize = 26;
                    app.extrctItms.(extrctItm).(numToProcRef).FontColor = app.staticColor;
                    app.extrctItms.(extrctItm).(numToProcRef).Position = extrctCntPos;
                    app.extrctItms.(extrctItm).(numToProcRef).Text = '--';% num2str(sum(~extractionLog.Extracted));

                    %% SWITCH
                    switchRef = sprintf("%sSwitch",extrctItm);
                    app.extrctItms.(extrctItm).(switchRef) = uiswitch(app.extrctItms.(extrctItm).(itmPanelRef), 'slider');
                    app.extrctItms.(extrctItm).(switchRef).Orientation = 'vertical';
                    app.extrctItms.(extrctItm).(switchRef).Visible = 'on';
                    app.extrctItms.(extrctItm).(switchRef).FontSize = 1;
                    app.extrctItms.(extrctItm).(switchRef).Position = swtchPos;
                    app.extrctItms.(extrctItm).(switchRef).Value = 'On';
                    app.extrctItms.(extrctItm).(switchRef).ValueChangedFcn= createCallbackFcn(app,@extrctSwitchValueChanged,true);


                end
            end

        end

        function extrctSwitchValueChanged(app, event)
            extrctItm= event.Source.Parent.Title;              
            % get switch Reference
            switchRef = sprintf("%sSwitch",extrctItm); 
            itmPanelRef = sprintf("%sPanel",extrctItm);
            numToProcRef = sprintf("%sNumToProc",extrctItm);
            % toggle button color
            state = app.extrctItms.(extrctItm).(switchRef).Value;
            switch state
                case 'On' % button currently off/unselected
                    app.params.extractCfg.(extrctItm).toExtract = 1;
                    app.extrctItms.(extrctItm).(itmPanelRef).ForegroundColor = app.staticColor;                    
                    app.extrctItms.(extrctItm).(numToProcRef).FontColor = app.staticColor;
                case 'Off' % button currently on/selected                 
                    app.params.extractCfg.(extrctItm).toExtract = 0;
                    app.extrctItms.(extrctItm).(itmPanelRef).ForegroundColor = app.disableGrey; 
                    app.extrctItms.(extrctItm).(numToProcRef).FontColor = app.disableGrey;
            end              
        end

        function resetButtonPressed(app, event)
            extrctItm = event.Source.Parent.Title;
            buttonRef = sprintf("%sResetButton",extrctItm);
            extrctLog = app.extrctItms.(extrctItm).extractionLog;
            tmpExtrctLog = app.extrctItms.(extrctItm).tmpExtractionLog;
            state = app.extrctItms.(extrctItm).(buttonRef).Value;
            subjectsToExtract = app.subjExtractTable(app.subjExtractTable.Extract==1,:).SubjectName;
            switch state
                case 1 % switched to 'reset'
                    app.extrctItms.(extrctItm).(buttonRef).BackgroundColor = app.staticColor;
                    app.extrctItms.(extrctItm).(buttonRef).FontColor = app.disableBlack;
                    % update temp extraction log and show results   
                    if ~isempty(subjectsToExtract)
                        subitms = extrctLog.Properties.VariableNames;
                        subitms = subitms(contains(subitms,"Extracted"));
                        idx = listdlg("PromptString","select extraction items to reset","ListString",subitms);
                        itms2Reset = convertCharsToStrings(subitms(idx));
                        for i = 1:length(itms2Reset)
                            itmReset = itms2Reset(i);
                            subLog = tmpExtrctLog((contains(tmpExtrctLog.SessionName,subjectsToExtract)),:);
                            tmpExtrctLog((contains(tmpExtrctLog.SessionName,subjectsToExtract)),:).(itmReset) = zeros(height(subLog),1);
                            tmpExtrctLog((contains(tmpExtrctLog.SessionName,subjectsToExtract)),:).Extracted = zeros(height(subLog),1);
                        end                                                
                    end
                case 0 % 'reset' cancelled
                    tmpExtrctLog = extrctLog;
                    app.extrctItms.(extrctItm).(buttonRef).BackgroundColor = app.disableBlack;
                    app.extrctItms.(extrctItm).(buttonRef).FontColor = app.staticColor;
            end
            numItms2Extract = tmpExtrctLog(contains(tmpExtrctLog.SessionName,subjectsToExtract),:).Extracted;             
            numItms2Extract = size(numItms2Extract,1);
            numLabel = sprintf("%sNumToProc",extrctItm);
            app.extrctItms.(extrctItm).(numLabel).Text = num2str(numItms2Extract);            
            app.extrctItms.(extrctItm).tmpExtractionLog = tmpExtrctLog;
            app.extrctItms.(extrctItm).extractionLog = extrctLog;
            updateSessions2Extract(app);            
        end

        function updateSessions2Extract(app)            
            for i = 1:length(app.extractItems)
                extrctItm = app.extractItems(i);
                numToProcRef = sprintf("%sNumToProc",extrctItm);
                sessionsToExtract = report4Extraction(app, app.subjExtractTable, extrctItm); % find sessions in extractionLog that match ui criteria                
                app.extrctItms.(extrctItm).(numToProcRef).Text = num2str(length(sessionsToExtract.sessions));
                app.extrctItms.(extrctItm).sessionsToExtract = sessionsToExtract;
            end
        end

        function sessionsToExtract = report4Extraction(app, subjExtrctTable, extrctItm)
            % get sessions associated with user-selected subjects            
            extrctLog = app.extrctItms.(extrctItm).tmpExtractionLog;
            subjectsToExtract = subjExtrctTable(subjExtrctTable.Extract==1,:).SubjectName;
            % extrctLog = extrctLog((contains(extrctLog.SessionName,subjectsToExtract)),:);
            if ~isempty(extrctLog)
                extrctLog = extrctLog((contains(extrctLog.SessionName,subjectsToExtract)),:);
                sessions = extrctLog(extrctLog.Extracted==0,:).SessionName; %% STOPPED HERE
            else
                sessions = [];
            end
            subjects = getSubjectName(sessions, app.subjExtractTable.SubjectName);
            sessionsToExtract.sessions = sessions;
            sessionsToExtract.subjects = subjects;
        end
      
    end
    
    methods (Access = private)
        
        function locateDTSButtonPushed(app)
            app.params = locateDTS(app.params);
        end

        function recycleRAWButtonPushed(app)
            if ~isfield(app.params.extractCfg,"reExtractFromCloud")
                app.params.extractCfg.reExtractFromCloud=0;
            end
            switch app.params.extractCfg.reExtractFromCloud
                case 0 % currently inactive (ACTIVATE)
                    app.params.extractCfg.reExtractFromCloud=1;
                    app.extrctItms.RAW.RAWRecycleButton.BackgroundColor = app.staticColor;                    
                case 1
                    app.params.extractCfg.reExtractFromCloud=0;
                    app.extrctItms.RAW.RAWRecycleButton.BackgroundColor = app.disableBlack;                    
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % scan working directory nCORTEx application
            cdir = dir(pwd);
            cTbl = struct2table(cdir);
            if any(contains(cTbl.name,'nCORTEx_target.mlapp'))
                %addpath(genpath("Setup/"));
                %app.params = setHostParams(struct);
                addpath(genpath(pwd));
                app.nCORTEx_repo = pwd;
            else
                disp("n-CORTEx startup failed; please navigate to n-CORTEx repository and call 'cortex('host')'")
            end
            
            app.params = setExtractionParams(struct);
            % initialize proxy framework
            cortexServerConnectedFcn = str2func("cortexServerConnected");
            app.proxon = proxon(app, []);
            % ncortex proxy
            % try
            %     prox_ncortex = proxy_ncortex(app, app.params.netConfig.MAC_net, 8001, [], [], [], [], cortexServerConnectedFcn);
            % catch
            %     prox_ncortex = proxy_ncortex(app, app.params.netConfig.MAC_net, 8002, [], [], [], [], cortexServerConnectedFcn);
            % end
            notConnected=1;
            portNum=8002;
            try
                prox_ncortex = proxy_ncortex(app, app.params.netConfig.MAC_net, portNum, [], [], [], [], cortexServerConnectedFcn);
                % while notConnected
                %     try
                %         prox_ncortex = proxy_ncortex(app, app.params.netConfig.MAC_net, portNum, [], [], [], [], cortexServerConnectedFcn);
                %         notConnected=0;
                %     catch
                %         portNum=portNum+1;
                %     end
                % end
                app.proxon.addProxy(prox_ncortex, []);
            
                % nexus proxy
                proxObj_nexus = proxy_nexus([],app,[],app.params);
                app.proxon.addProxy(proxObj_nexus,[]);
                if ~isempty(app.params.netConfig.MAC_slrt)
                    % % slrt command proxy
                    % portNum=8002;
                    % try
                    %     prox_slrt = proxy_slrt(app.params.netConfig.MAC_slrt, portNum, [], [], [], [], []);
                    % catch
                    %     try
                    %         prox_slrt = proxy_slrt(app.params.netConfig.MAC_slrt, portNum+1, [], [], [], [], []);
                    %     catch                        
                    %         prox_slrt = proxy_slrt(app.params.netConfig.MAC_slrt, portNum+2, [], [], [], [], []);
                    %     end
                    % end
                    notConnected=1;
                    portNum=8001;
                    prox_slrt = proxy_slrt(app.params.netConfig.MAC_slrt, portNum, [], [], [], [], []);
                    % while notConnected
                    %     try
                    %         prox_slrt = proxy_slrt(app.params.netConfig.MAC_slrt, portNum, [], [], [], [], []);
                    %         notConnected=0;
                    %     catch
                    %         portNum=portNum+1;
                    %     end
                    % end
                    app.proxon.addProxy(prox_slrt,[]);     
                end
            catch e
                disp(getReport(e));
                disp("ERROR: NCORTEX NETWORK FAILURE")
            end


            % app.proxyArray.proxy_slrt = proxy_slrt([],[],[]);
            % app.proxyArray.proxy_slrt.tgProxies = initializeTargetProxies(params.expmntCfg.targets);                      
            % app.proxyArray.proxy_slrt.addPartner(proxy_ncortex(app,app.params.ethernetIP,8001)); % assign as partner
            % app.proxyArray.proxy_slrt.addPartner(proxy_nexus([],app.params)); % initialize nexon with empty DTS
            
            % app.proxyArray = startProxy(app.params);
            % integrate proxy system into ncortex
            % app.proxyArray = nCORTEx_integrateProxyArray(app.proxyArray, app);
            % app.proxyArray.proxy_ncortex = proxy_ncortex(app.params.ethernetIP,8001,[],app,[]);
            % app.cortexServer = initiateCortexServer(app.params);            
            app.subjExtractTable = initExtractTable(app);
            if ~isempty(app.params.staticColor)
                app.staticColor = app.params.staticColor;
            else
                app.staticColor = app.cyberYellow;
            end
            drawServerModule(app);
            projDirs = [categorize(app.params.paths.nCORTEx_cloud,'Project'), categorize(app.params.paths.nCORTEx_local,'Project')];
            projDirs = unique(projDirs);
            % app.SelectProjectDirectoryDropDown.Items = ["None", categorize(app.params.paths.NECdrive_cloud,'Project')];    
            app.SelectProjectDirectoryDropDown.Items = ["None", projDirs];    


            serverConnectButtonPressed(app)
        end

        % Value changed function: SelectProjectDirectoryDropDown
        function SelectProjectDirectoryDropDownValueChanged(app, event)
            value = app.SelectProjectDirectoryDropDown.Value;
            if value~="None"
                app.params.paths = setPaths(app.params.paths,value);
                app.SelectProjectDirectoryDropDownLabel.Text=value;
                app.project = value;
                app.projDir_cloud = fullfile(app.params.paths.NECdrive_cloud,value);
                app.projDir_local = fullfile(app.params.paths.nCORTEx_local, value);
                expmntPath_cloud = fullfile(app.projDir_cloud,"Experiments");
                expmntPath_local = fullfile(app.projDir_local,"Experiments");
                expmntList = unique([categorize(expmntPath_cloud,'isDir'), categorize(expmntPath_local,'isDir')]);
                % app.ExpmntDropDown.Items = ["None", categorize(expmntPath,'isDir')];
                app.ExpmntDropDown.Items = ["None", expmntList];
                % app.params.paths = setPaths(value); % TO UPDATE
                % subjectList = getSubjectList(app,app.params.paths.all_data_path);
                % drawSubjectSelection(app, subjectList);
                % drawSubjectSelection(app);
                % drawExtractionItems(app);
                % updateNumToProc(app);
            elseif value=="None"
                app.SelectProjectDirectoryDropDownLabel.Text=value;
            end
        end

        % Value changed function: ExpmntDropDown
        function ExpmntDropDownValueChanged(app, event)
            value = app.ExpmntDropDown.Value;
            if value~= "None"
                app.params.extractCfg.experiment = value;
                app.params.experiment = value;
                app.params.extractCfg.behavior.slrt=value;            
                app.params.paths.expmntPath_cloud = fullfile(app.params.paths.projDir_cloud,"Experiments",value);
                app.ExpmntDropDownLabel.Text = value;
                app.expmnt = value;
    
                pattern = "_extraction_log.csv";
                extractionLogPath = fullfile(app.projDir_cloud,"Experiments",app.expmnt,"Extraction-Logs");
                extractList = categorize(extractionLogPath,"extraction_log");
                extractList = strrep(cellstr(extractList),pattern,"");                
                
                % modalities_pre = app.modalities;
                % if isempty(modalities_pre); modalities_pre=[]; end
                app.modalities = string(categorize(fullfile(app.projDir_cloud,"Experiments",app.expmnt,"Data","RAW"),"isDir"));            
                % if ~all(strcmp(modalities_pre,app.modalities))
                % start target proxies here
                % load expmntCfg_target and expmntCfg
                try
                    expmntModulePath = fullfile(app.params.paths.experimentModules,app.params.experiment);
                catch
                    expmntModulePath = uigetdir(app.params.paths.repo_path,"select experiment modules");                    
                    app.params.paths.experimentModules = expmntModulePath;
                    expmntModulePath = fullfile(expmntModulePath, app.params.experiment);
                    addpath(genpath(expmntModulePath));
                end
                try
                    load(fullfile(expmntModulePath,"expmntCfg_target.mat"));
                    app.params.expmntCfg_target=expmntCfg_target;
                catch e
                    disp(getReport(e));
                end
                try
                    load(fullfile(expmntModulePath,"expmntCfg.mat"));
                    app.params.expmntCfg=expmntCfg;
                catch e
                    disp(getReport(e));
                end
                
                try
                    configureTargetProxies(app.proxon, app.params.expmntCfg);
                catch e
                    disp(getReport(e));
                end
                % if app.servers.cortexServer.Connected
                % if app.proxyArray.proxy_ncortex.Server.Connected
                %     initiateModalityServers(app);
                % end
                % end               
                app.params.paths.repo_path = app.nCORTEx_repo;
                app.params = locateData(app.params);
                
                % app.params.expmntCfg = load() % load expmntCfg
                % set SGLX params path
                % [status, res] = system('tasklist | findstr /i "SpikeGLX.exe');
                
                % initialize proxyArray
                try
                    app.proxon = startProxy(app.params);
                catch e
                    disp(getReport(e));
                end


                % reconfigure datadirectory, accordingly
                try
                    if isfield(app.params.paths.Data.RAW,'NPXLS') & isSGLX
                        hSGL = SpikeGL(char(app.params.ethernetIP));
                        SetDataDir(hSGL,0,char(app.params.paths.Data.RAW.NPXLS.local))
                    end
                catch e
                    disp(getReport(e))
                end

                
                app.SubjectSelectionPanel.Visible="on";
                drawExtractionItems(app);
                drawSubjectSelection(app);
            else
                app.ExpmntDropDownLabel.Text = value;
                app.SubjectSelectionPanel.Visible="off";
                extrctItmsFields = fieldnames(app.extrctItms);
                if length(extrctItmsFields)>0; clear(app.extrctItms); end
                % if ~isempty(app.extrctItms); clear(app.extrctItms); end
            end
            
            % switch value
            %     case "nCORTEx"
            %         app.params.extractCfg.behavior.slrt = "nCORTEx";
            %     case "JOLT"
            %         app.params.extractCfg.behavior.slrt = "JOLT";            
            % end
        end

        % Button pushed function: ExtractButton
        function ExtractButtonPushed(app, event)
            
            % finalize extraction logs
            extrctItms = fieldnames(app.extrctItms);
            for i = 1:length(extrctItms)
                extItm = extrctItms{i};
                app.extrctItms.(extItm).extractionLog = app.extrctItms.(extItm).tmpExtractionLog;
            end
            app.params.extrctItms = app.extrctItms;
            extraction(app.params);
            
            % extracts = cell(length(app.modalities),1);
            % % parallel execution of extraction
            % % Check if 'SLRT' exists in the list and move it to the front
            % modalities = app.modalities;
            % if ismember('SLRT', modalities)
            %     modalities = ['SLRT', modalities(~strcmp(modalities, 'SLRT'))];
            % end            
            % % Check if 'Feature' exists in the list and move it to the end
            % if ismember('Feature', modalities)
            %     modalities = [modalities(~strcmp(modalities, 'Feature')), 'Feature'];
            % end
            % app.modalities = modalities;
            % 
            % % LOAD EXISTING DATASTORE
            % % datastorePath = fullfile(app.params.paths.projDir_cloud,"Experiments",app.params.extractCfg.experiment,"Data","Datastore");
            % % % background loading of current datastore
            % % if ~isempty(datastorePath)
            % %     % dataStorePre = parfeval(loadTall, 1, datastorePath);
            % %     dataStorePre = loadTall(datastorePath);
            % % else
            % %     dataStorePre = [];
            % % end
            % 
            % extrctHandles = arrayfun(@(x) extrctHndl(x), app.modalities, "UniformOutput", false);
            % 
            % for i = 1:length(extrctHandles)
            %     hndl = extrctHandles{i};
            %     if (~(isequal(hndl,str2func("@extractFeature"))))
            %         modality = app.modalities(i);
            %         app.params.extractCfg.modality = modality;                
            %         sessionsToExtract = app.extrctItms.(modality).sessionsToExtract;                                                             
            %         q = app.extrctItms.(modality).q;
            %         pq = app.extrctItms.(modality).pq;
            %         q.afterEach(@(data) waitBarCallback(app));
            %         % extracts{i} = parfeval(hndl, 1, q, pq, app.params, sessionsToExtract);
            %         hndl(q,pq,app.params,sessionsToExtract)
            %     end                
            %     % hndl(q,pq,app.params,sessionsToExtract)
            % 
            %     % extracts{i} = fetchOutputs(parfeval(hndl, 1, q, pq, app.params, sessionsToExtract));
            %     % cmd = sprintf("extracts{i} = extract%s(q, pq, app.params, sessionsToExtract);", modality);
            %     % eval(cmd);
            %     % extracts{i} = parfeval(@extractSLRT,1,q,pq,app.params,sessionsToExtract);
            %     % dtst = fetchOutputs(extracts{i});
            % end
            % 
            % % STORE TALL TABLE
            % dataStore = extracts{1};
            % for i  = 2:length(extracts)
            %     dataStore = outerjoin(dataStore, extracts{i}, "Keys", {'sessionName','trialNum','subjectName'},"MergeKeys",true);
            % end    
            % 
            % dataStore = [dataStorePre; dataStore];
            % 
            % % APPEND PREEXISTING DATASTORE
            % 
            % tallDS = tall(dataStore);
            % rmdir(fullfile(app.params.paths.expmntPath,"Data","Datastore"),s);
            % write(fullfile(app.params.paths.expmntPath,"Data","Datastore",tallDS));
        end

        % Callback function
        function serverButtonPushed(app, event)
            app.servers.cortexServer = initiateCortexServer(app);
            app.servers.pl = server_photon(app.params);            
            app.servers.sg = server_slrt(app.params, app.servers.pl);

            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isfield(app.servers,"cortexServer")
                ctxSrv = app.servers.cortexServer;
                delete(ctxSrv);
                clear ctxSrv;
            end
            % clear tcp objects
            closeProxies(app.proxon);
            delete(app)
            
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            
        end

        % Image clicked function: Image4_3
        function Image4_3Clicked(app, event)
            try
                params = app.params;
                assignin('base','params',params);
            catch e
                disp(e);
            end
        end

        % Button pushed function: SessionLabelButton
        function SessionLabelButtonPushed(app, event)
            rxArgs.sessionLabel = app.params.sessionLabel;
            app.proxon.index_type1.ncortex.updateSessionLabel(rxArgs);
        end

        % Value changed function: ExperimentModulesFolderButton
        function ExperimentModulesFolderButtonValueChanged(app, event)
            % value = app.ExperimentModulesFolderButton.Value;
            % if ~isempty(app.ModulesFilePath.Value)
            %     % Reset the dependencies
            %     app.ExpmntDropDownV2_slrt.Items = {'None'};
            %     app.ExpmntDropDownV2_slrt.Value = 'None';
            %     app.PhaseDropDown_slrt.Value = 'None';
            %     app.ExpmntDropDownV2_slrt.Enable = 'off';
            %     app.PhaseDropDown_slrt.Enable = 'off';
            %     app.BrowseProjects.Enable = 'on';
            % end

            % Open a folder selection dialog box
            modulePath = uigetdir;

            % Check if the user clicked 'Cancel'
            if modulePath == 0
                %disp('No experiment module selected.');
                return;
            end

            % Automatically display the selected path in the specified text box
            app.ModulesFilePath.Value = modulePath;
            app.params.paths.experimentModules = modulePath;

            % if ~isempty(app.ModulesFilePath.Value)
            %     % Enable the Experiment and Phase dropdowns
            %     app.BrowseProjects.Enable = 'on';
            % end

            % Use the listFolders function to get the folders in that path
            % experimentFolders = listFolders(app,modulePath);
            % unwantedFolders = [".git", ".qnx", "slxHelpers"]; % List of folders to exclude
            % experimentFolders = experimentFolders(~ismember(experimentFolders, unwantedFolders));
            % experimentFolders = [{'None'}, sort(experimentFolders), {'+'}];


            % Set the Items property of the ExpmntDropDown to the folders list
            % app.ExpmntDropDownV2_slrt.Items = experimentFolders;

            % Set a default value if needed
            % app.ExpmntDropDownV2_slrt.Value = "None";
            
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            assignin("base","nCORTEx",app);
        end

        % Button pushed function: ControlButton
        function ControlButtonPushed(app, event)
            % app.proxon.index_type1.ncortex_1.openControlPanel();
            % type1ProxObjs
            % proxObjs_t1 = fieldnames(app.proxon.index_type1);
            % start Nexus
            % CHECK DTS LOCATION
            dtsPath = fullfile(app.params.paths.Data.DTS.local,"nexDTS.h5");
            dtsManifestPath = fullfile(app.params.paths.Data.DTS.local,"nexDTS_manifest.mat");
            if isfile(dtsPath) & isfile(dtsManifestPath)
            % ELSE INIT EMPTY
                load(dtsManifestPath);
                app.nexon = startNexus(app.params, manifest);
            else
                app.nexon= startNexus(app.params,[]);
            end
            app.proxon.nexon=app.nexon;
            proxObjs = fieldnames(app.proxon.index_type2);
            for i = 1:length(proxObjs)
                ID_proxObj = proxObjs{i};                
                try 
                    proxObj = app.proxon.index_type2.(ID_proxObj);
                    proxObj.openControlPanel();
                catch e
                    disp(getReport(e));
                end
            end
            assignin("base","nexon",app.nexon);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.moduleSlot1 = uiimage(app.UIFigure);
            app.moduleSlot1.ImageSource = fullfile(pathToMLAPP, 'Icons', 'serverModule_disconnected.PNG');
            app.moduleSlot2 = uiimage(app.UIFigure);
            app.moduleSlot2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'serverModule_disconnected.PNG');
            app.moduleSlot3 = uiimage(app.UIFigure);
            app.moduleSlot3.ImageSource = fullfile(pathToMLAPP, 'Icons', 'serverModule_disconnected.PNG');
            app.moduleSlot4 = uiimage(app.UIFigure);
            app.moduleSlot4.ImageSource = fullfile(pathToMLAPP, 'Icons', 'serverModule_disconnected.PNG');
            app.moduleSlot5 = uiimage(app.UIFigure);
            app.moduleSlot5.ImageSource = fullfile(pathToMLAPP, 'Icons', 'serverModule_disconnected.PNG');
            app.ExtractionItemPanel = uipanel(app.UIFigure);
            app.ExtractionItemPanel.Title = 'Panel';
            app.SelectProjectDirectoryDropDownLabel = uilabel(app.UIFigure);
            app.SelectProjectDirectoryDropDownLabel.Text = 'Select Project Directory';
            app.geo2 = uiimage(app.UIFigure);
            app.geo2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'geo3.PNG');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'geo2_lightGrey.PNG');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Icons', 'geo4_triangle.PNG');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'geo1.PNG');
            app.Image3_2 = uiimage(app.UIFigure);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'geo4_triangle.PNG');
            app.Image3_3 = uiimage(app.UIFigure);
            app.Image3_3.ImageSource = fullfile(pathToMLAPP, 'Icons', 'geo4_triangle.PNG');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Icons', 'geo4_triangleDown.PNG');
            app.Image4_2 = uiimage(app.UIFigure);
            app.Image4_2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'geo4_triangleDown.PNG');
            app.Image4_3 = uiimage(app.UIFigure);
            app.Image4_3.ImageClickedFcn = createCallbackFcn(app, @Image4_3Clicked, true);
            app.Image4_3.ImageSource = fullfile(pathToMLAPP, 'Icons', 'geo4_triangleDown.PNG');
            app.connectIcon = uiimage(app.UIFigure);
            app.connectIcon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'serverIcon_disconnected.PNG');
            app.SubjectSelectionPanel = uipanel(app.UIFigure);
            app.SubjectSelectionPanel.Title = 'Subject Selection';
            app.SubjectSelectionPanel.Visible = 'off';
            app.subjectListPanel = uipanel(app.SubjectSelectionPanel);
            app.ExtractButton = uibutton(app.UIFigure, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractButtonPushed, true);
            app.ExtractButton.Icon = fullfile(pathToMLAPP, 'Icons', 'bolt.png');
            app.ExtractButton.Text = '';
            app.ExpmntDropDownLabel = uilabel(app.UIFigure);
            app.ExpmntDropDownLabel.Text = 'Experiment';
            app.ExpmntDropDown = uidropdown(app.UIFigure);
            app.ExpmntDropDown.Items = {'None'};
            app.ExpmntDropDown.ValueChangedFcn = createCallbackFcn(app, @ExpmntDropDownValueChanged, true);
            app.ExpmntDropDown.Value = 'None';
            app.SelectProjectDirectoryDropDown = uidropdown(app.UIFigure);
            app.SelectProjectDirectoryDropDown.Items = {'None', 'Neuromodulation for Pain', 'Neurotransmitter Exploration', 'Thalamic Pupil Synchronization', 'SSD', '+', 'Browse'};
            app.SelectProjectDirectoryDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectProjectDirectoryDropDownValueChanged, true);
            app.SelectProjectDirectoryDropDown.Value = 'None';
            app.SessionLabelButton = uibutton(app.UIFigure, 'push');
            app.SessionLabelButton.ButtonPushedFcn = createCallbackFcn(app, @SessionLabelButtonPushed, true);
            app.SessionLabelButton.Text = 'Session Label';
            app.ExperimentModulesFolderButton = uibutton(app.UIFigure, 'state');
            app.ExperimentModulesFolderButton.ValueChangedFcn = createCallbackFcn(app, @ExperimentModulesFolderButtonValueChanged, true);
            app.ExperimentModulesFolderButton.Text = '...';
            app.ControlButton = uibutton(app.UIFigure, 'push');
            app.ControlButton.ButtonPushedFcn = createCallbackFcn(app, @ControlButtonPushed, true);
            app.ControlButton.Icon = fullfile(pathToMLAPP, 'Icons', 'bolt.png');
            app.ControlButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nCORTEx_target

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end